classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ApplicationTab                  matlab.ui.container.Tab
        AuLabel_5                       matlab.ui.control.Label
        AuLabel_4                       matlab.ui.control.Label
        AuLabel_3                       matlab.ui.control.Label
        AuLabel_2                       matlab.ui.control.Label
        AuLabel                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SchematicPanel                  matlab.ui.container.Panel
        TopologyImage                   matlab.ui.control.Image
        PlaceholderSubTextLabel         matlab.ui.control.Label
        TopologiesPanel                 matlab.ui.container.Panel
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        DCACLabel                       matlab.ui.control.Label
        DCDCLabel                       matlab.ui.control.Label
        rbThreePhaseInverter            matlab.ui.control.RadioButton
        rbFullBridgeInverter            matlab.ui.control.RadioButton
        rbHalfBridgeInverter            matlab.ui.control.RadioButton
        rbBuckBoostConverter            matlab.ui.control.RadioButton
        rbBuckConverter                 matlab.ui.control.RadioButton
        rbBoostConverter                matlab.ui.control.RadioButton
        DeviceSelectionTab              matlab.ui.container.Tab
        alt2                            matlab.ui.control.NumericEditField
        alt3                            matlab.ui.control.Label
        alt3b                           matlab.ui.control.Label
        alt2b                           matlab.ui.control.NumericEditField
        IGBTadvancedparameterCheckbox   matlab.ui.control.CheckBox
        AdvancedParametersIGBT          matlab.ui.container.Panel
        eoneoffrefvoltage               matlab.ui.control.NumericEditField
        eoneoffrefcurrent               matlab.ui.control.NumericEditField
        Labelreversecapacitancelabel_2  matlab.ui.control.Label
        Labeloutputcapacitancelabel_2   matlab.ui.control.Label
        Labelinputcapacitancelabel      matlab.ui.control.Label
        Editfieldinputcapacitance       matlab.ui.control.NumericEditField
        Labelreversecapacitancelabel    matlab.ui.control.Label
        Labeloutputcapacitancelabel     matlab.ui.control.Label
        Editfieldreversecapacitance     matlab.ui.control.NumericEditField
        Editfieldoutputcapacitance      matlab.ui.control.NumericEditField
        Label5ADI                       matlab.ui.control.Label
        Label4ADI                       matlab.ui.control.Label
        Label3ADI                       matlab.ui.control.Label
        Label2ADI                       matlab.ui.control.Label
        Label1ADI                       matlab.ui.control.Label
        EditField5ADI                   matlab.ui.control.NumericEditField
        EditField4ADI                   matlab.ui.control.NumericEditField
        EditField3ADI                   matlab.ui.control.NumericEditField
        EditField2ADI                   matlab.ui.control.NumericEditField
        EditField1ADI                   matlab.ui.control.NumericEditField
        Panel_4                         matlab.ui.container.Panel
        IGBTeditfield3                  matlab.ui.control.NumericEditField
        IGBTeditfield4                  matlab.ui.control.NumericEditField
        IGBTlabel4                      matlab.ui.control.Label
        IGBTlabel3                      matlab.ui.control.Label
        IGBTlabel02                     matlab.ui.control.Label
        IGBTlabel01                     matlab.ui.control.Label
        IGBTeditfield02                 matlab.ui.control.NumericEditField
        IGBTeditfield01                 matlab.ui.control.NumericEditField
        IGBTeditfield1                  matlab.ui.control.NumericEditField
        IGBTeditfield2                  matlab.ui.control.NumericEditField
        IGBTlabel2                      matlab.ui.control.Label
        S                               matlab.ui.control.Label
        ConductionlossmodelLabel        matlab.ui.control.Label
        OptionConductionLossIGBT        matlab.ui.control.DropDown
        AdvancedParametersCheckBox      matlab.ui.control.CheckBox
        MOSFETParametersPanel           matlab.ui.container.Panel
        EoffLabelMOSFET                 matlab.ui.control.Label
        EoffEditFieldMOSFET             matlab.ui.control.NumericEditField
        EonLabelMOSFET                  matlab.ui.control.Label
        EonEditFieldMOSFET              matlab.ui.control.NumericEditField
        RdsOnLabelMOSFET                matlab.ui.control.Label
        RdsOnEditFieldMOSFET            matlab.ui.control.NumericEditField
        IGBTBlockParametersPanel        matlab.ui.container.Panel
        RdsOn2TLabelMOSFET              matlab.ui.control.Label
        RdsOnEditField2TMOSFET          matlab.ui.control.NumericEditField
        RdsOn2TEditFieldMOSFET          matlab.ui.control.NumericEditField
        RdsOnLabel2TMOSFET              matlab.ui.control.Label
        TmeasIGBTLabel                  matlab.ui.control.Label
        VcesIcesLabelIGBT               matlab.ui.control.Label
        IcesLabelIGBT                   matlab.ui.control.Label
        VgeThLabelIGBT                  matlab.ui.control.Label
        VgeVceSatLabelIGBT              matlab.ui.control.Label
        IcVceSatLabelIGBT               matlab.ui.control.Label
        TmeasIGBTEditField              matlab.ui.control.NumericEditField
        VcesIcesEditFieldIGBT           matlab.ui.control.NumericEditField
        IcesEditFieldIGBT               matlab.ui.control.NumericEditField
        VgeThEditFieldIGBT              matlab.ui.control.NumericEditField
        VgeVceSatEditFieldIGBT          matlab.ui.control.NumericEditField
        IcVceSatEditFieldIGBT           matlab.ui.control.NumericEditField
        Panel_2                         matlab.ui.container.Panel
        SwitchTechnologyDropDown        matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        DeviceSelectionTitleLabel_2     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        AdvanceReverseRecoveryCheckBox  matlab.ui.control.CheckBox
        didtrefEditfield                matlab.ui.control.NumericEditField
        didtrefLabel                    matlab.ui.control.Label
        Vrevlabel                       matlab.ui.control.Label
        IfredLabel                      matlab.ui.control.Label
        Peakreversecurrentlabel         matlab.ui.control.Label
        VrevEditfield                   matlab.ui.control.NumericEditField
        IfrefEditfield                  matlab.ui.control.NumericEditField
        IrrmEditfield                   matlab.ui.control.NumericEditField
        OmitLabelDiodeExtraParameters   matlab.ui.control.Label
        ReversetimeTrrlabel             matlab.ui.control.Label
        trrEditfield                    matlab.ui.control.NumericEditField
        ReverseQrrLabel                 matlab.ui.control.Label
        ErrErecLabel                    matlab.ui.control.Label
        QrrEditfield                    matlab.ui.control.NumericEditField
        ErrErecEditfield                matlab.ui.control.NumericEditField
        NoButton_OmitDiodeParameters    matlab.ui.control.RadioButton
        YesButton_OmitDiodeParameters   matlab.ui.control.RadioButton
        DiodeTypeDropDown               matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        DiodeRonLabel                   matlab.ui.control.Label
        DiodeVFLabel                    matlab.ui.control.Label
        DiodeRonEditField               matlab.ui.control.NumericEditField
        DiodeVFEditField                matlab.ui.control.NumericEditField
        DeviceSelectionTitleLabel_3     matlab.ui.control.Label
        CircuitParametersTab            matlab.ui.container.Tab
        Threephaseextrapanel            matlab.ui.container.Panel
        PWMStrategy3phDropDown          matlab.ui.control.DropDown
        PWMStrategy3phLabel             matlab.ui.control.Label
        VLLRMSLabel                     matlab.ui.control.Label
        VLLRMSEditField                 matlab.ui.control.NumericEditField
        HalfandFullBridge               matlab.ui.container.Panel
        PWMStrategyDropDown             matlab.ui.control.DropDown
        PWMStrategyLabel                matlab.ui.control.Label
        VoutRMSLabel                    matlab.ui.control.Label
        VoutRMSEditField                matlab.ui.control.NumericEditField
        DCACCircuitPanel                matlab.ui.container.Panel
        RgOffACEditField                matlab.ui.control.NumericEditField
        RgOnACEditField                 matlab.ui.control.NumericEditField
        RgOffACLabel                    matlab.ui.control.Label
        RgOnACLabel                     matlab.ui.control.Label
        PFEditField                     matlab.ui.control.NumericEditField
        PFLabel                         matlab.ui.control.Label
        ModIndexLabel                   matlab.ui.control.Label
        ModIndexEditField               matlab.ui.control.NumericEditField
        VgsDriveACLabel                 matlab.ui.control.Label
        fswACLabel                      matlab.ui.control.Label
        foutLabel                       matlab.ui.control.Label
        PoutACLabel                     matlab.ui.control.Label
        VdcLabel                        matlab.ui.control.Label
        VgsDriveACEditField             matlab.ui.control.NumericEditField
        fswACEditField                  matlab.ui.control.NumericEditField
        foutEditField                   matlab.ui.control.NumericEditField
        PoutACEditField                 matlab.ui.control.NumericEditField
        VdcEditField                    matlab.ui.control.NumericEditField
        DCDCFlyback                     matlab.ui.container.Panel
        CoutESRLabel                    matlab.ui.control.Label
        LlkLabel                        matlab.ui.control.Label
        TurnsRatioLabel                 matlab.ui.control.Label
        LmLabel                         matlab.ui.control.Label
        CoutESREditField                matlab.ui.control.NumericEditField
        LlkEditField                    matlab.ui.control.NumericEditField
        TurnsRatioEditField             matlab.ui.control.NumericEditField
        LmEditField                     matlab.ui.control.NumericEditField
        DCDCCircuit                     matlab.ui.container.Panel
        ToffRgEditField                 matlab.ui.control.NumericEditField
        TonRgEditField                  matlab.ui.control.NumericEditField
        CoutLabel_Boost_4               matlab.ui.control.Label
        CoutLabel_Boost_3               matlab.ui.control.Label
        LEditField_Boost_2              matlab.ui.control.NumericEditField
        LLabel_Boost_2                  matlab.ui.control.Label
        CoutLabel_Boost_2               matlab.ui.control.Label
        CoutEdit_Field                  matlab.ui.control.NumericEditField
        CoutLabel_Boost                 matlab.ui.control.Label
        fswLabel_Boost                  matlab.ui.control.Label
        PoutLabel_Boost                 matlab.ui.control.Label
        VoutLabel_Boost                 matlab.ui.control.Label
        VinLabel_Boost                  matlab.ui.control.Label
        GateDriveVoltageEditField       matlab.ui.control.NumericEditField
        fswEditField_Boost              matlab.ui.control.NumericEditField
        PoutEditField_Boost             matlab.ui.control.NumericEditField
        VoutEditField_Boost             matlab.ui.control.NumericEditField
        VinEditField_Boost              matlab.ui.control.NumericEditField
        SchematicPanel_2                matlab.ui.container.Panel
        TopologyImage_2                 matlab.ui.control.Image
        HereistheschematicLabel_2       matlab.ui.control.Label
        PlaceholderSubTextLabel_2       matlab.ui.control.Label
        DeviceSelectionTitleLabel_4     matlab.ui.control.Label
        CoolingTab                      matlab.ui.container.Tab
        HeatSinkImpedancePanel          matlab.ui.container.Panel
        TimeConstantPanel               matlab.ui.container.Panel
        TimeConstantValue               matlab.ui.control.EditField
        TimeConstantT_haLabel           matlab.ui.control.Label
        ThermalResistanceValue          matlab.ui.control.EditField
        ThermalCapacitancePanel         matlab.ui.container.Panel
        ThermalImpedanceValue           matlab.ui.control.NumericEditField
        ThermalcapacitanceC_thhLabel    matlab.ui.control.Label
        ThermalresistanceR_thhaLabel    matlab.ui.control.Label
        ThermalchainLabel               matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        CauerButton                     matlab.ui.control.RadioButton
        FosterButton                    matlab.ui.control.RadioButton
        DevicethermalimpedanceLabel     matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        BoundarytemperatureT_bCLabel    matlab.ui.control.Label
        HeatSinkFixedTempPanel          matlab.ui.container.Panel
        FixedTempValue                  matlab.ui.control.NumericEditField
        FixedTempValueLabel             matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CustomThermalImpedance          matlab.ui.control.RadioButton
        FixedTemp                       matlab.ui.control.RadioButton
        RthchEditField                  matlab.ui.control.NumericEditField
        RthchLabel                      matlab.ui.control.Label
        HeatsinkmodelLabel              matlab.ui.control.Label
        ThermalparametersLabel          matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        SimulationProgress              simulink.ui.control.SimulationProgress
        UseMissionProfileCheckBox       matlab.ui.control.CheckBox
        SchematicPanel_3                matlab.ui.container.Panel
        TopologyImage_3                 matlab.ui.control.Image
        Panel_3                         matlab.ui.container.Panel
        DownloadMissionProfileTemplateButton  matlab.ui.control.Button
        MissionProfileStatusLabel       matlab.ui.control.Label
        ImageLoadSymbol                 matlab.ui.control.Image
        LoadMissionProfileButton        matlab.ui.control.Button
        MissionProfileTable             matlab.ui.control.Table
        ContinueButton6                 matlab.ui.control.Button
        MissionprofileoptionalLabel     matlab.ui.control.Label
        SummaryTab                      matlab.ui.container.Tab
        SummaryPanel                    matlab.ui.container.Panel
        TotalLossesEditField            matlab.ui.control.NumericEditField
        TotalLossesLabel                matlab.ui.control.Label
        ConductionLossesEditField       matlab.ui.control.NumericEditField
        ConductionlossesWLabel          matlab.ui.control.Label
        DiodeLossesEditField            matlab.ui.control.NumericEditField
        DiodelossesWLabel               matlab.ui.control.Label
        SwitchingLossesEditField        matlab.ui.control.NumericEditField
        SwitchinglossesWLabel           matlab.ui.control.Label
        EfficiencyEditField             matlab.ui.control.NumericEditField
        EfficiencyLabel                 matlab.ui.control.Label
        SimulationstatusLabel           matlab.ui.control.Label
        SelecteddiodetypeLabel          matlab.ui.control.Label
        SelectedswitchtopologyLabel     matlab.ui.control.Label
        SelectedtopologyLabel           matlab.ui.control.Label
        SimulationSummaryLabel          matlab.ui.control.Label
        ThermalEvolutionPlot            matlab.ui.control.UIAxes
        LossesAxes                      matlab.ui.control.UIAxes
    end

    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

        properties (Access = private)
            ACDCExpanded logical = false
            DCDCExpanded logical = false
            DCACExpanded logical = false
            SelectedTopology string = ""
            SimulationData struct = struct()
            MissionProfileLoaded logical = false
            ReverseRecoveryAccounting string = "Separate"
            IncludeExternalBoostDiodeLosses string = "Yes"
        end
        
        methods (Access = private)
            
            function tf = includeExternalBoostDiode(app)
                setting = lower(strtrim(app.IncludeExternalBoostDiodeLosses));
                switch setting
                    case "yes"
                        tf = true;
                    case "no"
                        tf = false;
                    otherwise
                        error('Use "Yes" or "No" for IncludeExternalBoostDiodeLosses.');
                end
            end
            function plotFixedOperatingPointTemperature(app)
                if ~isfield(app.SimulationData,'Results')
                    return
                end
                R  = app.SimulationData.Results;
                th = getThermalParams(app);
                Pcond_ref = R.Psw_cond_avg;
                Psw_W     = R.Psw_switch_avg;
                PextFwd  = 0;
                PbodyFwd = 0;
                PrrExt   = 0;
                PrrBody  = 0;
                PoutSim  = 0;

                if isfield(R,'Pdiode_ext_fwd_avg') && isfinite(R.Pdiode_ext_fwd_avg)
                    PextFwd = R.Pdiode_ext_fwd_avg;
                end
                if isfield(R,'Pdiode_body_fwd_avg') && isfinite(R.Pdiode_body_fwd_avg)
                    PbodyFwd = R.Pdiode_body_fwd_avg;
                end
                if isfield(R,'Prr_ext_avg') && isfinite(R.Prr_ext_avg)
                    PrrExt = R.Prr_ext_avg;
                end
                if isfield(R,'Prr_body_avg') && isfinite(R.Prr_body_avg)
                    PrrBody = R.Prr_body_avg;
                end
                if isfield(R,'Pout_avg') && isfinite(R.Pout_avg)
                    PoutSim = R.Pout_avg;
                end
                finiteTau = [th.tau_foster_sw(:); th.tau_ha];
                finiteTau = finiteTau(isfinite(finiteTau) & finiteTau > 0);

                duration_s = max(60, 5*max(finiteTau));
                outputStep_s = max(0.01, min(1, duration_s/500));

                M = table(duration_s, Pcond_ref, Psw_W, ...
                    PextFwd, PbodyFwd, PrrExt, PrrBody, PoutSim, ...
                    'VariableNames', {'Duration_s','Pcond_W','Psw_W', ...
                    'Pdiode_ext_fwd_W','Pdiode_body_fwd_W', ...
                    'Prr_ext_W','Prr_body_W','Pout_sim_W'});
                Tprof = table(th.Tamb_C, 'VariableNames', {'T_amb_C'});
                [TT, ~] = buildMissionThermalTrace1min(app, M, Tprof, outputStep_s);
                app.SimulationData.FixedPointThermalTrace = TT;
                ax = app.ThermalEvolutionPlot;
                cla(ax);
                plot(ax, TT.time_min, TT.Tj_C,'LineWidth', 1.8);
                xlabel(ax, 'Time [min]');
                ylabel(ax, 'Junction temperature [°C]');
                title(ax, 'Junction Temperature – Fixed Operating Point');
                grid(ax, 'on');
            end
            function tf = validateDeviceInputs(app)
                if ismember(app.SelectedTopology, ["Boost","Buck","Flyback"])
                    positiveValues = [app.VinEditField_Boost.Value, app.VoutEditField_Boost.Value, ...
                        app.PoutEditField_Boost.Value, app.fswEditField_Boost.Value, app.CoutEdit_Field.Value];
                    if app.SelectedTopology == "Flyback"
                        positiveValues(end + 1) = app.LmEditField.Value;
                        nonnegativeValues = [app.LlkEditField.Value, app.CoutESREditField.Value];
                    else
                        positiveValues(end + 1) = app.LEditField_Boost_2.Value;
                        nonnegativeValues = [];
                    end
                else
                    positiveValues = [app.VdcEditField.Value, app.PoutACEditField.Value, ...
                        app.foutEditField.Value, app.fswACEditField.Value, app.VgsDriveACEditField.Value];
                    nonnegativeValues = [app.RgOnACEditField.Value, app.RgOffACEditField.Value];
                end
                tf = all(isfinite(positiveValues) & positiveValues > 0) && ...
                    all(isfinite(nonnegativeValues) & nonnegativeValues >= 0);
                if tf
                    tf = validateSwitchInputs(app);
                end
            end
            function assignVariables(~, workspace, values)
                names = fieldnames(values);
                for k = 1:numel(names)
                    assignin(workspace, names{k}, values.(names{k}));
                end
            end
            function exportACParamsToWorkspace(app)
                topology = app.SelectedTopology;
                Vdc = app.VdcEditField.Value;
                Pout = app.PoutACEditField.Value;
                fout = app.foutEditField.Value;
                fsw = app.fswACEditField.Value;
                PF = app.PFEditField.Value;
                ModIndex = app.ModIndexEditField.Value;
                Vg = app.VgsDriveACEditField.Value;
                Rg_on = app.RgOnACEditField.Value;
                Rg_off = app.RgOffACEditField.Value;
                if any(~isfinite([Vdc,Pout,fout,fsw])) || any([Vdc,Pout,fout,fsw] <= 0)
                    error('AC converter parameters must be finite and strictly positive.');
                end
                if ~isfinite(PF) || PF <= 0 || PF > 1
                    error('Power factor PF must be in the interval (0,1].');
                end
                if ~isfinite(ModIndex) || ModIndex < 0 || ModIndex > 1
                    error('Modulation index must be in the interval [0,1].');
                end
                if ~isfinite(Vg) || Vg <= 0
                    error('Gate-drive voltage must be finite and strictly positive.');
                end
                if any(~isfinite([Rg_on,Rg_off])) || any([Rg_on,Rg_off] <= 0)
                    error('Gate resistances Rg_on and Rg_off must be finite and non-negative.');
                end
            
                sw = getSwitchParams(app);
                exportIGBTParamsToBase(app, sw);
                tech = sw.Type;
                if strcmp(tech, 'GaN MOSFETs') && Vg > 7
                    error('GaN gate-drive voltage is too high. Use a datasheet-based value, typically 5 V to 6 V.');
                end
                diode = exportDiodeParamsToBase(app);
                phi = acos(PF);
            
                switch topology
                    case {"Half-bridge inverter","Full-bridge inverter"}
                        VoutRMS = app.VoutRMSEditField.Value;
                        if ~isfinite(VoutRMS) || VoutRMS <= 0
                            error('Output RMS voltage must be finite and strictly positive.');
                        end
                        Irms = Pout / (VoutRMS * PF);
                        Zload = VoutRMS / Irms;
                        PWMStrategy = app.PWMStrategyDropDown.Value;
                        topologyVars = struct('VoutRMS', VoutRMS, 'Vout_ref', VoutRMS);
            
                    case "Three-phase inverter"
                        VLLRMS = app.VLLRMSEditField.Value;
                        if ~isfinite(VLLRMS) || VLLRMS <= 0
                            error('Line-line RMS voltage must be finite and strictly positive.');
                        end
                        VphRMS = VLLRMS / sqrt(3);
                        Irms = Pout / (sqrt(3) * VLLRMS * PF);
                        Zload = VphRMS / Irms;
                        PWMStrategy = app.PWMStrategy3phDropDown.Value;
                        topologyVars = struct('VLLRMS', VLLRMS, 'VphRMS', VphRMS, 'Vout_ref', VphRMS);
            
                    otherwise
                        error('Unsupported topology in exportACParamsToWorkspace.');
                end
                Rload = Zload*cos(phi);
                Lload = Zload*sin(phi)/(2*pi*fout);
                [Lf, Cf, fcFilter] = calculateAutomaticACFilter(app);
                Cdc = 220e-6;
                Tsw = 1 / fsw;
                switch tech
                    case 'GaN MOSFETs'
                        tdead = 200e-9;
                    case 'SiC MOSFETs'
                        tdead = 500e-9;
                    otherwise
                        tdead = 1e-6;
                end
                Ts_ctrl  = min(Tsw/200, tdead/5); 
                Ts_local = 5e-9;                 

                deadSamples = max(1, ceil(tdead/Ts_ctrl));
                actualDeadTime = deadSamples*Ts_ctrl;
                
                fprintf('Control sample time: %.0f ns\n', Ts_ctrl*1e9);
                fprintf('Dead time: %d samples = %.0f ns\n', deadSamples, actualDeadTime*1e9);
            
                Ciss_sw = max(sw.Ciss, 1e-15);
                Crss_sw = max(sw.Crss, 1e-15);
                Coss_sw = max(sw.Coss, 1e-15);
                vars = struct('Vdc', Vdc, 'Vin', Vdc, 'Pout', Pout, 'fout', fout, 'fsw', fsw, ...
                    'PF', PF, 'ModIndex', ModIndex, 'PWMStrategy', PWMStrategy, 'Irms', Irms, ...
                    'phi', phi, 'Rload', Rload, 'Lload', Lload, 'L', Lload, 'Lf', Lf, 'Cf', Cf, 'fcFilter', fcFilter, 'Cdc', Cdc, 'Cout', Cdc, ...
                    'Vg', Vg, 'Rg_on', Rg_on, 'Rg_off', Rg_off, 'RdsOn', sw.RdsOn, ...
                    'Ids_RdsOn', sw.Ids_RdsOn, 'Vgs_RdsOn', sw.Vgs_RdsOn, 'Vth_sw', sw.Vth_sw, ...
                    'Ciss_sw', Ciss_sw, 'Crss_sw', Crss_sw, 'Coss_sw', Coss_sw, ...
                    'Ciss_sw_pF', Ciss_sw*1e12, 'Crss_sw_pF', Crss_sw*1e12, 'Coss_sw_pF', Coss_sw*1e12, ...
                    'SwitchTechnology', tech, 'Qg_sw', sw.Qg, 'Qoss_sw', sw.Qoss, ...
                    'Eon_sw', sw.Eon, 'Eoff_sw', sw.Eoff, 'Vf', diode.Vf, 'RonD', diode.Ron, ...
                    'Ts', Ts_local, ...
                    'Ts_local', Ts_local, ...
                    'Ts_ctrl', Ts_ctrl, ...
                    'tdead', tdead, ...
                    'deadSamples', deadSamples, ...
                    'actualDeadTime', actualDeadTime);
                assignVariables(app, 'base', topologyVars);
                assignVariables(app, 'base', vars);
                exportThermalParamsToBase(app);
            end
            function [Lf, Cf, fcFilter] = calculateAutomaticACFilter(app)
                Pout = app.PoutACEditField.Value;
                fout = app.foutEditField.Value;
                fsw = app.fswACEditField.Value;
                switch app.SelectedTopology
                    case "Half-bridge inverter"
                        Vrms = app.VoutRMSEditField.Value;
                    case "Full-bridge inverter"
                        % Fixed validation filter:
                        % Vdc = 400 V, Vout = 230 Vrms,
                        % Pout = 1 kW, PF = 0.95
                        Lf = 4.05e-3;
                        Cf = 18e-6;
                        fcFilter = 1/(2*pi*sqrt(Lf*Cf));
                        return
                    case "Three-phase inverter"
                        Vrms = app.VLLRMSEditField.Value;
                    otherwise
                        error('Automatic AC filter calculation requires an inverter topology.');
                end
                values = [Pout, fout, fsw, Vrms];
                if any(~isfinite(values)) || any(values <= 0)
                    error('Invalid values for automatic AC filter calculation.');
                end
                capacitorReactivePowerFraction = 0.05;
                fcFilter = sqrt(fout*fsw);
                Cf = capacitorReactivePowerFraction*Pout/(2*pi*fout*Vrms^2);
                Lf = 1/((2*pi*fcFilter)^2*Cf);
                if any(~isfinite([Lf,Cf,fcFilter])) || Lf <= 0 || Cf <= 0
                    error('Automatically calculated AC filter values are invalid.');
                end
            end
            function M = readDeviceSignalMatrix( ...
                    app, simOut, namePatterns, nDevices, tRef, allowZeroFallback)

                tRef = tRef(:);
                nSamples = numel(tRef);
                M = zeros(nSamples, nDevices);
                if any(~isfinite(tRef)) || any(diff(tRef) <= 0)
                    error('Reference time vector is invalid.');
                end
                for k = 1:nDevices

                    candidateNames = cell(size(namePatterns));
                    for p = 1:numel(namePatterns)
                        candidateNames{p} = sprintf(namePatterns{p}, k);
                    end
                    try
                        sig = getSignalFromSimOut(app, simOut, candidateNames);
                        [tSig, y] = unpackSimSignal(app, sig, candidateNames{1});
                        tSig = tSig(:);
                        y = squeeze(y);
                        if ~isvector(y)
                            error('Device signal must contain one scalar channel.');
                        end
                        y = y(:);
                        if numel(tSig) ~= numel(y) || any(~isfinite(tSig)) || any(diff(tSig) <= 0) || any(~isfinite(y))
                            error('Signal time/data are invalid.');
                        end
                        timeTolerance = max(1e-12, 10*eps(max(abs(tRef(end)),1)));

                        if numel(tSig) == nSamples && max(abs(tSig-tRef)) <= timeTolerance
                            M(:,k) = y;
                        else
                            error(['%s does not use the common simulation time vector. ' ...
                                'Do not interpolate switching waveforms. Configure all VDS, Isw, ' ...
                                'VGS, ID and VD logging blocks with identical sample time and ' ...
                                'decimation = 1.'],candidateNames{1});
                        end
                    catch ME
                        if allowZeroFallback
                            warning('Using zero fallback for %s.', strjoin(candidateNames, ', '));
                            M(:,k) = 0;
                        else
                            error('Could not align device %d. Tried: %s. Original error: %s', ...
                                k, strjoin(candidateNames, ', '), ME.message);
                        end
                    end
                end
            end   
            function runACSimulation(app)
                exportACParamsToWorkspace(app);
                modelName = getImplementedModelName(app);
                load_system(modelName);
                sw = getSwitchParams(app);

                if strcmp(sw.Type, 'Si IGBTs')
                    exportIGBTParamsToModelWorkspace(app, modelName, sw);
                end
                modelFile = get_param(modelName,'FileName');

                fprintf('\nMODEL USED BY APP\n');
                fprintf('Name:  %s\n', modelName);
                fprintf('File:  %s\n', modelFile);
                fprintf('Dirty: %s\n', get_param(modelName,'Dirty'));

                disp('All files with this name:');
                disp(which([modelName '.slx'],'-all'));
                waitUntilModelStopped(app, modelName);
                simStopTime = calculateAutomaticACStopTime(app);
                assignin('base','simStopTime',simStopTime);
                simOut = runModelWithSimulationProgress(app, modelName, simStopTime);
                waitUntilModelStopped(app, modelName);
                app.SimulationData.SimOut = simOut;
                sigIin = getSignalFromSimOut( app,simOut,{'Iin_sim','Idc_sim','Iin'});
                [tIin,Iin] = unpackSimSignal(app,sigIin,'Iin');
                tIin = tIin(:);
                Iin = Iin(:);
                if numel(tIin) ~= numel(Iin) || numel(tIin) < 3 || any(~isfinite(tIin)) || any(diff(tIin) <= 0) || any(~isfinite(Iin))
                    error('Iin_sim contains invalid time or current data.');
                end
                app.SimulationData.Results.tIin_sim = tIin;
                app.SimulationData.Results.Iin_sim = Iin;
                topology = app.SelectedTopology;
                nDevices = getActiveSwitchCount(app);
                allowZeroDiodeSignals = false;
                switch topology
                    case {"Half-bridge inverter", "Full-bridge inverter"}
                        sigVout = getSignalFromSimOut(app, simOut, {'Vout_sim','Vo_sim','Vout'});
                        sigIout = getSignalFromSimOut(app, simOut, {'Iout_sim','Io_sim','Iout'});
                        [t, Vout] = unpackSimSignal(app, sigVout, 'Vout');
                        [~, Iout] = unpackSimSignal(app, sigIout, 'Iout');
                        t = t(:);
                        Vout = Vout(:);
                        Iout = Iout(:);
                        Pout_inst = Vout .* Iout;
                        app.SimulationData.Results.t= t;
                        app.SimulationData.Results.Vout_sim = Vout;
                        app.SimulationData.Results.Iout_sim = Iout;
                    case "Three-phase inverter"
                        sigVa = getSignalFromSimOut(app, simOut, {'Va_sim'});
                        sigVb = getSignalFromSimOut(app, simOut, {'Vb_sim'});
                        sigVc = getSignalFromSimOut(app, simOut, {'Vc_sim'});
                        sigIa = getSignalFromSimOut(app, simOut, {'Ia_sim'});
                        sigIb = getSignalFromSimOut(app, simOut, {'Ib_sim'});
                        sigIc = getSignalFromSimOut(app, simOut, {'Ic_sim'});
                        [t, Va] = unpackSimSignal(app, sigVa, 'Va');
                        [~, Vb] = unpackSimSignal(app, sigVb, 'Vb');
                        [~, Vc] = unpackSimSignal(app, sigVc, 'Vc');
                        [~, Ia] = unpackSimSignal(app, sigIa, 'Ia');
                        [~, Ib] = unpackSimSignal(app, sigIb, 'Ib');
                        [~, Ic] = unpackSimSignal(app, sigIc, 'Ic');
                        t  = t(:);
                        Va = Va(:);
                        Vb = Vb(:);
                        Vc = Vc(:);
                        Ia = Ia(:);
                        Ib = Ib(:);
                        Ic = Ic(:);
                        Pout_inst = Va .* Ia + Vb .* Ib + Vc .* Ic;
                        app.SimulationData.Results.t      = t;
                        app.SimulationData.Results.Va_sim = Va;
                        app.SimulationData.Results.Vb_sim = Vb;
                        app.SimulationData.Results.Vc_sim = Vc;
                        app.SimulationData.Results.Ia_sim = Ia;
                        app.SimulationData.Results.Ib_sim = Ib;
                        app.SimulationData.Results.Ic_sim = Ic;
                    otherwise
                        error('runACSimulation supports only DC/AC topologies.');
                end
                nSamples = numel(t);
                VDS = readDeviceSignalMatrix(app, simOut, {'VDS%d_sim','Vds%d_sim'}, nDevices, t, false);
                Isw = readDeviceSignalMatrix(app, simOut, {'Isw%d_sim','Isw%d_ism'}, nDevices, t, false);
                ID = readDeviceSignalMatrix(app, simOut, {'ID%d_sim','Id%d_sim'}, nDevices, t, false);
                VD = readDeviceSignalMatrix(app, simOut, {'VD%d_sim','Vd%d_sim'}, nDevices, t, false);

                VGS = readDeviceSignalMatrix(app, simOut, ...
                    {'VGS%d_sim','Vgs%d_sim'}, nDevices, t, false);

                app.SimulationData.Results.VGS_sim = VGS;

                % Actual switch state from measured gate-emitter voltage
                sw = getSwitchParams(app);
                G = double(VGS >= sw.Vth_sw);
                % Define switches belonging to each bridge leg.
                % Numbering:
                % G1 = left upper,  G2 = left lower
                % G3 = right upper, G4 = right lower
                if topology == "Half-bridge inverter"
                    legPairs = [1 2];
                    legNames = "left leg";
                elseif topology == "Full-bridge inverter"
                    legPairs = [1 2; 3 4];
                    legNames = ["left leg"; "right leg"];
                else
                    legPairs = zeros(0,2);
                    legNames = strings(0,1);
                end

                % Shoot-through check using actual measured VGS.
                if ~isempty(legPairs)
                    sw = getSwitchParams(app);
                    dtGate = median(diff(t), 'omitnan');
                    overlapTolerance = 2*dtGate;
                    for leg = 1:size(legPairs,1)
                        upperDev = legPairs(leg,1);
                        lowerDev = legPairs(leg,2);
                        overlap = (VGS(:,upperDev) > sw.Vth_sw) & (VGS(:,lowerDev) > sw.Vth_sw);
                        transitions = diff([false; overlap; false]);
                        overlapStarts = find(transitions == 1);
                        overlapStops  = find(transitions == -1) - 1;
                        if isempty(overlapStarts)
                            maxOverlapTime = 0;
                            totalOverlapTime = 0;
                        else
                            overlapDurations = t(overlapStops) - t(overlapStarts) + dtGate;
                            maxOverlapTime = max(overlapDurations);
                            totalOverlapTime = sum(overlapDurations);
                        end
                        fprintf('\n%s GATE CHECK\n', upper(char(legNames(leg))));
                        fprintf('Maximum overlap = %.1f ns\n', maxOverlapTime*1e9);
                        fprintf('Total overlap = %.3f us\n', totalOverlapTime*1e6);
                        %if maxOverlapTime > overlapTolerance
                         %   error(['SHOOT-THROUGH in %s: VGS%d and VGS%d are ' ...
                         %       'simultaneously above threshold for %.1f ns.'], char(legNames(leg)), upperDev, lowerDev, maxOverlapTime*1e9);
                        %end
                    end
                end
                if topology == "Full-bridge inverter" && strcmp(app.PWMStrategyDropDown.Value, 'Bipolar PWM')
                    diagonalMismatch = (G(:,1) ~= G(:,4)) | (G(:,2) ~= G(:,3));
                    mismatchFraction = nnz(diagonalMismatch)/numel(diagonalMismatch);
                    if mismatchFraction > 1e-3
                        error(['Incorrect bipolar full-bridge gate commands. ' ...
                            'Expected G1=G4 and G2=G3.']);
                    end
                end
                if ismember(topology, ["Half-bridge inverter","Full-bridge inverter"])
                    idxCheck = getSteadyStateFundamentalWindow(app,t,3);
                    IoutRMS = sqrt(averageOverWindow( app,t(idxCheck),Iout(idxCheck).^2));
                    Iexpected = app.PoutACEditField.Value / (app.VoutRMSEditField.Value * app.PFEditField.Value);
                    fprintf('\n%s CHECK\n', upper(char(topology)));
                    fprintf('Expected Iout RMS = %.3f A\n', Iexpected);
                    fprintf('Measured Iout RMS = %.3f A\n', IoutRMS);
                    for dev = 1:nDevices
                        fprintf('Isw%d peak = %.3f A\n', dev,max(abs(Isw(:,dev))));
                    end
                end
                app.SimulationData.Results.VDS_sim = VDS;
                app.SimulationData.Results.Isw_sim = Isw;
                app.SimulationData.Results.VD_sim = VD;
                app.SimulationData.Results.ID_sim = ID;
                app.SimulationData.Results.G_sim  = G;
                for k = 1:nDevices
                    app.SimulationData.Results.(sprintf('VDS%d_sim',k)) = VDS(:,k);
                    app.SimulationData.Results.(sprintf('VGS%d_sim',k)) = VGS(:,k);
                    app.SimulationData.Results.(sprintf('Isw%d_sim',k)) = Isw(:,k);
                    app.SimulationData.Results.(sprintf('VD%d_sim',k))  = VD(:,k);
                    app.SimulationData.Results.(sprintf('ID%d_sim',k))  = ID(:,k);
                    app.SimulationData.Results.(sprintf('G%d_sim',k))   = G(:,k);
                end
                finalizeLossResults(app, t, Pout_inst, Isw, ID, G);
                validateOperatingPointResults(app);
            end
            function exportIGBTParamsToModelWorkspace(app, modelName, sw)
                if nargin < 3
                    sw = getSwitchParams(app);
                end
                if ~strcmp(sw.Type, 'Si IGBTs')
                    return
                end
                values = struct('VceSat', sw.VceSat, 'Ic_VceSat', sw.IcVceSat, ...
                    'Vge_VceSat', sw.VgeVceSat, 'VCE0_IGBT', sw.VCE0, 'rCE_IGBT', sw.rCE, ...
                    'Eref_I_IGBT', sw.Eref_I, 'Eref_V_IGBT', sw.Eref_V, 'Eref_T_IGBT', sw.Eref_T, ...
                    'VgeTh', sw.VgeTh, 'Vth', sw.VgeTh, 'Vth_sw', sw.VgeTh, ...
                    'Ices', sw.Ices, 'Vces_Ices', sw.VcesIces, 'Tmeas_IGBT', sw.TmeasIGBT, ...
                    'Eon_sw', sw.Eon, 'Eoff_sw', sw.Eoff, ...
                    'Cies_IGBT_pF', sw.Cies_IGBT_pF, 'Cres_IGBT_pF', sw.Cres_IGBT_pF, ...
                    'Coes_IGBT_pF', sw.Coes_IGBT_pF, 'Cies_IGBT_nF', sw.Cies_IGBT_nF, ...
                    'Cres_IGBT_nF', sw.Cres_IGBT_nF, 'Coes_IGBT_nF', sw.Coes_IGBT_nF, ...
                    'Cies_IGBT', sw.Cies_IGBT, 'Cres_IGBT', sw.Cres_IGBT, 'Coes_IGBT', sw.Coes_IGBT);
                assignVariables(app, get_param(modelName, 'ModelWorkspace'), values);
            end
            function finalizeLossResults(app, varargin)
                if ~isfield(app.SimulationData, 'Results') || isempty(app.SimulationData.Results)
                    app.SimulationData.Results = struct();
                end
                R = app.SimulationData.Results;
                if nargin == 6
                     [t, Pout_inst, Isw, ID, G] = varargin{:};
                elseif nargin == 1
                    if ~isfield(R, 't')
                        error('Cannot finalize losses because Results.t is missing.');
                    end
                    t = R.t;
                    if isfield(R, 'Pout_inst')
                        Pout_inst = R.Pout_inst;
                    elseif all(isfield(R, {'Vout_sim','Iout_sim'}))
                        Pout_inst = R.Vout_sim .* R.Iout_sim;
                    elseif all(isfield(R, {'Va_sim','Vb_sim','Vc_sim','Ia_sim','Ib_sim','Ic_sim'}))
                        Pout_inst = R.Va_sim .* R.Ia_sim + R.Vb_sim .* R.Ib_sim + R.Vc_sim .* R.Ic_sim;
                    else
                        error('Cannot reconstruct output power from the available signals.');
                    end
                    if isfield(R, 'Isw_sim')
                        Isw = R.Isw_sim;
                    else
                        Isw = collectNumberedSignals(app, R, 'Isw');
                    end
                    if isfield(R, 'G_sim')
                        G = R.G_sim;
                    else
                        G = collectNumberedSignals(app, R, 'G');
                    end
                    if isfield(R, 'ID_sim')
                        ID = R.ID_sim;
                    else
                        try
                            ID = collectNumberedSignals(app, R, 'ID');
                        catch
                            ID = zeros(numel(t), 1);
                        end
                    end
            
                else
                    error('Use finalizeLossResults(app) or finalizeLossResults(app,t,Pout_inst,Isw,ID).');
                end
                t = t(:);
                Pout_inst = squeeze(Pout_inst);
                Isw = squeeze(Isw);
                ID = squeeze(ID);
                G  = squeeze(G);
                if isvector(Pout_inst), Pout_inst = Pout_inst(:); end
                if isvector(Isw), Isw = Isw(:); end
                if isvector(ID), ID = ID(:); end
                if isvector(G), G = G(:); end
                n = min([numel(t), size(Pout_inst,1),size(Isw,1),size(ID,1),size(G,1)]);
                if size(Isw,2) ~= size(G,2)
                    error('Isw and G must have one matched column per MOSFET.');
                end
                if n < 2
                    error('Not enough samples to calculate losses.');
                end
                t = t(1:n);
                Pout_inst = Pout_inst(1:n,:);
                Isw = Isw(1:n,:);
                ID = ID(1:n,:);
                G = G(1:n,:);
                if ismember(app.SelectedTopology, ...
                        ["Half-bridge inverter","Full-bridge inverter","Three-phase inverter"])
                    idx = getSteadyStateFundamentalWindow(app, t, 3);
                else
                    idx = max(1, floor(0.8*n)):n;
                end
                t_ss = t(idx);
                VDS = squeeze(R.VDS_sim);
                if isvector(VDS)
                    VDS = VDS(:);
                end
                if size(VDS,1) < n
                    error('VDS_sim is shorter than the main simulation time vector.');
                end
                VDS = VDS(1:n,:);
                VDS_ss = VDS(idx,:);
                if ~isfield(R,'VGS_sim')
                    error('VGS_sim is required for accurate AC MOSFET loss calculation.');
                end
                VGS = squeeze(R.VGS_sim);
                if isvector(VGS)
                    VGS = VGS(:);
                end
                if size(VGS,1) < n
                    error('VGS_sim is shorter than the main simulation time vector.');
                end
                VGS = VGS(1:n,:);
                VGS_ss = VGS(idx,:);
                Psw_cond_inst = computeACSwitchConductionLoss(app,t,Isw,VDS,VGS);
                Psw_cond_avg = averageOverWindow(app,t_ss,Psw_cond_inst(idx,:));
                sw = getSwitchParams(app);
                Gactual = double(VGS_ss >= sw.Vth_sw);
                [Psw_switch_avg, swEvents] = computeSwitchingLossSelected( app, t_ss, Gactual,VDS_ss, Isw(idx,:));

                if strcmp(sw.Type,'Si IGBTs') && app.SelectedTopology == "Half-bridge inverter"
                    try
                        [Psw_wave_diag, waveEventsDiag] = computeSwitchingLossFromGateEvents( app, t_ss, VDS_ss, Isw(idx,:),Gactual);
                        % Average measured event energies
                        Eon_wave  = NaN;
                        Eoff_wave = NaN;
                        if istable(waveEventsDiag) && ~isempty(waveEventsDiag)
                            onRows  = waveEventsDiag.Type == "Turn-on";
                            offRows = waveEventsDiag.Type == "Turn-off";
                            if any(onRows)
                                Eon_wave = mean( waveEventsDiag.Energy_J(onRows), ...
                                    'omitnan');
                            end
                            if any(offRows)
                                Eoff_wave = mean(waveEventsDiag.Energy_J(offRows), ...
                                    'omitnan');
                            end
                        end
                        difference_W = Psw_wave_diag - Psw_switch_avg;
                        difference_percent = 100*difference_W/max(abs(Psw_switch_avg),eps);
                        % Also make values available in MATLAB base workspace
                        assignin('base', 'Psw_IGBT_datasheet_W', ...
                            Psw_switch_avg);
                        assignin('base', 'Psw_IGBT_waveform_W', ...
                            Psw_wave_diag);
                        assignin('base', 'IGBT_waveform_switching_events', ...
                            waveEventsDiag);

                    catch MEwave

                        fprintf('\n');

                    end

                end
                Pcap_avg = 0;
                Psw_overlap_avg = Psw_switch_avg;
                Pgate_avg = 0;
                if istable(swEvents) && ~isempty(swEvents)
                    names = swEvents.Properties.VariableNames;
                    if ismember('Pcap_W', names), Pcap_avg = swEvents.Pcap_W(1); end
                    if ismember('Poverlap_W', names), Psw_overlap_avg = swEvents.Poverlap_W(1); end
                    if ismember('Pgate_W', names), Pgate_avg = swEvents.Pgate_W(1); end
                end
                R = app.SimulationData.Results;
                if ~isfield(R, 'VD_sim')
                    error('VD_sim is required for measured half-bridge diode losses.');
                end
                VD = squeeze(R.VD_sim);
                if isvector(VD)
                    VD = VD(:);
                end
                if size(VD, 1) < n
                    error('VD_sim is shorter than the main time vector.');
                end
                VD = VD(1:n, :);
                Pdiode_ext_inst = zeros(n, 1);
                Pdiode_ext_fwd_avg = 0;
                Prr_ext_avg = 0;
                Pdiode_body_fwd_ss = computeMeasuredDiodeForwardPower( app, ID(idx,:), VD(idx,:));

                [~, Prr_body_ss, Prr_body_avg, rrBodyEvents] = ...
                    computeParallelDiodeLossesFromWaveforms( ...
                    app, t_ss, ID(idx,:), VD(idx,:), Gactual);
                if app.SelectedTopology == "Full-bridge inverter"
                    IDss = ID(idx,:);
                    VDss = VD(idx,:);
                    TobsD = t_ss(end) - t_ss(1);
                    totalPfwdDiag = 0;
                
                    for dev = 1:size(IDss,2)
                
                        Idev = IDss(:,dev);
                        Vdev = VDss(:,dev);
                
                        Ipeak = max(abs(Idev));
                        Vpeak = max(abs(Vdev));
                        if Ipeak <= 1e-9
                            PfwdDev = 0;
                            IfwdMean = 0;
                            dutyForward = 0;
                        else
                            Ithreshold = max(1e-6,0.005*Ipeak);
                            Vthreshold = max(1e-6,1e-4*Vpeak);
                            forwardMask = Idev > Ithreshold & ...
                                Vdev > Vthreshold;
                            pForwardDev = max(Vdev .* Idev,0) .* double(forwardMask);
                            PfwdDev = trapz(t_ss,pForwardDev) / TobsD;
                            IfwdMean = trapz(t_ss, max(Idev,0).*double(forwardMask)) / TobsD;
                            dutyForward = 100 * trapz(t_ss,double(forwardMask)) / TobsD;
                        end                
                        totalPfwdDiag = totalPfwdDiag + PfwdDev;
                        nRR = 0;
                        ErrSum = 0;
                        QrrSum = 0;
                        if istable(rrBodyEvents) && ~isempty(rrBodyEvents)
                            rrRows = rrBodyEvents.Diode == dev;
                            nRR = nnz(rrRows);
                            if nRR > 0
                                ErrSum = sum(rrBodyEvents.Err_J(rrRows), 'omitnan');
                                QrrSum = sum(rrBodyEvents.Qrr_C(rrRows), 'omitnan');
                            end
                        end
                        fprintf(['D%d: Pfwd = %.6f W | Ifwd mean = %.6f A | ' ...
                                 'forward duty = %.2f %% | RR events = %d | sum Err = %.6f mJ | ' ...
                                 'sum Qrr = %.3f uC\n'], dev, PfwdDev, IfwdMean, dutyForward, nRR, ErrSum*1e3, QrrSum*1e6);
                
                    end
                end
                Pdiode_body_fwd_inst = zeros(n,1);
                Pdiode_body_fwd_inst(idx) = Pdiode_body_fwd_ss;

                Prr_body_inst = zeros(n,1);
                Prr_body_inst(idx) = Prr_body_ss;

                Pdiode_body_inst = ...
                    Pdiode_body_fwd_inst + Prr_body_inst;

                Pdiode_body_fwd_avg = ...
                    averageOverWindow( ...
                    app, t_ss, Pdiode_body_fwd_ss);
                Prr_reported_avg = Prr_body_avg;
                if app.ReverseRecoveryAccounting ~= "Separate"
                    error('For separate MOSFET-channel and parallel-diode measurements, ReverseRecoveryAccounting must be "Separate".');
                end
                Prr_ext_included = 0;
                Prr_body_included = Prr_body_avg;
                Prr_included_avg = Prr_ext_included + Prr_body_included;
                Pdiode_ext_avg = Pdiode_ext_fwd_avg + Prr_ext_included;
                Pdiode_body_avg = Pdiode_body_fwd_avg + Prr_body_included;
                Pdiode_fwd_avg = Pdiode_ext_fwd_avg + Pdiode_body_fwd_avg;
                Pdiode_avg = Pdiode_fwd_avg + Prr_included_avg;
                fprintf(['STORED DIODE CHECK: forward = %.6f W | ' ...
                    'RR = %.6f W | total = %.6f W\n'], ...
                    Pdiode_fwd_avg, ...
                    Prr_included_avg, ...
                    Pdiode_avg);
                R.t = t;
                R.Isw_sim = Isw;
                R.ID_sim = ID;
                R.G_sim = G;

                R.Pout_inst = Pout_inst;
                R.Psw_cond_inst = Psw_cond_inst;
                R.Psw_switch_inst = repmat(Psw_switch_avg, n, 1);
                R.Pdiode_inst = Pdiode_ext_inst + Pdiode_body_inst;
                R.Pout_avg = averageOverWindow(app, t_ss, Pout_inst(idx,:));
                R.Psw_cond_avg = averageOverWindow(app, t_ss, Psw_cond_inst(idx,:));
                R.Psw_switch_avg = Psw_switch_avg;
                R.Psw_overlap_avg = Psw_overlap_avg;
                R.Pcap_avg = Pcap_avg;
                R.Pgate_avg = Pgate_avg;
                R.Pdiode_ext_fwd_avg = Pdiode_ext_fwd_avg;
                R.Pdiode_body_fwd_avg = Pdiode_body_fwd_avg;
                R.Pdiode_fwd_avg = Pdiode_fwd_avg;
                R.Prr_ext_avg = Prr_ext_avg;
                R.Prr_ext_reported_avg = Prr_ext_avg;
                R.Prr_body_avg = Prr_body_avg;
                R.Prr_avg = Prr_reported_avg;
                R.Prr_reported_avg = Prr_reported_avg;
                R.Prr_included_avg = Prr_included_avg;
                R.ReverseRecoveryBodyEvents = rrBodyEvents;
                R.Pdiode_ext_avg = Pdiode_ext_avg;
                R.Pdiode_body_avg = Pdiode_body_avg;
                R.Pdiode_avg = Pdiode_avg;
                R.Ptotal_avg = R.Psw_cond_avg + R.Psw_switch_avg + R.Pdiode_avg;
                if R.Pout_avg > 0
                    R.EfficiencySemiconductor = 100 * R.Pout_avg / (R.Pout_avg + R.Ptotal_avg);
                else
                    R.EfficiencySemiconductor = 0;
                end
                R.Pin_avg = NaN;
                R.Ploss_measured_avg = NaN;
                R.EfficiencyMeasured = NaN;
                R.PowerBalanceError_W = NaN;
                R.PowerBalanceError_percent = NaN;
                R.InputPowerMeasurementValid = false;
                R.Iin_avg = NaN;
                R.Iin_expected_avg = NaN;

                if all(isfield(R,{'tIin_sim','Iin_sim'}))

                    tIn = R.tIin_sim(:);
                    Iin = R.Iin_sim(:);

                    nIn = min(numel(tIn),numel(Iin));
                    tIn = tIn(1:nIn);
                    Iin = Iin(1:nIn);

                    if nIn < 3 || any(~isfinite(tIn)) || any(diff(tIn) <= 0) || any(~isfinite(Iin))
                        error('Invalid input-current signal for efficiency calculation.');
                    end
                    idxIn = getSteadyStateFundamentalWindow(app,tIn,3);
                    Vdc = app.VdcEditField.Value;
                    Iin_avg = averageOverWindow( app,tIn(idxIn),Iin(idxIn));
                    Pin_inst = Vdc .* Iin;
                    Pin_avg = Vdc * Iin_avg;
                    if ~isfinite(Pin_avg) || Pin_avg <= 0
                        error(['Measured DC input power is not positive. ' ...
                            'Check the Iin_sim sensor polarity.']);
                    end
                    R.Pin_inst = Pin_inst;
                    R.Pin_avg = Pin_avg;
                    R.Iin_avg = Iin_avg;
                    R.Iin_expected_avg =(R.Pout_avg + R.Ptotal_avg) / Vdc;
                    R.Ploss_measured_avg = Pin_avg - R.Pout_avg;
                    R.EfficiencyMeasured = 100 * R.Pout_avg / Pin_avg;
                    R.PowerBalanceError_W = Pin_avg - R.Pout_avg - R.Ptotal_avg;
                    R.PowerBalanceError_percent = 100 * abs(R.PowerBalanceError_W) / max(abs(Pin_avg),eps);
                    balanceTolerance_W = max(5,0.10*abs(Pin_avg));
                    R.InputPowerMeasurementValid = abs(R.PowerBalanceError_W) <= balanceTolerance_W;
                    if R.InputPowerMeasurementValid
                        R.Efficiency = R.EfficiencyMeasured;
                    else
                        R.Efficiency = R.EfficiencySemiconductor;
                        warning(['Iin_sim failed the power-balance validation. ' ...
                            'Measured efficiency = %.2f%%, semiconductor efficiency = %.2f%%, balance error = %.2f%%.'], ...
                            R.EfficiencyMeasured, R.EfficiencySemiconductor, R.PowerBalanceError_percent);
                    end
                else
                    R.Efficiency = R.EfficiencySemiconductor;
                end
                averageFields = {'Vout_sim','Iout_sim','IL_sim'};
                resultFields = {'Vout_avg','Iout_avg','IL_avg'};
                for k = 1:numel(averageFields)
                    if isfield(R, averageFields{k})
                        x = R.(averageFields{k});
                        validIdx = idx(idx <= numel(x));
                        R.(resultFields{k}) = mean(x(validIdx));
                    end
                end
                Isw_ss = Isw(idx,:);
                R.Isw_avg = mean(Isw_ss(:));
                R.Isw_rms = sqrt(mean(Isw_ss(:).^2));
                R.Isw_max = max(abs(Isw_ss(:)));
                app.SimulationData.Results = R;
            end
            
            function [Pdiode_fwd_inst, Prr_inst, Prr_avg, rrEvents] = computeParallelDiodeLossesFromWaveforms(app, t, ID, VD, G)
                t = t(:);
                ID = squeeze(ID);
                VD = squeeze(VD);
                G = squeeze(G);
                if isvector(ID), ID = ID(:); end
                if isvector(VD), VD = VD(:); end
                if isvector(G), G = G(:); end
                n = min([numel(t), size(ID, 1), size(VD, 1), size(G, 1)]);
                if n < 3
                    Pdiode_fwd_inst = zeros(max(n, 1), 1);
                    Prr_inst = zeros(max(n, 1), 1);
                    Prr_avg = 0;
                    rrEvents = table();
                    return
                end
                t = t(1:n);
                ID = ID(1:n, :);
                VD = VD(1:n, :);
                G = G(1:n, :);
                nDevices = size(ID, 2);
                if size(VD, 2) ~= nDevices || size(G, 2) ~= nDevices
                    error('ID, VD and G must contain one matched column per half-bridge device.');
                end
                if any(~isfinite(t)) || any(diff(t) <= 0) || any(~isfinite(ID(:))) || any(~isfinite(VD(:))) || any(~isfinite(G(:)))
                    error('Invalid diode recovery signals.');
                end
                Pdiode_fwd_inst = computeMeasuredDiodeForwardPower(app, ID, VD);
                Prr_inst = zeros(n, 1);
                rrEvents = table('Size', [0 9], 'VariableTypes', {'double','double','string','double','double','double','double','double','double'}, 'VariableNames', {'Diode','CommutatingSwitch','Method','GateEdge_s','tStart','tEnd','Qrr_C','Err_J','IrrPeak_A'});
                if ismember(app.SelectedTopology, ["Boost","Buck","Flyback"])
                    fsw = app.fswEditField_Boost.Value;
                else
                    fsw = app.fswACEditField.Value;
                end
                Tsw = 1/fsw;
                Tobs = t(end) - t(1);
                dt = median(diff(t), 'omitnan');
                if Tobs <= 0 || ~isfinite(dt) || dt <= 0
                    error('Invalid reverse-recovery observation interval.');
                end
                gateOn = G > 0.5;
                maxSearchSamples = max(6, round(0.15*Tsw/dt));
                preEdgeSamples = 3;
                for dev = 1:nDevices
                    Idev = ID(:, dev);
                    Vdev = VD(:, dev);
                    Ipeak = max(abs(Idev));
                    if Ipeak <= 1e-9
                        continue
                    end
                    Ithreshold = max(1e-6, 0.01*Ipeak);
                    complementaryDevice = getComplementarySwitchIndex(app, dev);
                    complementaryRisingEdges = find(diff(gateOn(:, complementaryDevice)) == 1) + 1;
                    pDiode = Vdev .* Idev;
                    usedSamples = false(n, 1);
                    for edgeNumber = 1:numel(complementaryRisingEdges)
                        iEdge = complementaryRisingEdges(edgeNumber);
                        searchStart = max(1, iEdge - preEdgeSamples);
                        searchEnd = min(n, iEdge + maxSearchSamples);
                        localIndices = searchStart:searchEnd;
                        recoveryMask = Idev(localIndices) < -Ithreshold & Vdev(localIndices) < 0;
                        transitions = diff([false; recoveryMask; false]);
                        localStarts = find(transitions == 1);
                        localStops = find(transitions == -1) - 1;
                        if isempty(localStarts)
                            continue
                        end
                        localEdgePosition = iEdge - searchStart + 1;
                        validSegment = find(localStops >= localEdgePosition - preEdgeSamples, 1, 'first');
                        if isempty(validSegment)
                            continue
                        end
                        i1 = max(1, searchStart + localStarts(validSegment) - 2);
                        i2 = min(n, searchStart + localStops(validSegment));
                        if i2 <= i1 || any(usedSamples(i1:i2))
                            continue
                        end
                        Irecovery = max(-Idev(i1:i2), 0);
                        Precovery = max(pDiode(i1:i2), 0);
                        Qrr = trapz(t(i1:i2), Irecovery);
                        Err = trapz(t(i1:i2), Precovery);
                        if ~isfinite(Qrr) || ~isfinite(Err) || Err <= 0
                            continue
                        end
                        usedSamples(i1:i2) = true;
                        Prr_inst(i1:i2) = Prr_inst(i1:i2) + Precovery;
                        newRow = table(dev, complementaryDevice, "Measured terminal power", t(iEdge), t(i1), t(i2), Qrr, Err, max(Irecovery), 'VariableNames', rrEvents.Properties.VariableNames);
                        rrEvents = [rrEvents; newRow];
                    end
                end
                Prr_avg = trapz(t, Prr_inst)/Tobs;
            end
            function Pcond_inst = computeACSwitchConductionLoss(app,t,Isw,VDS,VGS)

                sw = getSwitchParams(app);

                t   = t(:);
                Isw = squeeze(Isw);
                VDS = squeeze(VDS);
                VGS = squeeze(VGS);

                if isvector(Isw), Isw = Isw(:); end
                if isvector(VDS), VDS = VDS(:); end
                if isvector(VGS), VGS = VGS(:); end

                n = min([numel(t),size(Isw,1),size(VDS,1),size(VGS,1)]);

                Isw = Isw(1:n,:);
                VDS = VDS(1:n,:);
                VGS = VGS(1:n,:);
                nDevices = size(Isw,2);
                Pdevice = zeros(n,nDevices);
                Vg = getGateDriveVoltageValue(app);
                for dev = 1:nDevices
                    iAbs = abs(Isw(:,dev));
                    vAbs = abs(VDS(:,dev));
                    if strcmp(sw.Type,'Si IGBTs')
                        gateOn = VGS(:,dev) >= sw.VgeTh;
                        Ic = max(Isw(:,dev),0) .* double(gateOn);
                        [VCE0_T,rCE_T] = getIGBTConductionParamsAtTemperature( app,sw.Tref_cond_C);
                        Pdevice(:,dev) = VCE0_T .* Ic + rCE_T .* Ic.^2;
                    else

                        positiveV = sort(vAbs(vAbs > 0));

                        if isempty(positiveV)
                            continue
                        end

                        q95 = max(1,round(0.95*numel(positiveV)));
                        Vblock = positiveV(q95);

                        fullyOn = ...
                            VGS(:,dev) >= max(sw.Vth_sw + 1,0.80*Vg);

                        stableOn = ...
                            fullyOn & ...
                            vAbs <= max(2,0.02*Vblock);

                        Pdevice(:,dev) = ...
                            sw.RdsOn .* iAbs.^2 .* double(stableOn);

                    end
                end

                Pcond_inst = sum(Pdevice,2);

            end
            function diode = exportDiodeParamsToBase(app)
                diode = getDiodeParams(app);

                values = struct('IrrmD', -abs(diode.Irrm), 'IF_ref_A', abs(diode.IF_ref_A), ...
                    'didt_ref_A_per_us', -abs(diode.didt_ref_A_per_us), 'QrrD', diode.Qrr, ...
                    'QrrD_nAs', diode.Qrr*1e9, 'TrrD', diode.Trr, 'TrrD_ns', diode.Trr*1e9, ...
                    'ErrErecD', diode.ErrErec, 'Vrev_ref_D', diode.Vrev_ref_V, ...
                    'Vf', diode.Vf, 'RonD', diode.Ron, 'CjD', diode.Cj, ...
                    'BodyDiodeType', diode.Type, 'BodyDiodeVf', diode.Vf, 'BodyDiodeVF', diode.Vf, ...
                    'BodyDiodeRon', diode.Ron, 'BodyDiodeQrr', diode.Qrr, 'BodyDiodeTrr', diode.Trr, ...
                    'BodyDiodeCj', diode.Cj, 'BodyDiodeCj_pF', diode.Cj_pF, ...
                    'BodyDiodeTrr_ns', diode.Trr_ns, 'BodyDiodeIs', diode.Is, ...
                    'BodyDiodeVbi', diode.Vbi, 'BodyDiodeN', diode.n, 'BodyDiodeTT', diode.TT);

                assignVariables(app, 'base', values);

                isDCDC = ismember(app.SelectedTopology, ["Boost","Buck","Flyback"]);
                if isDCDC && strcmp(app.SwitchTechnologyDropDown.Value, 'SiC MOSFETs')
                    override = struct('BodyDiodeVf', 2.5, 'BodyDiodeVF', 2.5, ...
                        'BodyDiodeRon', 0.0042, 'BodyDiodeQrr', 0, ...
                        'BodyDiodeTrr', 1e-12, 'BodyDiodeTT', 1e-12);
                    assignVariables(app, 'base', override);
                end
            end
            function values = parseNumericVector(~, textValue, fieldLabel)
                cleanText = regexprep(char(textValue), '[\[\],;]', ' ');
                tokens = regexp(strtrim(cleanText), '\S+', 'match');
                if isempty(tokens)
                    error('%s is empty.', fieldLabel);
                end
                values = str2double(tokens);
                if any(~isfinite(values))
                    error('%s contains a non-numeric or non-finite value.', fieldLabel);
                end
                values = values(:).';
            end
            function th = getThermalParams(app)

                tech = app.SwitchTechnologyDropDown.Value;

                D = getDefaultValues(app, app.SelectedTopology, ...
                    tech, app.DiodeTypeDropDown.Value);

                th = struct();

                th.Tamb_C = app.EditField_3.Value;
                th.Th_C   = app.FixedTempValue.Value;
                th.Rth_ch = app.RthchEditField.Value;
                if ~isfinite(th.Tamb_C)
                    error('Ambient temperature must be finite.');
                end
                if ~isfinite(th.Th_C)
                    error('Fixed boundary temperature must be finite.');
                end
                if ~isfinite(th.Rth_ch) || th.Rth_ch < 0
                    error('Rth_ch must be finite and non-negative.');
                end
                th.Tamb_K = th.Tamb_C + 273.15;
                th.Th_K   = th.Th_C   + 273.15;
                if app.FixedTemp.Value
                    th.Tboundary_C = th.Th_C;
                    th.Tboundary_K = th.Th_K;
                else
                    th.Tboundary_C = th.Tamb_C;
                    th.Tboundary_K = th.Tamb_K;
                end
                app.FosterButton.Value = true;
                app.CauerButton.Value  = false;
                if app.CustomThermalImpedance.Value
                    Rth_user = parseNumericVector(app, app.ThermalResistanceValue.Value, ...
                        'Foster thermal resistances');
                    tau_user = parseNumericVector(app,app.TimeConstantValue.Value, ...
                        'Foster time constants');
                    if numel(Rth_user) ~= numel(tau_user)
                        error(['Foster resistance and time-constant arrays must have equal length.']);
                    end
                    if any(Rth_user <= 0) || any(tau_user <= 0)
                        error(['All Foster resistances and time constants must be strictly positive.']);
                    end
                    th.Rth_foster_sw = Rth_user;
                    th.tau_foster_sw = tau_user;
                else
                    th.Rth_foster_sw = D.Cooling.Rth_foster_sw(:).';
                    th.tau_foster_sw = D.Cooling.tau_foster_sw(:).';
                end
                if app.FixedTemp.Value
                    th.Rth_ha = 0;
                    th.Cth_h  = Inf;
                    th.tau_ha = Inf;
                else
                    th.Rth_ha = 0.4;  % Replace with actual heatsink value [K/W]
                    th.Cth_h  = app.ThermalImpedanceValue.Value;
                    if ~isfinite(th.Cth_h) || th.Cth_h <= 0
                        error('Heatsink thermal capacitance must be positive.');
                    end
                    th.tau_ha = th.Rth_ha * th.Cth_h;
                end
            end
            function exportThermalParamsToBase(app)
                th = getThermalParams(app);
                values = struct('Tamb_C', th.Tamb_C, 'Tamb_K', th.Tamb_K, ...
                    'Th_C', th.Th_C, 'Th_K', th.Th_K, 'Tboundary_C', th.Tboundary_C, 'Tboundary_K', th.Tboundary_K, ...
                    'Rth_ch', th.Rth_ch, 'Rth_ha', th.Rth_ha, ...
                    'Cth_h', th.Cth_h, 'tau_ha', th.tau_ha, 'Rth_foster_sw', th.Rth_foster_sw, 'tau_foster_sw', th.tau_foster_sw);
                assignVariables(app, 'base', values);
            end
            function updateMissionProfileControls(app)

                useMission = app.UseMissionProfileCheckBox.Value;
    
                if useMission
                    state = 'on';
                    app.MissionProfileTable.BackgroundColor = [1 1 1; 0.94 0.94 0.94];
    
                    if app.MissionProfileLoaded
                        app.MissionProfileStatusLabel.Text = 'Status: mission profile enabled.';
                    else
                        app.MissionProfileStatusLabel.Text = 'Status: mission profile enabled. Load or edit a profile.';
                    end
                else
                    state = 'off';
                    app.MissionProfileTable.BackgroundColor = [0.90 0.90 0.90];
                    app.MissionProfileStatusLabel.Text = 'Status: mission profile disabled.';
                end
    
                app.MissionProfileTable.Enable = state;
                app.LoadMissionProfileButton.Enable = state;
                app.DownloadMissionProfileTemplateButton.Enable = state;
            end
            function updateSchematicImage(app, sourceBox)
                app.TopologyImage.Visible = 'on';
            
                if isequal(sourceBox, app.rbBoostConverter)
                    app.TopologyImage.ImageSource = 'BoostConverter.drawio.svg';
                elseif isequal(sourceBox, app.rbBuckConverter)
                    app.TopologyImage.ImageSource = 'Buckconverter.drawio.png';
                elseif isequal(sourceBox, app.rbBuckBoostConverter)
                    app.TopologyImage.ImageSource = 'Flyback.drawio.png';
                elseif isequal(sourceBox, app.rbHalfBridgeInverter)
                    app.TopologyImage.ImageSource = 'halfbridge.drawio.png';
                elseif isequal(sourceBox, app.rbFullBridgeInverter)
                    app.TopologyImage.ImageSource = 'fullbridge.drawio.png';
                elseif isequal(sourceBox, app.rbThreePhaseInverter)
                    app.TopologyImage.ImageSource = 'threephaseinverter.drawio.png';
                else
                    app.TopologyImage.ImageSource = '';
                    app.TopologyImage.Visible = 'off';
                end
                syncSchematicToTab4(app);
            end
           
            function X = collectNumberedSignals(app, R, prefix)
                nDevices = getActiveSwitchCount(app);
                if ~isfield(R, 't')
                    error('Cannot collect %s signals because Results.t is missing.', prefix);
                end
                nSamples = numel(R.t);
                X = zeros(nSamples, nDevices);
                for k = 1:nDevices
                    fieldName1 = sprintf('%s%d_sim', prefix, k);
                    fieldName2 = sprintf('%s%d_ism', prefix, k);
                    fieldName3 = sprintf('%sd%d_sim', prefix, k);
                    if isfield(R, fieldName1)
                        xk = R.(fieldName1);
                    elseif isfield(R, fieldName2)
                        xk = R.(fieldName2);
                    elseif isfield(R, fieldName3)
                        xk = R.(fieldName3);
                    else
                        error('Missing signal field: %s%d_sim', prefix, k);
                    end
                    xk = squeeze(xk);
                    if isvector(xk)
                        xk = xk(:);
                    end
                    X(:, k) = xk(1:nSamples);
                end
            end
            function updateSwitchTechnologyFields(app)
                tech = app.SwitchTechnologyDropDown.Value;
                isIGBT = strcmp(tech, 'Si IGBTs');
                visibility = matlab.lang.OnOffSwitchState(isIGBT);

                app.alt2.Visible  = visibility;
                app.alt2b.Visible = visibility;
                app.alt3.Visible  = visibility;
                app.alt3b.Visible = visibility;

                app.alt3.Text = 'Second temperature T2 [degC]';
                app.alt3b.Text = 'VCE(sat) at T2 [V]';
                isMOSFET = ismember(tech, {'Si MOSFETs','SiC MOSFETs','GaN MOSFETs'});

                app.MOSFETParametersPanel.Visible = 'on';

                if isMOSFET
                    app.IGBTadvancedparameterCheckbox.Visible = 'off';
                    app.IGBTadvancedparameterCheckbox.Value = false;
                    app.AdvancedParametersCheckBox.Visible = 'on';
                    app.ConductionlossmodelLabel.Visible = 'off';
                    app.OptionConductionLossIGBT.Visible = 'off';
                    hideIGBTConductionFields(app);
                    hideIGBTAdvancedFields(app);
                    showMOSFETBasicFields(app);
                    app.RdsOnLabelMOSFET.Text = 'On-resistance Rds_on at Tref [ohm]';
                    app.RdsOn2TLabelMOSFET.Text = '2nd temperature for Rds_on [degC]';
                    app.RdsOnLabel2TMOSFET.Text = 'Rds_on at 2nd temp [ohm]';
                    if strcmp(tech, 'SiC MOSFETs')
                        app.EonLabelMOSFET.Text = 'Turn-on energy Eon [J]';
                        app.EoffLabelMOSFET.Text = 'Turn-off energy Eoff [J]';
                    else
                        app.EonLabelMOSFET.Text = 'Total gate charge Qg [C]';
                        app.EoffLabelMOSFET.Text = 'Output charge Qoss [C]';
                    end

                    setIGBTFieldsVisible(app, 'mosfet');
                elseif strcmp(tech, 'Si IGBTs')
                    app.ConductionlossmodelLabel.Visible = 'on';
                    app.OptionConductionLossIGBT.Visible = 'on';
                    app.AdvancedParametersCheckBox.Visible = 'off';
                    app.IGBTadvancedparameterCheckbox.Visible = 'on';
                    app.RdsOnLabelMOSFET.Visible = 'off';
                    app.RdsOnEditFieldMOSFET.Visible = 'off';
                    app.EonLabelMOSFET.Visible = 'off';
                    app.EonEditFieldMOSFET.Visible = 'off';
                    app.EoffLabelMOSFET.Visible = 'off';
                    app.EoffEditFieldMOSFET.Visible = 'off';
                    app.IGBTBlockParametersPanel.Visible = 'off';
                    setIGBTFieldsVisible(app, 'off');
                    updateIGBTConductionUI(app);
                    updateIGBTAdvancedUI(app);
                else
                    error('Unsupported switch technology.');
                end
                app.RdsOnEditFieldMOSFET.ValueDisplayFormat = '%.3g';
                app.EonEditFieldMOSFET.ValueDisplayFormat = '%.3e';
                app.EoffEditFieldMOSFET.ValueDisplayFormat = '%.3e';
            end
            function updateCoolingPanels(app)
                app.FosterButton.Value = true;
                app.FosterButton.Enable = 'on';
                app.CauerButton.Value = false;
                app.CauerButton.Enable = 'off';
                app.CauerButton.Visible = 'off';
                app.ThermalCapacitancePanel.Visible = 'off';

                isFixed = app.FixedTemp.Value;
                isCustom = ~isFixed && app.CustomThermalImpedance.Value;

                app.HeatSinkFixedTempPanel.Visible = matlab.lang.OnOffSwitchState(isFixed);
                app.HeatSinkImpedancePanel.Visible = matlab.lang.OnOffSwitchState(isCustom);
                app.TimeConstantPanel.Visible = matlab.lang.OnOffSwitchState(isCustom);
            end
            function buildSimulationData(app)
                app.SimulationData = struct('Topology', app.SelectedTopology, ...
                    'SwitchTechnology', app.SwitchTechnologyDropDown.Value, ...
                    'DiodeType', app.DiodeTypeDropDown.Value);

                if ismember(app.SelectedTopology, ["Boost","Buck","Flyback"])
                    converter = struct('Vin', app.VinEditField_Boost.Value, ...
                        'Vout', app.VoutEditField_Boost.Value, 'Pout', app.PoutEditField_Boost.Value, ...
                        'fsw', app.fswEditField_Boost.Value, 'Cout', app.CoutEdit_Field.Value, ...
                        'Vgs_drive', app.GateDriveVoltageEditField.Value, ...
                        'Rg_on', app.TonRgEditField.Value, 'Rg_off', app.ToffRgEditField.Value);

                    if app.SelectedTopology == "Flyback"
                        converter.Lm = app.LmEditField.Value;
                        converter.TurnsRatio = app.TurnsRatioEditField.Value;
                        converter.Llk = app.LlkEditField.Value;
                        converter.CoutESR = app.CoutESREditField.Value;
                    else
                        converter.L = app.LEditField_Boost_2.Value;
                    end

                elseif ismember(app.SelectedTopology, ["Half-bridge inverter","Full-bridge inverter","Three-phase inverter"])
                    converter = struct('Vdc', app.VdcEditField.Value, 'Pout', app.PoutACEditField.Value, ...
                        'fout', app.foutEditField.Value, 'fsw', app.fswACEditField.Value, ...
                        'PF', app.PFEditField.Value, 'ModIndex', app.ModIndexEditField.Value, ...
                        'Vgs_drive', app.VgsDriveACEditField.Value, 'Rg_on', app.RgOnACEditField.Value, 'Rg_off', app.RgOffACEditField.Value);

                    if app.SelectedTopology == "Three-phase inverter"
                        converter.VLLRMS = app.VLLRMSEditField.Value;
                        converter.PWMStrategy = app.PWMStrategy3phDropDown.Value;
                    else
                        converter.VoutRMS = app.VoutRMSEditField.Value;
                        converter.PWMStrategy = app.PWMStrategyDropDown.Value;
                    end

                else
                    error('Unsupported topology in buildSimulationData.');
                end

                app.SimulationData.Converter = converter;
                app.SimulationData.Switch = getSwitchParams(app);
                app.SimulationData.Diode = getDiodeParams(app);
            end
            function updateDiodeFields(app)
                tech     = app.SwitchTechnologyDropDown.Value;
                dtype    = app.DiodeTypeDropDown.Value;
                topology = app.SelectedTopology;
            
          
                app.OmitLabelDiodeExtraParameters.Text = 'Include reverse recovery? Yes/No';
            
                app.ErrErecLabel.Text = 'Reverse-recovery energy Err/Erec [J]';
                app.ReverseQrrLabel.Text = 'Reverse-recovery charge Qrr [nC]';
                app.ReversetimeTrrlabel.Text = 'Reverse-recovery time trr [ns]';
                app.Peakreversecurrentlabel.Text = 'Peak reverse-recovery current Irrm [A]';
            
                app.AdvanceReverseRecoveryCheckBox.Text = 'Use reference data for scaling';
                app.IfredLabel.Text = 'Forward current before recovery, IF_ref [A]';
                app.Vrevlabel.Text = 'Reverse voltage during recovery, Vrev_ref [V]';
                app.didtrefLabel.Text = 'Current fall rate, di/dt_ref [A/us]';
                isBridgeTopology = ismember(topology, ["Half-bridge inverter","Full-bridge inverter","Three-phase inverter"]);
                isGaN = strcmp(dtype,'GaN reverse path') || strcmp(tech,'GaN MOSFETs');
                isExternalSiCSchottky = strcmp(dtype,'SiC Diode') && ~(strcmp(tech,'SiC MOSFETs') && isBridgeTopology);
                hasReverseRecovery = false;
                if strcmp(dtype,'Si ultrafast Diode')
                    hasReverseRecovery = true;
                elseif strcmp(dtype,'Fast Recovery Diode')
                    hasReverseRecovery = true;
                elseif strcmp(dtype,'SiC Diode') && strcmp(tech,'SiC MOSFETs') && isBridgeTopology
                    hasReverseRecovery = true;
                end
                if isGaN || isExternalSiCSchottky || strcmp(dtype,'Ideal Diode')
                    hasReverseRecovery = false;
                end
                if strcmp(dtype,'GaN reverse path') || strcmp(tech,'GaN MOSFETs')
                    app.DeviceSelectionTitleLabel_3.Text = 'GaN reverse conduction path';
                    app.DiodeVFLabel.Text  = 'Reverse path voltage Vrev [V]';
                    app.DiodeRonLabel.Text = 'Reverse path resistance Rrev [ohm]';
                elseif strcmp(tech,'Si IGBTs')
                    app.DeviceSelectionTitleLabel_3.Text = 'Antiparallel diode / FWD parameters';
                    app.DiodeVFLabel.Text  = 'Diode forward voltage VF0 [V]';
                    app.DiodeRonLabel.Text = 'Diode dynamic resistance rF [ohm]';
                elseif strcmp(tech,'Si MOSFETs') || strcmp(tech,'SiC MOSFETs')
                    app.DeviceSelectionTitleLabel_3.Text = 'Body diode parameters';
                    app.DiodeVFLabel.Text  = 'Body diode voltage VSD0 [V]';
                    app.DiodeRonLabel.Text = 'Body diode dynamic resistance rD [ohm]';
                else
                    app.DeviceSelectionTitleLabel_3.Text = 'Diode parameters';
                    app.DiodeVFLabel.Text  = 'Diode forward voltage VF0 [V]';
                    app.DiodeRonLabel.Text = 'Diode dynamic resistance rD [ohm]';
                end
                if strcmp(dtype,'Ideal Diode')
                    app.DiodeVFEditField.Enable  = 'off';
                    app.DiodeRonEditField.Enable = 'off';
                else
                    app.DiodeVFEditField.Enable  = 'on';
                    app.DiodeRonEditField.Enable = 'on';
                end
                if hasReverseRecovery
                    app.YesButton_OmitDiodeParameters.Enable = 'on';
                    app.NoButton_OmitDiodeParameters.Enable  = 'on';
                    includeRR = app.YesButton_OmitDiodeParameters.Value;
                    setRRVisible(app, true);
                    if includeRR
                        setRREnabled(app, true);
                        useScaling = app.AdvanceReverseRecoveryCheckBox.Value;
                        setScalingVisible(app, useScaling);
                        setScalingEnabled(app, useScaling);
                    else
                        app.AdvanceReverseRecoveryCheckBox.Value = false;
                        setRREnabled(app, false);
                        setScalingVisible(app, false);
                    end
                else
                    app.YesButton_OmitDiodeParameters.Value = false;
                    app.NoButton_OmitDiodeParameters.Value  = true;
                    app.YesButton_OmitDiodeParameters.Enable = 'off';
                    app.NoButton_OmitDiodeParameters.Enable  = 'off';
                    setRRVisible(app, false);
                    setScalingVisible(app, false);
                end
            end
            
            function setRRVisible(app, state)
    
                v = matlab.lang.OnOffSwitchState(state);
    
                app.ErrErecLabel.Visible = v;
                app.ErrErecEditfield.Visible = v;
                app.ReverseQrrLabel.Visible = v;
                app.QrrEditfield.Visible = v; 
                app.ReversetimeTrrlabel.Visible = v;
                app.trrEditfield.Visible = v;
                app.Peakreversecurrentlabel.Visible = v;
                app.IrrmEditfield.Visible = v;
                app.AdvanceReverseRecoveryCheckBox.Visible = v;
            end
            function showMOSFETBasicFields(app)
                app.MOSFETParametersPanel.Visible = 'on';
                app.RdsOnLabelMOSFET.Visible = 'on';
                app.RdsOnEditFieldMOSFET.Visible = 'on';
                app.EonLabelMOSFET.Visible = 'on';
                app.EonEditFieldMOSFET.Visible = 'on';
                app.EoffLabelMOSFET.Visible = 'on';
                app.EoffEditFieldMOSFET.Visible = 'on';
            end
            function simStopTime = calculateAutomaticACStopTime(app)
                fout = app.foutEditField.Value;
                if ~isfinite(fout) || fout <= 0
                    error('Output frequency must be finite and positive.');
                end
                simStopTime = 6/fout;
            end
            function setRREnabled(app, state)
    
                e = matlab.lang.OnOffSwitchState(state);
    
                app.ErrErecEditfield.Enable = e;
                app.QrrEditfield.Enable = e;
                app.trrEditfield.Enable = e;
                app.IrrmEditfield.Enable = e;
    
                if state
                    c = [0 0 0];
                else
                    c = [0.6 0.6 0.6];
                end
                app.ErrErecLabel.FontColor = c;
                app.ReverseQrrLabel.FontColor = c;
                app.ReversetimeTrrlabel.FontColor = c;
                app.Peakreversecurrentlabel.FontColor = c;
            end
            function setScalingVisible(app, state)
    
                v = matlab.lang.OnOffSwitchState(state);
                app.IfredLabel.Visible = v;
                app.IfrefEditfield.Visible = v;
                app.Vrevlabel.Visible = v;
                app.VrevEditfield.Visible = v;
                app.didtrefLabel.Visible = v;
                app.didtrefEditfield.Visible = v;
            end
            function setScalingEnabled(app, state)
    
                e = matlab.lang.OnOffSwitchState(state);
                app.IfrefEditfield.Enable = e;
                app.VrevEditfield.Enable = e;
                app.didtrefEditfield.Enable = e;
                if state
                    c = [0 0 0];
                else
                    c = [0.6 0.6 0.6];
                end
                app.IfredLabel.FontColor = c;
                app.Vrevlabel.FontColor = c;
                app.didtrefLabel.FontColor = c;
            end
            function topology = getSelectedTopology(app)
                if app.rbBoostConverter.Value
                    topology = "Boost";
                elseif app.rbBuckConverter.Value
                    topology = "Buck";
                elseif app.rbBuckBoostConverter.Value
                    topology = "Flyback";
                elseif app.rbHalfBridgeInverter.Value
                    topology = "Half-bridge inverter";
                elseif app.rbFullBridgeInverter.Value
                    topology = "Full-bridge inverter";
                elseif app.rbThreePhaseInverter.Value
                    topology = "Three-phase inverter";
                else
                    topology = "";
                end
            end
            function syncSchematicToTab4(app)
                app.PlaceholderSubTextLabel_2.Text = app.PlaceholderSubTextLabel.Text;
                if isempty(app.TopologyImage.ImageSource)
                    app.TopologyImage_2.ImageSource = '';
                    app.TopologyImage_2.Visible = 'off';
                    app.TopologyImage_3.ImageSource = '';
                    app.TopologyImage_3.Visible = 'off';
                else
                    app.TopologyImage_2.ImageSource = app.TopologyImage.ImageSource;
                    app.TopologyImage_2.Visible = app.TopologyImage.Visible;
                    app.TopologyImage_3.ImageSource = app.TopologyImage.ImageSource;
                    app.TopologyImage_3.Visible = app.TopologyImage.Visible;
                end
            end
            function updateCircuitParameterPanels(app)
                isBoostBuck = app.rbBoostConverter.Value || app.rbBuckConverter.Value;
                isFlyback = app.rbBuckBoostConverter.Value;
                isSinglePhase = app.rbHalfBridgeInverter.Value || app.rbFullBridgeInverter.Value;
                isThreePhase = app.rbThreePhaseInverter.Value;

                app.DCDCCircuit.Visible = matlab.lang.OnOffSwitchState(isBoostBuck || isFlyback);
                app.DCDCFlyback.Visible = matlab.lang.OnOffSwitchState(isFlyback);
                app.DCACCircuitPanel.Visible = matlab.lang.OnOffSwitchState(isSinglePhase || isThreePhase);
                app.HalfandFullBridge.Visible = matlab.lang.OnOffSwitchState(isSinglePhase);
                app.Threephaseextrapanel.Visible = matlab.lang.OnOffSwitchState(isThreePhase);
                app.LEditField_Boost_2.Enable = matlab.lang.OnOffSwitchState(~isFlyback);

                if isFlyback
                    app.LLabel_Boost_2.FontColor = [0.6 0.6 0.6];
                else
                    app.LLabel_Boost_2.FontColor = [0 0 0];
                end
            end
            function waitUntilModelStopped(app, modelName)
    
                if ~bdIsLoaded(modelName)
                    load_system(modelName);
                end
    
                maxWait_s = 30;
                t0 = tic;
    
                while true
                    status = get_param(modelName, 'SimulationStatus');
                    if strcmpi(status, 'stopped')
                        break
                    end
                    if any(strcmpi(status, {'running','paused'}))
                        try
                            set_param(modelName, 'SimulationCommand', 'stop');
                        catch
                        end
                    end
    
                    pause(0.1);
                    drawnow limitrate
    
                    if toc(t0) > maxWait_s
                        error('Model "%s" is still busy. Current SimulationStatus = "%s". Wait or close/reopen the model.', ...
                            modelName, status);
                    end
                end
            end

            function runDCDCMissionProfileSimulation(app, d)
                if nargin < 2
                    d = [];
                end
                T = app.MissionProfileTable.Data;
            
                if ~istable(T) || height(T) == 0
                    error('Mission profile table is empty.');
                end       
                requiredCols = {'Segment','Duration_s','Vin_V','Vout_V','Pout_W','T_amb_C'};
                missingCols = setdiff(requiredCols, T.Properties.VariableNames);
                if ~isempty(missingCols)
                    error( ['Mission profile is missing columns: ' strjoin(missingCols, ', ')]);
                end
                swMission = getSwitchParams(app);
                if strcmp(swMission.Type, 'Si IGBTs') && ~swMission.HasConductionTempDependence
                    error( ['IGBT mission-profile simulation requires a second ' ...
                         'VCE(sat)-temperature point. Enter the second temperature ' ...
                         'and VCE(sat) at the same reference current in Device Selection.']);
                end
                n = height(T);
                if ismember(app.SelectedTopology, ["Boost","Buck"])
                    Lrequired_H = zeros(n,1);
                    Crequired_F = zeros(n,1);
                    for row = 1:n
                        [Lrequired_H(row), Crequired_F(row)] = calculateAutomaticDCDCComponents( ...
                            app, app.SelectedTopology, T.Vin_V(row), T.Vout_V(row), ...
                            T.Pout_W(row),app.fswEditField_Boost.Value);
                    end
                    app.LEditField_Boost_2.Value = max(Lrequired_H);
                    app.CoutEdit_Field.Value     = max(Crequired_F);
                end
                Segment     = string(T.Segment);
                Duration_s  = T.Duration_s;
                
                Pcond_W     = zeros(n,1);
                Psw_W       = zeros(n,1);
                Pdiode_W    = zeros(n,1);
                Ploss_W     = zeros(n,1);
                Tj_end_C  = zeros(n,1);
                Tj_peak_C = zeros(n,1);
                Pcap_W        = zeros(n,1);
                Psw_overlap_W = zeros(n,1);
                Pgate_W = zeros(n,1);
                Pout_W      = zeros(n,1);   % commanded mission-profile output power
                Pout_sim_W  = zeros(n,1);   % measured Simulink output power, only for validation
                Eff_percent = zeros(n,1);
                Pdiode_ext_fwd_W  = zeros(n,1);
                Pdiode_body_fwd_W = zeros(n,1);
    
                Prr_ext_W  = zeros(n,1);
                Prr_body_W = zeros(n,1);
                Prr_W      = zeros(n,1);
    
                Pdiode_fwd_W  = zeros(n,1);
                Pdiode_ext_W  = zeros(n,1);
                Pdiode_body_W = zeros(n,1);
                Econd_J     = zeros(n,1);
                Esw_J       = zeros(n,1);
                Ediode_J    = zeros(n,1);
                Eloss_J     = zeros(n,1);
                Eout_J      = zeros(n,1);
                oldVin  = app.VinEditField_Boost.Value;
                oldVout = app.VoutEditField_Boost.Value;
                oldPout = app.PoutEditField_Boost.Value;
                oldTamb = app.EditField_3.Value;
                try
                    for k = 1:n
                        if ~isempty(d) && isvalid(d)
                            d.Message = sprintf('Running mission segment %d of %d: %s', ...
                                k, n, char(Segment(k)));
                            drawnow limitrate
                        end
                        rowValues = [Duration_s(k), T.Vin_V(k), T.Vout_V(k), T.Pout_W(k),T.T_amb_C(k)];
                        if any(~isfinite(rowValues))
                            error( 'Mission-profile row %d contains NaN or Inf.', k);
                        end
                        if Duration_s(k) <= 0
                            error( 'Duration_s must be positive in row %d.', k);
                        end
                        if T.Vin_V(k) <= 0 T.Vout_V(k) <= 0 || T.Pout_W(k) <= 0
                            error( ['Vin_V, Vout_V and Pout_W must be positive in row %d.'], k);
                        end
                        app.VinEditField_Boost.Value  = T.Vin_V(k);
                        app.VoutEditField_Boost.Value = T.Vout_V(k);
                        app.PoutEditField_Boost.Value = T.Pout_W(k);
                        Tamb_mission = T.T_amb_C(k);
                        app.EditField_3.Value = Tamb_mission;
            
                        runDCDCSimulation(app);
            
                        R = app.SimulationData.Results;
                        Pcond_W(k) = R.Psw_cond_avg;
                        Psw_W(k)   = R.Psw_switch_avg;
                        if isfield(R, 'Pcap_avg')
                            Pcap_W(k) = R.Pcap_avg;
                        else
                            Pcap_W(k) = 0;
                        end

                        if isfield(R, 'Psw_overlap_avg')
                            Psw_overlap_W(k) = R.Psw_overlap_avg;
                        else
                            Psw_overlap_W(k) = Psw_W(k) - Pcap_W(k);
                        end
                        if isfield(R, 'Pgate_avg')
                            Pgate_W(k) = R.Pgate_avg;
                        else
                            Pgate_W(k) = 0;
                        end
                        if isfield(R, 'Pdiode_ext_fwd_avg')
                            Pdiode_ext_fwd_W(k) = R.Pdiode_ext_fwd_avg;
                        else
                            Pdiode_ext_fwd_W(k) = 0;
                        end
                        if isfield(R, 'Pdiode_body_fwd_avg')
                            Pdiode_body_fwd_W(k) = R.Pdiode_body_fwd_avg;
                        else
                            Pdiode_body_fwd_W(k) = 0;
                        end
                        if isfield(R, 'Prr_ext_avg')
                            Prr_ext_W(k) = R.Prr_ext_avg;
                        else
                            Prr_ext_W(k) = 0;
                        end
                        if isfield(R, 'Prr_body_avg')
                            Prr_body_W(k) = R.Prr_body_avg;
                        else
                            Prr_body_W(k) = 0;
                        end
                        Prr_W(k) = Prr_ext_W(k) + Prr_body_W(k);
                        Pdiode_fwd_W(k) = Pdiode_ext_fwd_W(k) + Pdiode_body_fwd_W(k);
                        Pdiode_ext_W(k) = Pdiode_ext_fwd_W(k) + Prr_ext_W(k);
                        Pdiode_body_W(k) = Pdiode_body_fwd_W(k) + Prr_body_W(k);
                        Pdiode_W(k) = Pdiode_fwd_W(k) + Prr_W(k);
                        Ploss_W(k) = Pcond_W(k) + Psw_W(k) + Pdiode_W(k);
                        Pout_W(k)     = T.Pout_W(k);
                        Pout_sim_W(k) = R.Pout_avg;   
                        if Pout_sim_W(k) > 0
                            Eff_percent(k) = 100 * Pout_sim_W(k) / (Pout_sim_W(k) + Ploss_W(k));
                        else
                            Eff_percent(k) = 0;
                        end
                        Econd_J(k)  = Pcond_W(k)  * Duration_s(k);
                        Esw_J(k)    = Psw_W(k)    * Duration_s(k);
                        Ediode_J(k) = Pdiode_W(k) * Duration_s(k);
                        Eloss_J(k)  = Ploss_W(k)  * Duration_s(k);
                        Eout_J(k)   = Pout_sim_W(k) * Duration_s(k);
                    end
            
                catch ME
                    app.VinEditField_Boost.Value  = oldVin;
                    app.VoutEditField_Boost.Value = oldVout;
                    app.PoutEditField_Boost.Value = oldPout;
                    app.EditField_3.Value         = oldTamb;
                    rethrow(ME)
                end
            
                app.VinEditField_Boost.Value  = oldVin;
                app.VoutEditField_Boost.Value = oldVout;
                app.PoutEditField_Boost.Value = oldPout;
                app.EditField_3.Value         = oldTamb;
                MissionResults = table(Segment, Duration_s,Pcond_W, Psw_W, Psw_overlap_W, Pcap_W, Pgate_W, ...
                    Pdiode_ext_fwd_W, Pdiode_body_fwd_W, ...
                    Prr_ext_W, Prr_body_W, Prr_W, Pdiode_fwd_W, Pdiode_ext_W, Pdiode_body_W, Pdiode_W, ...
                    Ploss_W, Pout_W, Pout_sim_W, Eff_percent, ...
                    Econd_J, Esw_J, Ediode_J, Eloss_J, Eout_J,Tj_end_C, Tj_peak_C);
            
                [ThermalTrace, MissionResults] = buildMissionThermalTrace1min(app, MissionResults, T, 60);
                
                app.SimulationData.MissionResults = MissionResults;
                app.SimulationData.MissionThermalTrace = ThermalTrace;
                
                app.SimulationData.Results.Tj_final_C = ThermalTrace.Tj_C(end);
                app.SimulationData.Results.Tj_max_C   = max(ThermalTrace.Tj_C);
                totalDuration = sum(Duration_s);
                TT = ThermalTrace;
                dt_trace = diff(TT.time_s);
                Pcond_trace  = TT.Pcond_scaled_W(2:end);
                Psw_trace    = TT.Psw_W(2:end);
                Ploss_trace  = TT.Ptotal_W(2:end);
                Pdiode_trace = TT.Pdiode_W(2:end);
                if ismember('Pdiode_body_W', TT.Properties.VariableNames)
                    Pdiode_body_trace = TT.Pdiode_body_W(2:end);
                else
                    Pdiode_body_trace = zeros(size(Pdiode_trace));
                end

                if ismember('Pdiode_ext_W', TT.Properties.VariableNames)
                    Pdiode_ext_trace = TT.Pdiode_ext_W(2:end);
                else
                    Pdiode_ext_trace = zeros(size(Pdiode_trace));
                end
                valid = isfinite(Pcond_trace) & isfinite(Psw_trace) & isfinite(Pdiode_trace) & isfinite(Pdiode_body_trace) & ...
                    isfinite(Pdiode_ext_trace) & isfinite(Ploss_trace) & dt_trace > 0;
                Econd_total_J = sum(Pcond_trace(valid) .* dt_trace(valid));
                Esw_total_J= sum(Psw_trace(valid)  .* dt_trace(valid));
                Ediode_total_J= sum(Pdiode_trace(valid).* dt_trace(valid));
                Ediode_body_total_J = sum(Pdiode_body_trace(valid).* dt_trace(valid));
                Ediode_ext_total_J  = sum(Pdiode_ext_trace(valid) .* dt_trace(valid));
                Eloss_total_J = sum(Ploss_trace(valid).* dt_trace(valid));

                avgPcond = Econd_total_J/ totalDuration;
                avgPsw= Esw_total_J / totalDuration;
                avgPdiode= Ediode_total_J / totalDuration;  
                avgPdiode_body = Ediode_body_total_J / totalDuration;   
                avgPdiode_ext  = Ediode_ext_total_J  / totalDuration;   
                avgPloss = Eloss_total_J  / totalDuration;
                totalEout = sum(Pout_sim_W .* Duration_s);
                totalEout_cmd = sum(Pout_W .* Duration_s);
                missionEff = 100 * totalEout / (totalEout + Eloss_total_J);
                app.SimulationData.Results.Psw_cond_avg   = avgPcond;      
                app.SimulationData.Results.Psw_switch_avg = avgPsw;         
                app.SimulationData.Results.Pcap_avg = sum(Pcap_W .* Duration_s) / totalDuration;
                app.SimulationData.Results.Psw_overlap_avg = sum(Psw_overlap_W .* Duration_s) / totalDuration;
                app.SimulationData.Results.Pgate_avg = sum(Pgate_W .* Duration_s) / totalDuration;
                app.SimulationData.Results.Pdiode_body_avg  = avgPdiode_body;
                app.SimulationData.Results.Pdiode_ext_avg   = avgPdiode_ext;
                app.SimulationData.Results.Pdiode_total_avg = avgPdiode;

                app.SimulationData.Results.Pdiode_avg = avgPdiode;

                app.SimulationData.Results.Ptotal_avg = avgPloss;
                app.SimulationData.Results.Pout_avg = totalEout / totalDuration;
                app.SimulationData.Results.Pout_cmd_avg = totalEout_cmd / totalDuration;
                app.SimulationData.Results.Pout_sim_avg = totalEout / totalDuration;
                app.SimulationData.Results.Efficiency = missionEff;
                app.SimulationData.Results.Eout_total_J = totalEout;
                app.SimulationData.Results.Eout_cmd_total_J = totalEout_cmd;
            end
            function turnsRatio = calculateFlybackTurnsRatio(app)
                Vin = app.VinEditField_Boost.Value;
                Vout = app.VoutEditField_Boost.Value;
                Vf = getDiodeParams(app).Vf;
                Dtarget = 0.45;
                turnsRatio = Dtarget*Vin / ((1-Dtarget)*(Vout+Vf));

                if ~isfinite(turnsRatio) || turnsRatio <= 0
                    error('Calculated Flyback turns ratio Np/Ns is invalid.');
                end
            end
            function diode = getDiodeParams(app)

                tech = app.SwitchTechnologyDropDown.Value;
                dtype = app.DiodeTypeDropDown.Value;
                D = getDefaultValues(app, app.SelectedTopology, tech, dtype);

                isBridge = ismember(app.SelectedTopology, ...
                    ["Half-bridge inverter","Full-bridge inverter","Three-phase inverter"]);

                if isBridge && ismember(tech, {'Si MOSFETs','SiC MOSFETs','Si IGBTs','GaN MOSFETs'})
                    base = D.Reverse;
                else
                    base = D.Diode;
                end

                Vf = app.DiodeVFEditField.Value;
                Ron = app.DiodeRonEditField.Value;

                if ~isfinite(Vf) || Vf < 0, Vf = base.Vf; end
                if ~isfinite(Ron) || Ron <= 0, Ron = base.Ron; end

                diode.Type = dtype;
                diode.Vf = Vf;
                diode.Ron = max(Ron, 1e-6);
                diode.ErrErec = 0;
                diode.Qrr = 0;
                diode.Trr = 1e-12;
                diode.Irrm = 0;
                diode.IF_ref_A = 0;
                diode.Vrev_ref_V = 0;
                diode.didt_ref_A_per_us = 0;

                if app.YesButton_OmitDiodeParameters.Value

                    switch dtype
                        case 'Fast Recovery Diode'
                            defaults = [10, 100, 800e-9, 200e-9, 0.5e-3];
                        case 'SiC Diode'
                            defaults = [2, 100, 100e-9, 80e-9, 0.08e-3];
                        otherwise
                            defaults = [5, 100, 300e-9, 150e-9, 0.2e-3];
                    end

                    diode.ErrErec = app.ErrErecEditfield.Value;
                    diode.Qrr = app.QrrEditfield.Value * 1e-9;
                    diode.Trr = app.trrEditfield.Value * 1e-9;
                    diode.Irrm = app.IrrmEditfield.Value;

                    if app.AdvanceReverseRecoveryCheckBox.Value
                        diode.IF_ref_A = app.IfrefEditfield.Value;
                        diode.Vrev_ref_V = app.VrevEditfield.Value;
                        diode.didt_ref_A_per_us = app.didtrefEditfield.Value;
                    else
                        rr = getReverseRecoveryUIDefaults(app, dtype);
                        diode.IF_ref_A = rr.IF_ref_A;
                        diode.Vrev_ref_V = rr.Vrev_ref_V;
                        diode.didt_ref_A_per_us = rr.didt_A_per_us;
                    end

                    if ~isfinite(diode.Irrm) || diode.Irrm <= 0
                        diode.Irrm = defaults(1);
                    end
                    if ~isfinite(diode.didt_ref_A_per_us) || diode.didt_ref_A_per_us <= 0
                        diode.didt_ref_A_per_us = defaults(2);
                    end
                    if ~isfinite(diode.Qrr) || diode.Qrr <= 0
                        diode.Qrr = defaults(3);
                    end
                    if ~isfinite(diode.Trr) || diode.Trr <= 0
                        diode.Trr = defaults(4);
                    end
                    if ~isfinite(diode.ErrErec) || diode.ErrErec <= 0
                        diode.ErrErec = defaults(5);
                    end
                    if ~isfinite(diode.IF_ref_A) || diode.IF_ref_A <= 0
                        diode.IF_ref_A = max(1, 2 * diode.Irrm);
                    end
                    if ~isfinite(diode.Vrev_ref_V) || diode.Vrev_ref_V <= 0
                        diode.Vrev_ref_V = 600;
                    end
                end
                diode.Cj = max(base.Cj, 1e-12);
                diode.Trr = max(diode.Trr, 1e-12);
                diode.Is = base.Is;
                diode.Vbi = base.Vbi;
                diode.n = base.n;
                diode.Cj_pF = diode.Cj * 1e12;
                diode.Trr_ns = diode.Trr * 1e9;
                diode.TT = diode.Trr;
            end
            function [Np, Ns] = sendAutomaticFlybackTurns(app, modelName)
                if app.SelectedTopology ~= "Flyback"
                    Np = [];
                    Ns = [];
                    return
                end
                turnsRatio = calculateFlybackTurnsRatio(app);
                Np = 100;
                Ns = Np / turnsRatio;
                values = struct('Np', Np, 'Ns', Ns, 'TurnsRatio', turnsRatio);
                assignVariables(app, 'base', values);
                assignVariables(app, get_param(modelName, 'ModelWorkspace'), values);
            end
            function [Psw_avg, swEvents] = computeSwitchingLossSelected(app,t,G,VDS,Isw)

                sw = getSwitchParams(app);

                % ============================================================
                % Switching-loss method selection
                %
                % Half-bridge IGBT  -> datasheet Eon/Eoff
                % Full-bridge IGBT  -> measured waveform VCE*IC integration
                % SiC               -> datasheet Eon/Eoff
                % Si / GaN MOSFET   -> waveform method
                % ============================================================

                isFullBridgeIGBT = ...
                    strcmp(sw.Type,'Si IGBTs') && ...
                    app.SelectedTopology == "Full-bridge inverter";

                if ismember(sw.Type, {'Si IGBTs','SiC MOSFETs'}) && ...
                        ~isFullBridgeIGBT

                    [Psw_avg, swEvents] = ...
                        computeDatasheetSwitchingLossAverage(app);

                    return
                end

                % ============================================================
                % MOSFET waveform method
                % ============================================================
                if ismember(app.SelectedTopology,["Boost","Buck","Flyback"])
                    fsw = app.fswEditField_Boost.Value;
                else
                    fsw = app.fswACEditField.Value;
                end

                nSw = size(VDS,2);

                if nSw ~= getActiveSwitchCount(app)
                    error('Expected %d switch waveforms, but received %d.', ...
                        getActiveSwitchCount(app),nSw);
                end

                Vg = getGateDriveVoltageValue(app);

                if isfield(sw,'Qg') && isfinite(sw.Qg) && sw.Qg > 0
                    Pgate_avg = nSw*fsw*sw.Qg*Vg;
                else
                    Pgate_avg = 0;
                end

                [Psw_avg,waveformEvents] = ...
                    computeSwitchingLossFromGateEvents(app,t,VDS,Isw,G);

                if ~isfinite(Psw_avg) || Psw_avg < 0 || isempty(waveformEvents)
                    error(['No valid waveform switching events were measured. ' ...
                        'Check switch voltage/current polarity, gate threshold ' ...
                        'and solver step size.']);
                end

                swEvents = table( ...
                    "Waveform switching-energy integration", ...
                    Psw_avg,0,Pgate_avg,Psw_avg,height(waveformEvents), ...
                    'VariableNames', ...
                    {'Method','Poverlap_W','Pcap_W', ...
                    'Pgate_W','Pjunction_W','EventCount'});

                app.SimulationData.Results.SwitchingWaveformEvents = waveformEvents;
                if isFullBridgeIGBT

                    assignin('base', ...
                        'Psw_FullBridge_IGBT_Waveform_W', ...
                        Psw_avg);

                    assignin('base', ...
                        'FullBridge_IGBT_SwitchingEvents', ...
                        waveformEvents);

                    fprintf('\n');
                    fprintf('====================================================\n');
                    fprintf(' FULL-BRIDGE IGBT SWITCHING LOSS\n');
                    fprintf(' OFFICIAL METHOD: WAVEFORM INTEGRATION\n');
                    fprintf('====================================================\n');
                    fprintf('Psw = %.6f W\n',Psw_avg);
                    fprintf('Accepted switching events = %d\n', ...
                        height(waveformEvents));
                    fprintf('====================================================\n\n');

                end

            end
            function [Psw_avg,events] = computeSwitchingLossFromGateEvents(app,t,VDS,Isw,G)            
                t   = t(:);
                VDS = squeeze(VDS);
                Isw = squeeze(Isw);
                G   = squeeze(G);
            
                if isvector(VDS), VDS = VDS(:); end
                if isvector(Isw), Isw = Isw(:); end
                if isvector(G),   G   = G(:);   end
                n = min([numel(t),size(VDS,1),size(Isw,1),size(G,1)]);
                t   = t(1:n);
                VDS = VDS(1:n,:);
                Isw = Isw(1:n,:);
                G   = G(1:n,:);
                nDevices = size(VDS,2);
                if size(Isw,2) ~= nDevices || size(G,2) ~= nDevices
                    error('VDS, Isw and G need one synchronized column per MOSFET.');
                end
                if any(~isfinite(t)) || any(diff(t) <= 0) || ...
                        any(~isfinite(VDS(:))) || any(~isfinite(Isw(:)))
                    error('Invalid MOSFET waveform data.');
                end
                if ismember(app.SelectedTopology,["Boost","Buck","Flyback"])
                    fsw = app.fswEditField_Boost.Value;
                else
                    fsw = app.fswACEditField.Value;
                end
                Tsw  = 1/fsw;
                Tobs = t(end)-t(1);
                dt   = median(diff(t));
                if Tobs <= 0 || dt <= 0
                    error('Invalid switching-loss observation interval.');
                end
                gateOn = G > 0.5;
                sw = getSwitchParams(app);
                if strcmp(sw.Type,'Si IGBTs')
                    Ic = max(Isw,0).*double(gateOn);

                    [VCE0_T,rCE_T] = ...
                        getIGBTConductionParamsAtTemperature(app,sw.Tref_cond_C);

                    Pcond = VCE0_T.*Ic + rCE_T.*Ic.^2;
                else
                    Pcond = sw.RdsOn.*Isw.^2.*double(gateOn);
                end
                events = table('Size',[0 9], 'VariableTypes',{'double','string','double','double', ...
                    'double','double','double','double','double'}, ...
                    'VariableNames',{'Device','Type','GateEdge_s','tStart', ...
                    'tEnd','Duration_s','Energy_J','Ievent_A','Vevent_V'});
            
                totalEnergy_J = 0;
                searchSamples = max(10,ceil(0.02*Tsw/dt));
            
                for dev = 1:nDevices
            
                    vAbs = abs(VDS(:,dev));
                    iAbs = abs(Isw(:,dev));
            
                    sortedV = sort(vAbs(isfinite(vAbs)));
            
                    if isempty(sortedV) || max(iAbs) <= 1e-9
                        continue
                    end
            
                    q95 = max(1,round(0.95*numel(sortedV)));
                    Vblock = sortedV(q95);
            
                    if Vblock <= 1e-9
                        continue
                    end
            
                    % Passive-sign terminal power.
                    pTerminal = VDS(:,dev).*Isw(:,dev);
            
                    stableOn = gateOn(:,dev) & ...
                        vAbs < 0.05*Vblock & ...
                        iAbs > max(1e-6,0.02*max(iAbs));
            
                    % Correct a completely reversed current-sensor polarity.
                    if any(stableOn)
                        polarityCheck = median(pTerminal(stableOn),'omitnan');
                        if polarityCheck < 0
                            pTerminal = -pTerminal;
                        end
                    end
            
                    % Remove steady MOSFET conduction power.
                    pSwitch = max(pTerminal-Pcond(:,dev),0);
            
                    edges = find(diff(gateOn(:,dev)) ~= 0)+1;
            
                    for e = 1:numel(edges)
            
                        iEdge = edges(e);
                        iLo = max(1,iEdge-searchSamples);
                        iHi = min(n,iEdge+searchSamples);
            
                        if iHi-iLo < 6
                            continue
                        end
            
                        if gateOn(iEdge,dev)
                            eventType = "Turn-on";
            
                            relCross = find( ...
                                vAbs(iLo:iHi-1) >= 0.90*Vblock & ...
                                vAbs(iLo+1:iHi) < 0.90*Vblock);
            
                            if isempty(relCross), continue; end
            
                            crosses = iLo+relCross-1;
                            [~,k] = min(abs(crosses-iEdge));
                            iCross = crosses(k);
            
                            relEnd = find( ...
                                vAbs(iCross+1:iHi) <= 0.10*Vblock,1,'first');
            
                        else
                            eventType = "Turn-off";
            
                            relCross = find( ...
                                vAbs(iLo:iHi-1) <= 0.10*Vblock & ...
                                vAbs(iLo+1:iHi) > 0.10*Vblock);
            
                            if isempty(relCross), continue; end
            
                            crosses = iLo+relCross-1;
                            [~,k] = min(abs(crosses-iEdge));
                            iCross = crosses(k);
            
                            relEnd = find( ...
                                vAbs(iCross+1:iHi) >= 0.90*Vblock,1,'first');
                        end
            
                        if isempty(relEnd)
                            continue
                        end
            
                        iStart = max(iLo,min(iEdge,iCross)-3);

                        if strcmp(sw.Type,'Si IGBTs') && eventType == "Turn-off"

                            % Capture IGBT collector-current tail after VCE rises
                            extraTailTime = min(2e-6,0.02*Tsw);
                            extraTailSamples = max(3,ceil(extraTailTime/dt));

                        else

                            % No artificial 2-us extension during turn-on
                            extraTailSamples = 3;

                        end

                        iEnd = min(iHi,iCross + relEnd + extraTailSamples); 
            
                        if iEnd-iStart+1 < 6
                            warning('MOSFET %d %s has insufficient samples.', ...
                                dev,eventType);
                            continue
                        end
            
                        Eevent = trapz(t(iStart:iEnd),pSwitch(iStart:iEnd));
            
                        if ~isfinite(Eevent) || Eevent <= 0
                            continue
                        end
            
                        totalEnergy_J = totalEnergy_J+Eevent;
            
                        newRow = table( ...
                            dev,eventType,t(iEdge),t(iStart),t(iEnd), ...
                            t(iEnd)-t(iStart),Eevent, ...
                            max(iAbs(iStart:iEnd)),max(vAbs(iStart:iEnd)), ...
                            'VariableNames',events.Properties.VariableNames);
            
                        events = [events;newRow];
                    end
                end
            
                Psw_avg = totalEnergy_J/Tobs;
            
                fprintf('\n%s WAVEFORM SWITCHING LOSSES\n',upper(sw.Type));
            
                for dev = 1:nDevices
                    rows = events.Device == dev;
                    onRows  = rows & events.Type == "Turn-on";
                    offRows = rows & events.Type == "Turn-off";
            
                    fprintf(['Device %d: Eon mean = %.3f uJ, ' ...
                        'Eoff mean = %.3f uJ, events = %d\n'], ...
                        dev, ...
                        mean(events.Energy_J(onRows),'omitnan')*1e6, ...
                        mean(events.Energy_J(offRows),'omitnan')*1e6, ...
                        nnz(rows));
                end
            
                fprintf('Total waveform switching loss = %.6f W\n',Psw_avg);
            end
            function Pdiode_fwd_inst = computeMeasuredDiodeForwardPower(~, ID, VD)
                ID = squeeze(ID);
                VD = squeeze(VD);
                if isvector(ID), ID = ID(:); end
                if isvector(VD), VD = VD(:); end
                n = min(size(ID, 1), size(VD, 1));
                ID = ID(1:n, :);
                VD = VD(1:n, :);
                if size(ID, 2) ~= size(VD, 2)
                    error('ID and VD must have one matched column per diode.');
                end
                if any(~isfinite(ID(:))) || any(~isfinite(VD(:)))
                    error('Diode signals contain NaN or Inf.');
                end
                Pdevice = zeros(size(ID));
                for dev = 1:size(ID, 2)
                    Ipeak = max(abs(ID(:, dev)));
                    if Ipeak <= 1e-9
                        continue
                    end
                    Ithreshold = max(1e-6, 0.005 * Ipeak);
                    Vpeak = max(abs(VD(:,dev)));
                    Vthreshold = max(1e-6, 1e-4*Vpeak);
                    forwardMask = ID(:,dev) > Ithreshold & VD(:,dev) > Vthreshold;
                    pTerminal = VD(:,dev) .* ID(:,dev);
                    Pdevice(:,dev) = max(pTerminal,0) .* double(forwardMask);
                end
                Pdiode_fwd_inst = sum(Pdevice, 2);
            end
            function complementaryDevice = getComplementarySwitchIndex(app, device)
                nDevices = getActiveSwitchCount(app);
                if device < 1 || device > nDevices
                    error('Invalid device index.');
                end
                switch app.SelectedTopology
                    case "Half-bridge inverter"
                        complementaryDevice = 3 - device;
                    case {"Full-bridge inverter","Three-phase inverter"}
                        if mod(device, 2) == 1
                            complementaryDevice = device + 1;
                        else
                            complementaryDevice = device - 1;
                        end
                    otherwise
                        complementaryDevice = device;
                end
            end
            function [Ion_event, Ioff_event] = getDatasheetSwitchingCurrentEstimate(app)
                if ismember(app.SelectedTopology, ["Boost","Buck","Flyback"])
                    fsw = app.fswEditField_Boost.Value;
                else
                    fsw = app.fswACEditField.Value;
                end
                switch app.SelectedTopology
                    case "Boost"
                        Vin  = app.VinEditField_Boost.Value;
                        Vout = app.VoutEditField_Boost.Value;
                        Pout = app.PoutEditField_Boost.Value;
                        L    = app.LEditField_Boost_2.Value;
                        D = 1 - Vin/Vout;
                        Iavg = Pout/Vin;
                        dIpp = Vin*D/(L*fsw);
                        Ion_event  = max(Iavg - dIpp/2, 0);
                        Ioff_event = Iavg + dIpp/2;
                    case "Buck"
                        Vin  = app.VinEditField_Boost.Value;
                        Vout = app.VoutEditField_Boost.Value;
                        Pout = app.PoutEditField_Boost.Value;
                        L    = app.LEditField_Boost_2.Value;
                        D = Vout/Vin;
                        Iavg = Pout/Vout;
                        dIpp = (Vin-Vout)*D/(L*fsw);

                        Ion_event  = max(Iavg - dIpp/2, 0);
                        Ioff_event = Iavg + dIpp/2;

                    case "Flyback"
                        Vin  = app.VinEditField_Boost.Value;
                        Vout = app.VoutEditField_Boost.Value;
                        Pout = app.PoutEditField_Boost.Value;
                        Lm   = app.LmEditField.Value;
                        n    = app.TurnsRatioEditField.Value; % Np/Ns
                        diode = getDiodeParams(app);
                        D = n*(Vout + diode.Vf) /(Vin + n*(Vout + diode.Vf));
                        IavgDuringOn = Pout / (Vin*D);
                        dIpp = Vin*D/(Lm*fsw);
                        if IavgDuringOn >= dIpp/2
                            Ion_event  = IavgDuringOn - dIpp/2;
                            Ioff_event = IavgDuringOn + dIpp/2;
                        else
                            Ion_event  = 0;
                            Ioff_event = sqrt(2*Pout/(Lm*fsw));
                        end
                    case {"Half-bridge inverter","Full-bridge inverter"}
                        Pout = app.PoutACEditField.Value;
                        Vout = app.VoutRMSEditField.Value;
                        PF   = app.PFEditField.Value;
                        Irms = Pout/(Vout*PF);
                        IeventMean = 2*sqrt(2)/pi * Irms;
                        Ion_event  = IeventMean;
                        Ioff_event = IeventMean;
                    case "Three-phase inverter"
                        Pout= app.PoutACEditField.Value;
                        VLLRMS = app.VLLRMSEditField.Value;
                        PF = app.PFEditField.Value;
                        Irms = Pout/(sqrt(3)*VLLRMS*PF);
                        IeventMean = 2*sqrt(2)/pi * Irms;
                        Ion_event  = IeventMean;
                        Ioff_event = IeventMean;
                    otherwise
                        error('Unsupported topology for switching-current estimate.');
                end
            end
            function [Psw_avg, swEvents] = computeDatasheetSwitchingLossAverage(app)
                sw = getSwitchParams(app);
                if ismember(app.SelectedTopology, ["Boost","Buck","Flyback"])
                    fsw = app.fswEditField_Boost.Value;
                else
                    fsw = app.fswACEditField.Value;
                end
                nSw = getActiveSwitchCount(app);
                Eon_ref  = sw.Eon;
                Eoff_ref = sw.Eoff;
                Iref     = sw.Eref_I;
                Vref     = sw.Eref_V;
                if any(~isfinite([Eon_ref,Eoff_ref,Iref,Vref,fsw,nSw])) || ...
                        Eon_ref < 0 || Eoff_ref < 0 || Iref <= 0 || Vref <= 0 || fsw <= 0 || nSw <= 0
                    error('Invalid datasheet switching-energy reference data.');
                end
                if Eon_ref + Eoff_ref <= 0
                    error('Datasheet Eon/Eoff fallback has no positive energy data.');
                end
                [Ion_event, Ioff_event] = getDatasheetSwitchingCurrentEstimate(app);
                Vevent = getBlockingVoltageEstimate(app);
                if any(~isfinite([Ion_event,Ioff_event,Vevent])) || Ion_event < 0 || Ioff_event < 0 || Vevent <= 0
                    error('Invalid estimated switching event conditions.');
                end
                voltageScale = Vevent/Vref;
                Eon_scaled   = Eon_ref  * (Ion_event/Iref)  * voltageScale;
                Eoff_scaled  = Eoff_ref * (Ioff_event/Iref) * voltageScale;
                if ismember(app.SelectedTopology, ["Half-bridge inverter","Full-bridge inverter","Three-phase inverter"])
                    nEffective = nSw/2;
                else
                    nEffective = nSw;
                end
                
                Poverlap_avg = nEffective * fsw * (Eon_scaled + Eoff_scaled);
                Pcap_avg     = 0;
                Psw_avg      = Poverlap_avg;
                if max([Ion_event,Ioff_event]/Iref) > 2 || min([Ion_event,Ioff_event]/Iref) < 0.5 || ...
                        voltageScale > 2 || voltageScale < 0.5
                    warning(['Datasheet switching-energy scaling is being extrapolated far from its reference point.']);
                end
                swEvents = table( nSw, fsw, Ion_event, Ioff_event, Vevent, Iref, Vref, ...
                    Eon_ref, Eoff_ref, Eon_scaled, Eoff_scaled, Poverlap_avg, Pcap_avg, Psw_avg, 'VariableNames', { 'NumberOfSwitches','fsw_Hz','Ion_A','Ioff_A', ...
                    'Vevent_V','Iref_A','Vref_V', 'EonRef_J','EoffRef_J','EonScaled_J','EoffScaled_J', ...
                    'Poverlap_W','Pcap_W','Psw_W'});
            end
            function [Psw_avg, events] = computeSwitchingLossFromVDSCurrent(app, t, VDS, Isw)
                t   = t(:);
                VDS = squeeze(VDS);
                Isw = squeeze(Isw);
                if isvector(VDS), VDS = VDS(:); end
                if isvector(Isw), Isw = Isw(:); end
                n = min([numel(t), size(VDS,1), size(Isw,1)]);
                if n < 5
                    Psw_avg = 0;
                    events = table();
                    return
                end
                t   = t(1:n);
                VDS = VDS(1:n,:);
                Isw = Isw(1:n,:);
                if size(VDS,2) ~= size(Isw,2)
                    error(['VDS and Isw must have one matched column per physical switch.']);
                end
                if any(~isfinite(t)) || any(diff(t) <= 0) || any(~isfinite(VDS(:))) || any(~isfinite(Isw(:)))
                    error('Invalid switching waveform or time vector.');
                end
                if ismember(app.SelectedTopology, ["Boost","Buck","Flyback"])
                    fsw = app.fswEditField_Boost.Value;
                else
                    fsw = app.fswACEditField.Value;
                end
                if ~isfinite(fsw) || fsw <= 0
                    error('Switching frequency must be positive.');
                end
                Tobs = t(end) - t(1);
                Tsw  = 1 / fsw;
                dt   = median(diff(t), 'omitnan');
                if Tobs <= 0 || ~isfinite(dt) || dt <= 0
                    error('Invalid observation interval.');
                end
                if Tsw / dt < 40
                    warning(['Only %.1f samples per switching period are available. At least 40 are required.'], Tsw/dt);
                end
                events = table( 'Size', [0 8], 'VariableTypes', { 'double','string','double','double', ...
                        'double','double','double','double'}, 'VariableNames', { 'Device','Type','tStart','tEnd', ...
                        'Duration_s','Energy_J','Ievent_A','Vevent_V'});
                totalEnergy_J = 0;
                minSeparation = max(2, round(0.20 * Tsw / dt));
                maxSearch     = max(4, round(0.20 * Tsw / dt));
                for dev = 1:size(VDS,2)
                    vSigned = VDS(:,dev);
                    iSigned = Isw(:,dev);
                    vAbs    = abs(vSigned);
                    iAbs    = abs(iSigned);
                    positiveV = sort(vAbs(vAbs > 0 & isfinite(vAbs)));
                    if isempty(positiveV), continue; end
                    q90 = max(1, min(numel(positiveV), round(0.90 * numel(positiveV))));
                    Vblock = positiveV(q90);
                    if ~isfinite(Vblock) || Vblock <= 1e-9
                        continue
                    end
                    rawPower = vSigned .* iSigned;
                    onMask = vAbs <= 0.10 * Vblock & iAbs >= max(1e-6, 0.01 * max(iAbs));
                    if any(onMask)
                        signReference = median(rawPower(onMask), 'omitnan');
                        if isfinite(signReference) && signReference < 0
                            rawPower = -rawPower;
                        end
                    end
                    pDiss = max(rawPower, 0);
                    onStarts = find( vAbs(1:end-1) >= 0.90*Vblock & vAbs(2:end)   <  0.90*Vblock) + 1;
                    offStarts = find(vAbs(1:end-1) <= 0.10*Vblock & vAbs(2:end)   >  0.10*Vblock) + 1;
                    candidateIndex = [onStarts; offStarts];
                    candidateType  = [ repmat("Turn-on",  numel(onStarts),  1); repmat("Turn-off", numel(offStarts), 1)]
                    [candidateIndex, order] = sort(candidateIndex);
                    candidateType = candidateType(order);
                    lastAccepted = -Inf;
                    for e = 1:numel(candidateIndex)
                        i1 = candidateIndex(e);
                        eventType = candidateType(e);
                        if i1 - lastAccepted < minSeparation
                            continue
                        end
                        iMax = min(n, i1 + maxSearch);
                        if eventType == "Turn-on"
                            relEnd = find(vAbs(i1:iMax) <= 0.10*Vblock, 1, 'first');
                        else
                            relEnd = find(vAbs(i1:iMax) >= 0.90*Vblock, 1, 'first');
                        end
                        if isempty(relEnd), continue; end
                        i2 = i1 + relEnd - 1;
                        if i2 <= i1 || (t(i2)-t(i1)) > 0.30*Tsw
                            continue
                        end
                        nBase = max(2, round(0.01*Tsw/dt));
                        preIdx  = max(1,i1-nBase):max(1,i1-1);
                        postIdx = min(n,i2+1):min(n,i2+nBase);
                        Ppre  = median(pDiss(preIdx),  'omitnan');
                        Ppost = median(pDiss(postIdx), 'omitnan');
                        if ~isfinite(Ppre),  Ppre  = 0; end
                        if ~isfinite(Ppost), Ppost = 0; end
                        baseline = linspace(Ppre, Ppost, i2-i1+1).';
                        pExcess  = max(pDiss(i1:i2) - baseline, 0);
                        Eevent = trapz(t(i1:i2), pExcess);
                        if ~isfinite(Eevent) || Eevent <= 0
                            continue
                        end
                        totalEnergy_J = totalEnergy_J + Eevent;
                        lastAccepted = i2;
                        newRow = table( dev, eventType, t(i1), t(i2), t(i2)-t(i1), Eevent, max(iAbs(i1:i2)), max(vAbs(i1:i2)), ...
                            'VariableNames', events.Properties.VariableNames);
                        events = [events; newRow];
                    end
                end
                Psw_avg = totalEnergy_J / Tobs;
                maximumPlausibleEvents = 2.5 * size(VDS,2) * fsw * Tobs;
            end
            function [ThermalTrace, M] = buildMissionThermalTrace1min(app, M, Tprof, outputStep_s)
                if nargin < 4 || isempty(outputStep_s), outputStep_s = 60; end
                if ~istable(M) || isempty(M), error('MissionResults table is empty.'); end
                if ~istable(Tprof) || isempty(Tprof), error('Mission profile table is empty.'); end
                if ~ismember('T_amb_C', Tprof.Properties.VariableNames)
                    error('Mission profile must contain T_amb_C.');
                end
                th = getThermalParams(app);
                Rth = th.Rth_foster_sw(:).';
                tau = th.tau_foster_sw(:).';
            
                if isempty(Rth) || numel(Rth) ~= numel(tau)
                    error('Invalid Foster thermal-network arrays.');
                end
                if any(tau <= 0)
                    error('All thermal time constants must be positive.');
                end
                sw = getSwitchParams(app);
                isIGBT = strcmp(sw.Type, 'Si IGBTs');
                if isIGBT
                    conductionReference = getVceSatAtTemperature(app, sw.Tref_cond_C);
                else
                    conductionReference = getRdsOnAtTemperature(app, sw.Tref_cond_C);
                end
                if ~isfinite(conductionReference) || conductionReference <= 0
                    error('Invalid reference-temperature conduction parameter.');
                end
                x = zeros(1, numel(Rth));
                time_s = 0;

                if app.FixedTemp.Value
                    Tstart_C = th.Th_C;
                else
                    Tstart_C = Tprof.T_amb_C(1);
                end
                Th_state_C = Tstart_C;
                Tj_C = Tstart_C;
                Tamb_C = Tstart_C;
                operationIndex = 1;
                powerTrace = nan(1,13);
                x_foster_K = x;
                x_heatsink_K = 0;
                segmentPeak_C = -inf(height(M),1);
                maxIterations = 50;
                temperatureTolerance_C = 1e-4;
                relaxation = 0.5;
                for k = 1:height(M)
                    duration = M.Duration_s(k);
                    if duration <= 0
                        error('Duration_s must be positive in every mission segment.');
                    end
                    if app.FixedTemp.Value
                        Tamb_k = th.Th_C;
                    else
                        Tamb_k = Tprof.T_amb_C(k);
                    end
                    Pcond_ref = M.Pcond_W(k);
                    Psw_k = M.Psw_W(k);
                    PextFwd = getTableValueOrDefault(app, M, k, 'Pdiode_ext_fwd_W', 0);
                    PbodyFwd = getTableValueOrDefault(app, M, k, 'Pdiode_body_fwd_W', 0);
                    PrrExt = getTableValueOrDefault(app, M, k, 'Prr_ext_W', 0);
                    PrrBody = getTableValueOrDefault(app, M, k, 'Prr_body_W', 0);
                    Prr = PrrExt + PrrBody;
                    PdiodeFwd = PextFwd + PbodyFwd;
                    PdiodeExt = PextFwd + PrrExt;
                    PdiodeBody = PbodyFwd + PrrBody;
                    Pdiode = PdiodeFwd + Prr;
                    remaining = duration;
                    while remaining > 1e-9
                        dt = min(outputStep_s, remaining);
                        oneMinusDecay = -expm1(-dt ./ tau);
                        decay = 1 - oneMinusDecay;
                        fosterAverageWeight = (tau ./ dt) .* oneMinusDecay;
                        if isinf(th.tau_ha)
                            oneMinusDecay_h = 0;
                            decay_h = 1;
                            heatsinkAverageWeight = 1;
                        else
                            oneMinusDecay_h = -expm1(-dt / th.tau_ha);
                            decay_h = 1 - oneMinusDecay_h;
                            heatsinkAverageWeight = (th.tau_ha / dt) * oneMinusDecay_h;
                        end
                        Tj_guess = Tj_C(end);
                        converged = false;
                        for iter = 1:maxIterations
                            if isIGBT
                                conductionAtT = getVceSatAtTemperature(app, Tj_guess);
                            else
                                conductionAtT = getRdsOnAtTemperature(app, Tj_guess);
                            end
                            conductionFactor = conductionAtT / conductionReference;
                            Pcond_k = Pcond_ref * conductionFactor;
                            Pjunction = Pcond_k + Psw_k + PdiodeBody;
                            Tj_start = Th_state_C + Pjunction * th.Rth_ch + sum(x);
                            x_new = x .* decay + Pjunction .* Rth .* oneMinusDecay;
                            Th_new = Tamb_k + (Th_state_C - Tamb_k) * decay_h + Pjunction * th.Rth_ha * oneMinusDecay_h;
                            Tj_end = Th_new + Pjunction * th.Rth_ch + sum(x_new);

                            x_average = x .* fosterAverageWeight + Pjunction .* Rth .* (1 - fosterAverageWeight);
                            Th_average = Tamb_k + (Th_state_C - Tamb_k) * heatsinkAverageWeight + Pjunction * th.Rth_ha * (1 - heatsinkAverageWeight);
                            Tj_new = Th_average + Pjunction * th.Rth_ch + sum(x_average);
                            if any(~isfinite([conductionAtT, conductionFactor, Pcond_k, Pjunction, Tj_start, Tj_end, Tj_new]))
                                error('Non-finite electrothermal result in mission segment %d.', k);
                            end
                            if abs(Tj_new - Tj_guess) < temperatureTolerance_C
                                converged = true;
                                break
                            end
                            Tj_guess = Tj_guess + relaxation * (Tj_new - Tj_guess);
                        end
                        if ~converged
                            error('Electrothermal fixed-point iteration did not converge in mission segment %d at t = %.6g s.', k, time_s(end));
                        end
                        x = x_new;
                        Th_state_C = Th_new;
                        remaining = remaining - dt;
                        time_s(end+1,1) = time_s(end) + dt;
                        Tj_C(end+1,1) = Tj_end;
                        Tamb_C(end+1,1) = Tamb_k;
                        operationIndex(end+1,1) = k;
                        segmentPeak_C(k) = max(segmentPeak_C(k), max(Tj_start, Tj_end));
                        powerTrace(end+1,:) = [Pcond_k, Psw_k, PextFwd, PbodyFwd, PrrExt, PrrBody, Prr, PdiodeFwd, PdiodeExt, PdiodeBody, Pdiode, Pcond_k + Psw_k + Pdiode, conductionFactor];
                        x_foster_K(end+1,:) = x;
                        x_heatsink_K(end+1,1) = Th_state_C - Tamb_k;
                    end
                end
                ThermalTrace = table(time_s, time_s / 60, Tj_C, Tamb_C, operationIndex, ...
                    'VariableNames', {'time_s', 'time_min','Tj_C', 'Tamb_C', 'operationIndex'});

                powerNames = {'Pcond_scaled_W', 'Psw_W', 'Pdiode_ext_fwd_W', 'Pdiode_body_fwd_W', 'Prr_ext_W', 'Prr_body_W', 'Prr_W', ...
                    'Pdiode_fwd_W', 'Pdiode_ext_W', 'Pdiode_body_W', 'Pdiode_W', 'Ptotal_W', 'Rds_factor'};
                for j = 1:numel(powerNames)
                    ThermalTrace.(powerNames{j}) = powerTrace(:,j);
                end
                ThermalTrace.x_foster_K = x_foster_K;
                ThermalTrace.x_heatsink_K = x_heatsink_K;
                dt_all = diff(time_s);
                op_all = operationIndex(2:end);
                intervalPowers = powerTrace(2:end,[1 2 11 12 4 6 10]);
                if size(intervalPowers,1) ~= numel(dt_all)
                    error('Internal mission thermal trace size mismatch.');
                end
                for k = 1:height(M)
                    intervalMask = op_all == k & isfinite(dt_all) & dt_all > 0;
                    temperatureMask = operationIndex == k;
                    if any(temperatureMask)
                        lastIndex = find(temperatureMask, 1, 'last');
                        M.Tj_end_C(k) = Tj_C(lastIndex);
                        M.Tj_peak_C(k) = segmentPeak_C(k);
                    end
                    if ~any(intervalMask), continue; end
                    dt = dt_all(intervalMask);
                    dt = dt(:);
                    duration = sum(dt);
                    segmentPowers = intervalPowers(intervalMask,:);
                    if size(segmentPowers,1) ~= numel(dt)
                        error('Internal mission segment size mismatch.');
                    end

                    energy = sum(bsxfun(@times, segmentPowers, dt), 1);
                    M.Pcond_W(k) = energy(1) / duration;
                    M.Psw_W(k) = energy(2) / duration;
                    M.Pdiode_W(k) = energy(3) / duration;
                    M.Ploss_W(k) = energy(4) / duration;
                    M.Pdiode_body_fwd_W(k) = energy(5) / duration;
                    M.Prr_body_W(k) = energy(6) / duration;
                    M.Pdiode_body_W(k) = energy(7) / duration;
                    if ismember(app.SelectedTopology, ["Boost","Flyback"])
                        M.Prr_W(k) = M.Prr_ext_W(k) + M.Prr_body_W(k);
                        M.Pdiode_fwd_W(k) = M.Pdiode_ext_fwd_W(k) + M.Pdiode_body_fwd_W(k);
                        M.Pdiode_ext_W(k) = M.Pdiode_ext_fwd_W(k) + M.Prr_ext_W(k);
                        M.Pdiode_body_W(k) = M.Pdiode_body_fwd_W(k) + M.Prr_body_W(k);
                    else
                        M.Pdiode_ext_fwd_W(k) = 0;
                        M.Prr_ext_W(k) = 0;
                        M.Pdiode_ext_W(k) = 0;
                        M.Prr_W(k) = M.Prr_body_W(k);
                        M.Pdiode_fwd_W(k) = M.Pdiode_body_fwd_W(k);
                    end
                    M.Econd_J(k) = energy(1);
                    M.Esw_J(k) = energy(2);
                    M.Ediode_J(k) = energy(3);
                    M.Eloss_J(k) = energy(4);
                    M.Eout_J(k) = M.Pout_sim_W(k) * M.Duration_s(k);
                    if M.Eout_J(k) > 0
                        M.Eff_percent(k) = 100 * M.Eout_J(k) / (M.Eout_J(k) + M.Eloss_J(k));
                    else
                        M.Eff_percent(k) = 0;
                    end
                end
            end
            function updateAutomaticFlybackTurnsRatio(app)
                app.TurnsRatioEditField.Value = calculateFlybackTurnsRatio(app);
            end
            function Psw_cond_inst = computeSwitchConductionLossPWM(app, t, Isw, PWM)
                sw = getSwitchParams(app);
                t   = t(:);
                Isw = Isw(:);
                PWM = PWM(:);
                n = min([numel(t),numel(Isw),numel(PWM)]);
                if n < 2
                    error('Not enough samples for PWM-gated conduction loss.');
                end
                t   = t(1:n);
                Isw = Isw(1:n);
                PWM = PWM(1:n);
                if any(~isfinite(t)) || any(diff(t) <= 0) || any(~isfinite(Isw)) || any(~isfinite(PWM))
                    error('Invalid PWM conduction signals.');
                end
                gateOn = PWM > 0.5;
                dt = median(diff(t),'omitnan');
                if ismember(app.SelectedTopology, ["Boost","Buck","Flyback"])
                    fsw = app.fswEditField_Boost.Value;
                else
                    fsw = app.fswACEditField.Value;
                end
                if isfinite(dt) && dt > 0 && isfinite(fsw) && fsw > 0
                    Tsw = 1/fsw;
                    Nblank = max(1,round(0.002*Tsw/dt));
                    edges = find(diff(gateOn) ~= 0) + 1;
                    for e = edges(:).'
                        gateOn(max(1,e-Nblank):min(n,e+Nblank)) = false;
                    end
                end
                if strcmp(sw.Type,'Si IGBTs')
                    Ic = abs(Isw).*double(gateOn);
                    [VCE0_T,rCE_T] = getIGBTConductionParamsAtTemperature(app, sw.Tref_cond_C);
                    Psw_cond_inst = VCE0_T.*Ic + rCE_T.*Ic.^2;
                else
                    Ich = Isw .* double(gateOn);
                    Psw_cond_inst = abs(Ich).^2 .* sw.RdsOn;
                end
            end
            function value = getTableValueOrDefault(~, T, rowIndex, varName, defaultValue)
                if istable(T) && ismember(varName, T.Properties.VariableNames)
                    value = T.(varName)(rowIndex);
                else
                    value = defaultValue;
                end
                if isempty(value) || ~isfinite(value)
                    value = defaultValue;
                end
            end
            function plotMissionTemperature(app)
                if ~isfield(app.SimulationData, 'MissionThermalTrace')
                    return
                end
                TT = app.SimulationData.MissionThermalTrace;
                cla(app.ThermalEvolutionPlot)
                plot(app.ThermalEvolutionPlot, TT.time_min, TT.Tj_C, 'LineWidth', 1.8)
                xlabel(app.ThermalEvolutionPlot, 'Mission time [min]')
                ylabel(app.ThermalEvolutionPlot, 'Junction temperature [°C]')
                if strcmp(app.SwitchTechnologyDropDown.Value, 'Si IGBTs')
                    title(app.ThermalEvolutionPlot, 'IGBT Junction Temperature Evolution')
                else
                    title(app.ThermalEvolutionPlot, 'MOSFET Junction Temperature Evolution')
                end
                grid(app.ThermalEvolutionPlot, 'on')
            end
            function runDCDCSimulation(app, simStopTime)
                if nargin < 2, simStopTime = []; end
                topology = app.SelectedTopology;
                isDCDC = ismember(topology, ["Boost","Buck","Flyback"]);
                isFlyback = topology == "Flyback";
                isBoost = topology == "Boost";
                if isDCDC && ~app.UseMissionProfileCheckBox.Value
                    if isFlyback
                        updateAutomaticFlybackTurnsRatio(app);
                    end
                    [Lauto_H, Cauto_F] = calculateAutomaticDCDCComponents(app, topology,  app.VinEditField_Boost.Value, app.VoutEditField_Boost.Value, ...
                        app.PoutEditField_Boost.Value, app.fswEditField_Boost.Value);
                    if isFlyback
                        app.LmEditField.Value = Lauto_H;
                    else
                        app.LEditField_Boost_2.Value = Lauto_H;
                    end
                    app.CoutEdit_Field.Value = Cauto_F;
                end
                if isDCDC
                    automaticStopTime = calculateAutomaticDCDCStopTime(app);
                    if isempty(simStopTime)
                        simStopTime = automaticStopTime;
                    else
                        simStopTime = max(simStopTime, automaticStopTime);
                    end
                    assignin('base', 'simStopTime', simStopTime);
                end
                exportDCDCParamsToWorkspace(app);
                modelName = getImplementedModelName(app);
                load_system(modelName);
                sw = getSwitchParams(app);
                if strcmp(sw.Type, 'Si IGBTs')
                    exportIGBTParamsToBase(app, sw);
                    exportIGBTParamsToModelWorkspace(app, modelName, sw);
                end
                waitUntilModelStopped(app, modelName);
                if isFlyback
                    sendAutomaticFlybackTurns(app, modelName);
                end
                simOut = runModelWithSimulationProgress(app, modelName, simStopTime);
                waitUntilModelStopped(app, modelName);
                app.SimulationData.SimOut = simOut;
                [t, Vout] = unpackSimSignal(app,getSignalFromSimOut(app, simOut, {'Vout_sim','Vout'}), 'Vout');
                [~, Iout] = unpackSimSignal(app,getSignalFromSimOut(app, simOut, {'Iout_sim','Iout'}), 'Iout');
                [~, VDS] = unpackSimSignal(app, getSignalFromSimOut(app, simOut, {'VDS_sim','Vds_sim'}), 'VDS');
                [~, VD] = unpackSimSignal(app, getSignalFromSimOut(app, simOut, {'VD_sim','Vd_sim'}), 'VD');
                [~, Isw] = unpackSimSignal(app, getSignalFromSimOut(app, simOut, {'Isw_sim'}), 'Isw');
                [~, IL] = unpackSimSignal(app, getSignalFromSimOut(app, simOut, {'IL_sim','Il_sim','IL'}), 'IL');
                [~, ID] = unpackSimSignal(app, getSignalFromSimOut(app, simOut, {'ID_sim','Id_sim'}), 'ID');
                [~, PWM] = unpackSimSignal(app, getSignalFromSimOut(app, simOut, {'PWM_sim','PWMSignal','PWMsignal','PWM'}), 'PWM');
                if isFlyback || strcmp(sw.Type, 'GaN MOSFETs')
                    IDiodeIntrinsic = zeros(size(t));
                    VDiodeIntrinsic = zeros(size(t));
                else
                    [~, IDiodeIntrinsic] = unpackSimSignal(app, getSignalFromSimOut(app, simOut, {'IDiodeIntrinsic_sim','IDiodeIntrinsic','ID_intrinsic'}), ...
                        'IDiodeIntrinsic');
                    [~, VDiodeIntrinsic] = unpackSimSignal(app, getSignalFromSimOut(app, simOut, {'VDiodeIntrinsic_sim','VDiodeIntrinsic','VD_intrinsic'}), 'VDiodeIntrinsic');
                end
                n = min([numel(t), numel(Vout), numel(Iout), numel(VDS), numel(VD),  numel(Isw), numel(IL), numel(ID), numel(PWM), numel(IDiodeIntrinsic), numel(VDiodeIntrinsic)]);
                t = t(1:n);
                Vout = Vout(1:n);
                Iout = Iout(1:n);
                VDS = VDS(1:n);
                VD = VD(1:n);
                Isw = Isw(1:n);
                IL = IL(1:n);
                ID = ID(1:n);
                PWM = PWM(1:n);
                IDiodeIntrinsic = IDiodeIntrinsic(1:n);
                VDiodeIntrinsic = VDiodeIntrinsic(1:n);
            
                if isfield(app.SimulationData, 'Results')
                    R = app.SimulationData.Results;
                else
                    R = struct();
                end
                R.t = t;
                R.Vout_sim = Vout;
                R.Iout_sim = Iout;
                R.VDS_sim = VDS;
                R.VD_sim = VD;
                R.Isw_sim = Isw;
                R.IL_sim = IL;
                R.ID_sim = ID;
                R.PWM_sim = PWM;
                R.IDiodeIntrinsic_sim = IDiodeIntrinsic;
                R.VDiodeIntrinsic_sim = VDiodeIntrinsic;
            
                nRefCycles = 10;
                idxRef = getSteadyStatePWMWindow(app, t, PWM, nRefCycles);
                tRef = t(idxRef);
                VoutRef = Vout(idxRef);
                IoutRef = Iout(idxRef);
                VDSRef = VDS(idxRef);
                IswRef = Isw(idxRef);
                ILRef = IL(idxRef);
                IDRef = ID(idxRef);
                VDRef = VD(idxRef);
                PWMRef = PWM(idxRef);
                if isBoost
                    validateBoostDiodePolarity(app, tRef, IDRef, VDRef);
                end
                IDiodeIntrinsicRef = IDiodeIntrinsic(idxRef);
                VDiodeIntrinsicRef = VDiodeIntrinsic(idxRef);        
                Tref = tRef(end) - tRef(1);          
                if Tref <= 0
                    error('Invalid steady-state reference window. Tref must be positive.');
                end
                Pout_inst = Vout .* Iout;
                if isBoost
                    Icond = IL;
                    IcondRef = ILRef;
                else
                    Icond = Isw;
                    IcondRef = IswRef;
                end
                Psw_cond_inst = computeSwitchConductionLossPWM(app, t, Icond, PWM);
                Psw_cond_ref = computeSwitchConductionLossPWM(app, tRef, IcondRef, PWMRef);
                Psw_cond_avg = averageOverWindow(app, tRef, Psw_cond_ref);
                Pout_avg = averageOverWindow(app, tRef, VoutRef .* IoutRef);
                if isBoost || isFlyback
                    Pdiode_ext_inst = computeDiodeLossFromCurrent(app, ID);
                else
                    Pdiode_ext_inst = zeros(size(t));
                end
                if isFlyback
                    Pdiode_body_inst = zeros(size(t));
                else
                    [Pdiode_body_inst, ~, ~] = computeBodyDiodeLossAccurate( app, t, IDiodeIntrinsic, VDiodeIntrinsic, PWM);
                end
                Pdiode_inst = Pdiode_ext_inst + Pdiode_body_inst;
                [Psw_switch_avg, swEvents] = computeSwitchingLossSelected(app,tRef,PWMRef,VDSRef,IswRef);
                Pcap_avg = 0;
                Psw_overlap_avg = Psw_switch_avg;
                Pgate_avg = 0;
            
                if istable(swEvents) && ~isempty(swEvents)
                    names = swEvents.Properties.VariableNames;
                    if ismember('Pcap_W', names)
                        Pcap_avg = swEvents.Pcap_W(1);
                    end
                    if ismember('Poverlap_W', names)
                        Psw_overlap_avg = swEvents.Poverlap_W(1);
                    end
                    if ismember('Pgate_W', names)
                        Pgate_avg = swEvents.Pgate_W(1);
                    end
                end
                diode = getDiodeParams(app);
                if isBoost || isFlyback
                    [Pdiode_ext_ref, Prr_ext_reported_avg, rrExtEvents] = computeDiodeLossWithReverseRecovery(app, tRef, IDRef, VDRef, ...
                        diode, "external-diode");
                    Pdiode_ext_fwd_reported_avg =averageOverWindow(app, tRef, Pdiode_ext_ref);
                    includeExternalDiode = ~isBoost || includeExternalBoostDiode(app);
                    if includeExternalDiode
                        Pdiode_ext_fwd_avg = Pdiode_ext_fwd_reported_avg;
                    else
                        Pdiode_ext_fwd_avg = 0;
                        Pdiode_ext_inst = zeros(size(Pdiode_ext_inst));
                    end
                    switch app.ReverseRecoveryAccounting
                        case "IncludedInSwitching"
                            Prr_ext_avg = 0;
                        case "Separate"
                            Prr_ext_avg = double(includeExternalDiode) * Prr_ext_reported_avg;
                        otherwise
                            error('Invalid ReverseRecoveryAccounting configuration.');
                    end
                else
                    includeExternalDiode = false;
                    Pdiode_ext_fwd_reported_avg = 0;
                    Pdiode_ext_fwd_avg = 0;
                    Prr_ext_reported_avg = 0;
                    Prr_ext_avg = 0;
                    rrExtEvents = table();
                end
                Pdiode_inst = Pdiode_ext_inst + Pdiode_body_inst;
                if isFlyback
                    Pdiode_body_fwd_avg = 0;
                    Prr_body_reported_avg = 0;
                    Prr_body_avg = 0;
                    rrBodyEvents = table();
                else
                    [~, Pdiode_body_fwd_avg, ~] = computeBodyDiodeLossAccurate(app, tRef, IDiodeIntrinsicRef, VDiodeIntrinsicRef, PWMRef);
                    [~, Prr_body_reported_avg, rrBodyEvents] = computeDiodeLossWithReverseRecovery(app, tRef, IDiodeIntrinsicRef, VDiodeIntrinsicRef, diode, "body");
                    switch app.ReverseRecoveryAccounting
                        case "IncludedInSwitching"
                            Prr_body_avg = 0;
                        case "Separate"
                            Prr_body_avg = Prr_body_reported_avg;
                        otherwise
                            error('Invalid ReverseRecoveryAccounting configuration.');
                    end
                end
                Pdiode_ext_avg = Pdiode_ext_fwd_avg + Prr_ext_avg;
                Pdiode_body_avg = Pdiode_body_fwd_avg + Prr_body_avg;
                Pdiode_fwd_avg = Pdiode_ext_fwd_avg + Pdiode_body_fwd_avg;
                Pdiode_rr_avg = Prr_ext_avg + Prr_body_avg;
                Pdiode_avg = Pdiode_ext_avg + Pdiode_body_avg;
                Ptotal_avg = Psw_cond_avg + Psw_switch_avg + Pdiode_avg;
                if Pout_avg > 0
                    Efficiency = 100 * Pout_avg / (Pout_avg + Ptotal_avg);
                else
                    Efficiency = 0;
                end
                R.idxRef = idxRef;
                R.tRef = tRef;
                R.nRefCycles = nRefCycles;
                R.Tref = Tref;
                R.Pout_inst = Pout_inst;
                R.Psw_cond_inst = Psw_cond_inst;
                R.Psw_switch_inst = repmat(Psw_switch_avg, n, 1);
                R.Pdiode_inst = Pdiode_inst;
                R.Pout_avg = Pout_avg;
                R.Psw_cond_avg = Psw_cond_avg;
                R.Psw_switch_avg = Psw_switch_avg;
                R.Pcap_avg = Pcap_avg;
                R.Psw_overlap_avg = Psw_overlap_avg;
                R.Pgate_avg = Pgate_avg;
                R.Pdiode_ext_fwd_avg = Pdiode_ext_fwd_avg;
                R.Pdiode_body_fwd_avg = Pdiode_body_fwd_avg;
                R.Pdiode_fwd_avg = Pdiode_fwd_avg;
                R.Pdiode_ext_avg = Pdiode_ext_avg;
                R.Pdiode_body_avg = Pdiode_body_avg;
                R.Pdiode_avg = Pdiode_avg;
                R.Prr_ext_avg = Prr_ext_avg;
                R.Prr_ext_reported_avg = Prr_ext_reported_avg;
                R.Prr_body_avg = Prr_body_avg;
                R.Pdiode_rr_avg = Pdiode_rr_avg;
                R.Ptotal_avg = Ptotal_avg;
                R.Efficiency = Efficiency;
                R.SwitchingEvents = swEvents;
                R.ReverseRecoveryExternalEvents = rrExtEvents;
                R.ReverseRecoveryBodyEvents = rrBodyEvents;
                R.IL_avg = averageOverWindow(app, tRef, ILRef);
                R.IL_rms = sqrt(averageOverWindow(app, tRef, ILRef.^2));
                R.IL_max = max(abs(ILRef));
                R.Isw_avg = averageOverWindow(app, tRef, IswRef);
                R.Isw_rms = sqrt(averageOverWindow(app, tRef, IswRef.^2));
                R.Isw_max = max(abs(IswRef));
                R.Icond_avg = averageOverWindow(app, tRef, IcondRef);
                R.Icond_rms = sqrt(averageOverWindow(app, tRef, IcondRef.^2));
                R.Icond_max = max(abs(IcondRef));
                app.SimulationData.Results = R;
                validateOperatingPointResults(app);
            end
            function [Pdiode_fwd_inst, Prr_avg, rrEvents] = computeDiodeLossWithReverseRecovery( app, t, ID, VD, diode, label)
                if nargin < 6
                    label = "diode";
                end
                t  = t(:);
                ID = squeeze(ID);
                VD = squeeze(VD);
                if isvector(ID)
                    ID = ID(:);
                end
                if isvector(VD)
                    VD = VD(:);
                end
                n = min([numel(t), size(ID,1), size(VD,1)]);
                if n < 2
                    Pdiode_fwd_inst = zeros(max(n,1),1);
                    Prr_avg = 0;
                    rrEvents = table();
                    return
                end
                t  = t(1:n);
                ID = ID(1:n,:);
                VD = VD(1:n,:);
                nID = size(ID,2);
                nVD = size(VD,2);
                if nID == 1 && nVD > 1
                    ID = repmat(ID, 1, nVD);
                elseif nVD == 1 && nID > 1
                    VD = repmat(VD, 1, nID);
                elseif nID ~= nVD
                    error( ['Diode current and voltage matrices must have the same ' ...
                         'number of device columns. ID has %d and VD has %d.'], nID, nVD);
                end
                if any(~isfinite(ID(:))) || any(~isfinite(VD(:)))
                    error('Diode voltage/current signals contain NaN or Inf values.');
                end
                Vf   = diode.Vf;
                RonD = diode.Ron;
                Pdiode_fwd_inst = computeMeasuredDiodeForwardPower(app, ID, VD);
                rrEvents = table( 'Size', [0 8], 'VariableTypes', { 'double','string','string', 'double','double','double','double','double'}, ...
                    'VariableNames', { 'Device','Diode','Method', 'tStart','tEnd','Qrr_C','Err_J','Irr_peak_A'});
                Tobs = t(end) - t(1);
                if ~isfinite(Tobs) || Tobs <= 0
                    Prr_avg = 0;
                    return
                end
                dtMedian = median(diff(t), 'omitnan');
                if ~isfinite(dtMedian) || dtMedian <= 0
                    error('Invalid time vector for reverse-recovery calculation.');
                end
                if ismember(app.SelectedTopology, ["Boost","Buck","Flyback"])
                    fsw = app.fswEditField_Boost.Value;
                else
                    fsw = app.fswACEditField.Value;
                end
                VeventFallback = getBlockingVoltageEstimate(app);
                totalRecoveryEnergy_J = 0;
                nDevices = size(ID,2);
                for dev = 1:nDevices
                    Idev = ID(:,dev);
                    Vdev = VD(:,dev);
                    Ipk = max(abs(Idev));
                    if ~isfinite(Ipk) || Ipk < 1e-9
                        continue
                    end
                    Ithr = max(1e-6, 0.02 * Ipk);
                    rrMask = Idev < -Ithr;
                    dMask  = diff([false; rrMask; false]);
                    starts = find(dMask == 1);
                    stops  = find(dMask == -1) - 1;
                    measuredRecoveryFound = false;
                    for eventNumber = 1:numel(starts)
                        i1 = starts(eventNumber);
                        i2 = stops(eventNumber);
                        if i2 <= i1
                            continue
                        end
                        if (t(i2) - t(i1)) < 2 * dtMedian
                            continue
                        end
                        Irec = max(-Idev(i1:i2), 0);
                        Vrec = abs(Vdev(i1:i2));
                        Qrr = trapz(t(i1:i2), Irec);
                        Err = trapz(t(i1:i2), Vrec .* Irec);
                        if ~isfinite(Qrr) || ~isfinite(Err) || Err <= 0
                            continue
                        end
                        measuredRecoveryFound = true;
                        totalRecoveryEnergy_J = totalRecoveryEnergy_J + Err;
                        newRow = table( dev, string(label), "Measured waveform", t(i1), t(i2), Qrr, ...
                            Err, max(Irec), 'VariableNames', rrEvents.Properties.VariableNames);
                        rrEvents = [rrEvents; newRow]; 
                    end
                    if ~measuredRecoveryFound && isfield(diode,'ErrErec') && isfinite(diode.ErrErec) && diode.ErrErec > 0
                        IfwdSamples = max(Idev, 0);
                        IfwdSamples = IfwdSamples( isfinite(IfwdSamples) & IfwdSamples > 0);
                        if isempty(IfwdSamples)
                            continue
                        end
                        isBridge = ismember( app.SelectedTopology, ["Half-bridge inverter", "Full-bridge inverter", "Three-phase inverter"]);
                        if isBridge
                            error( ['No measurable reverse-recovery pulse was found for diode/device %d. A bridge datasheet fallback ' ...
                                'cannot assume one recovery event per device per switching period. Verify ID/VD signals and solver ' ...
                                'resolution in Simulink.'], dev);
                        end
                        if ~isfield(diode,'IF_ref_A') || ~isfinite(diode.IF_ref_A) || diode.IF_ref_A <= 0
                            error( 'Valid IF_ref_A is required for recovery scaling.');
                        end
                        if ~isfield(diode,'Vrev_ref_V') || ~isfinite(diode.Vrev_ref_V) || diode.Vrev_ref_V <= 0
                            error( 'Valid Vrev_ref_V is required for recovery scaling.');
                        end
                        if ~isfinite(VeventFallback) || VeventFallback <= 0
                            error( 'Valid reverse voltage is required for recovery scaling.');
                        end
                        Ievent = median(IfwdSamples, 'omitnan');
                        currentScale = Ievent / diode.IF_ref_A;
                        voltageScale = VeventFallback / diode.Vrev_ref_V;
                        ErecScaled_J = diode.ErrErec * currentScale * voltageScale;
                        Ewindow_J = fsw * ErecScaled_J * Tobs;
                        totalRecoveryEnergy_J = totalRecoveryEnergy_J + Ewindow_J;
                        newRow = table( dev, string(label), "Datasheet fallback", NaN, NaN, NaN, Ewindow_J, NaN, 'VariableNames', ...
                            rrEvents.Properties.VariableNames);
                        rrEvents = [rrEvents; newRow]; 
                    end
                end
                Prr_avg = totalRecoveryEnergy_J / Tobs;
            
            end
            function validateBoostDiodePolarity(~, t, ID, VD)
                t = t(:);
                ID = squeeze(ID);
                VD = squeeze(VD);
                ID = ID(:);
                VD = VD(:);
                n = min([numel(t), numel(ID), numel(VD)]);
                t = t(1:n);
                ID = ID(1:n);
                VD = VD(1:n);
                if n < 3 || any(~isfinite(t)) || any(diff(t) <= 0) || ...
                        any(~isfinite(ID)) || any(~isfinite(VD))
                    error('Invalid Boost diode voltage/current signals.');
                end
                Ipeak = max(abs(ID));
                Vpeak = max(abs(VD));

                if Ipeak < 1e-9
                    warning('No measurable Boost diode current was detected.');
                    return
                end
                Ithreshold = max(1e-6, 0.05*Ipeak);
                Vtolerance = max(1e-6, 1e-4*Vpeak);
                Qforward = trapz(t, max(ID,0));
                Qreverse = trapz(t, max(-ID,0));
                if Qreverse > 2*Qforward
                    error(['Boost diode current polarity is reversed. ' ...
                        'Positive ID must flow from anode to cathode.']);
                end
                forwardMask = ID > Ithreshold;
                if nnz(forwardMask) < 3
                    warning('Not enough forward-conduction samples to validate the Boost diode.');
                    return
                end

                Vforward = median(VD(forwardMask), 'omitnan');

                if Vforward < -Vtolerance
                    error(['Boost diode voltage polarity is reversed. ' ...
                        'Connect voltage sensor + to the anode and - to the cathode.']);
                end
                blockingMask = abs(ID) < 0.02*Ipeak & abs(VD) > 0.20*Vpeak;
                if nnz(blockingMask) >= 3
                    Vblocking = median(VD(blockingMask), 'omitnan');
                    if Vblocking > Vtolerance
                        error(['Boost diode voltage polarity is reversed. During reverse blocking, VD = Vanode - Vcathode ' ...
                            'must be negative.']);
                    end
                end
            end
            function idxRef = getSteadyStatePWMWindow(app, t, PWM, nCycles)
                if nargin < 4 || isempty(nCycles)
                    nCycles = 10;
                end
                t   = t(:);
                PWM = PWM(:);
    
                n = min(numel(t), numel(PWM));
                t   = t(1:n);
                PWM = PWM(1:n);
                if n < 3
                    idxRef = 1:n;
                    return
                end
    
                gate = PWM > 0.5;
    
                risingEdges = find(diff(gate) == 1) + 1;
                if numel(risingEdges) >= nCycles + 1
                    i0 = max(1, risingEdges(end - nCycles) - 1);
                    i1 = max(i0 + 1, risingEdges(end) - 1);
                    idxRef = i0:i1;
                else
                    idx0 = max(1, floor(0.8 * n));
                    idxRef = idx0:n;
                end
            end
            function validateOperatingPointResults(app)

                if ~isfield(app.SimulationData, 'Results')
                    error('No simulation results are available for validation.');
                end

                R = app.SimulationData.Results;
                voltageTolerance = 20;
                powerTolerance = 20;

                switch app.SelectedTopology

                    case {"Boost","Buck","Flyback"}
                        required = {'idxRef','Vout_sim','t','Pout_avg'};
                        if ~all(isfield(R, required))
                            error('DC/DC operating-point validation signals are missing.');
                        end
                        idx = R.idxRef;
                        tRef = R.t(idx);
                        Vmeasured = averageOverWindow(app, tRef, R.Vout_sim(idx));
                        Pmeasured = R.Pout_avg;
                        Vcommand = app.VoutEditField_Boost.Value;
                        Pcommand = app.PoutEditField_Boost.Value;
                        voltageDescription = 'average output voltage';
                        validationMethod = "PWM-cycle average voltage and output power";

                    case {"Half-bridge inverter","Full-bridge inverter"}
                        required = {'t','Vout_sim','Iout_sim'};
                        missing = required(~isfield(R, required));

                        if ~isempty(missing)
                            error('Missing AC validation signal: %s.', missing{1});
                        end

                        idx = getSteadyStateFundamentalWindow(app, R.t, 3);
                        tRef = R.t(idx);
                        Vout = R.Vout_sim(idx);
                        Iout = R.Iout_sim(idx);
                        fout = app.foutEditField.Value;

                        Vmeasured = getFundamentalRMS(app, tRef, Vout, fout);
                        Pmeasured = averageOverWindow(app, tRef, Vout .* Iout);
                        Vcommand = app.VoutRMSEditField.Value;
                        Pcommand = app.PoutACEditField.Value;
                        voltageDescription = 'fundamental output RMS voltage';
                        validationMethod = "Weighted sinusoidal projection at fout";
                    case "Three-phase inverter"
                        required = {'t','Va_sim','Vb_sim','Vc_sim','Ia_sim','Ib_sim','Ic_sim'};
                        missing = required(~isfield(R, required));

                        if ~isempty(missing)
                            error('Missing three-phase validation signal: %s.', missing{1});
                        end
                        idx = getSteadyStateFundamentalWindow(app, R.t, 3);
                        tRef = R.t(idx);
                        Va = R.Va_sim(idx);
                        Vb = R.Vb_sim(idx);
                        Vc = R.Vc_sim(idx);
                        Ia = R.Ia_sim(idx);
                        Ib = R.Ib_sim(idx);
                        Ic = R.Ic_sim(idx);
                        fout = app.foutEditField.Value;
                        Vmeasured = mean([getFundamentalRMS(app, tRef, Va - Vb, fout), getFundamentalRMS(app, tRef, Vb - Vc, fout), getFundamentalRMS(app, tRef, Vc - Va, fout)]);
                        Pmeasured = averageOverWindow(app, tRef, Va .* Ia + Vb .* Ib + Vc .* Ic);
                        Vcommand = app.VLLRMSEditField.Value;
                        Pcommand = app.PoutACEditField.Value;
                        voltageDescription = 'fundamental line-to-line RMS voltage';
                        validationMethod = "Weighted line-to-line sinusoidal projection at fout";
                    otherwise
                        error('Unsupported topology for operating-point validation.');
                end

                voltageError = 100 * abs(Vmeasured - Vcommand) / max(abs(Vcommand), eps);
                powerError = 100 * abs(Pmeasured - Pcommand) / max(abs(Pcommand), eps);
                positivePowerFlow = isfinite(Pmeasured) && Pmeasured > 0;
                isValid = isfinite(Vmeasured) && voltageError <= voltageTolerance && powerError <= powerTolerance && positivePowerFlow;

                message = sprintf('Measured %s = %.6g, commanded = %.6g, error = %.2f%%. Measured output power = %.6g W, commanded = %.6g W, error = %.2f%%. Positive output power flow = %d.', voltageDescription, Vmeasured, Vcommand, voltageError, Pmeasured, Pcommand, powerError, positivePowerFlow);

                app.SimulationData.Results.Validation = struct('IsValid', isValid, 'Method', validationMethod, 'VoltageMeasured', Vmeasured, 'VoltageCommanded', Vcommand, 'VoltageError_percent', voltageError, 'PowerMeasured_W', Pmeasured, 'PowerCommanded_W', Pcommand, 'PowerError_percent', powerError, 'PositivePowerFlow', positivePowerFlow, 'VoltageTolerance_percent', voltageTolerance, 'PowerTolerance_percent', powerTolerance, 'Message', message);

                if ~isValid
                    error('The simulation did not reach the requested operating point. %s', message);
                end
            end
            function idxRef = getSteadyStateFundamentalWindow(app, t, nCycles)
                if nargin < 3 || isempty(nCycles)
                    nCycles = 3;
                end
                t = t(:);

                if numel(t) < 2
                    error('Not enough samples for fundamental-cycle averaging.');
                end
                if any(~isfinite(t)) || any(diff(t) <= 0)
                    error( ['The simulation time vector must be finite and strictly increasing.']);
                end
                fout = app.foutEditField.Value;
                if ~isfinite(fout) || fout <= 0
                    error( 'Output fundamental frequency must be positive.');
                end
                observationTime = nCycles / fout;
                availableTime   = t(end) - t(1);
                if availableTime < observationTime
                    error( ['The AC simulation contains only %.6g s, but %.6g s is required for %d complete fundamental cycles.'], availableTime, observationTime, nCycles);
                end
                exactStartTime = t(end) - observationTime;
                idxRef = find(t >= exactStartTime);
                if numel(idxRef) < 3
                    error( ['Not enough samples exist in the selected AC steady-state window.']);
                end
            end
            function y = lossColumn(~, x)
                x = squeeze(x);
                if isvector(x)
                    y = x(:);
                else
                    y = sum(x, 2);
                end
            end
            function Xfund_rms = getFundamentalRMS(app, t, x, fundamentalFrequency_Hz)
                t = t(:);
                x = squeeze(x);
                if isvector(x)
                    x = x(:);
                end
                if size(x,2) ~= 1
                    error( ['getFundamentalRMS expects one voltage signal at a time.']);
                end
                n = min(numel(t), numel(x));
                t = t(1:n);
                x = x(1:n);
                if n < 3
                    error( 'Not enough samples for fundamental RMS extraction.');
                end
                if any(~isfinite(t)) || any(~isfinite(x))
                    error( ['Fundamental RMS signals contain NaN or Inf values.']);
                end
                dt = diff(t);
                if any(dt <= 0)
                    error( 'Time samples must be strictly increasing.');
                end
                if ~isfinite(fundamentalFrequency_Hz) || fundamentalFrequency_Hz <= 0
                    error('Fundamental frequency must be positive.');
                end
                weights = zeros(n,1);
                weights(1)   = dt(1) / 2;
                weights(end) = dt(end) / 2;
                if n > 2
                    weights(2:end-1) = (dt(1:end-1) + dt(2:end)) / 2;
                end
                tau = t - t(1);
                omega = 2*pi*fundamentalFrequency_Hz;

                basis = [  ones(n,1), cos(omega*tau), sin(omega*tau)];
                sqrtWeights = sqrt(weights);
                weightedBasis  = basis .* sqrtWeights;
                weightedSignal = x .* sqrtWeights;
                coefficients = weightedBasis \ weightedSignal;
                cosineAmplitude = coefficients(2);
                sineAmplitude   = coefficients(3);
                peakAmplitude = hypot( cosineAmplitude, sineAmplitude);
                Xfund_rms = peakAmplitude / sqrt(2);
            end
            function avg = averageOverWindow(app, t, x)
    
                t = t(:);
                y = lossColumn(app, x);
                n = min(numel(t), numel(y));
                t = t(1:n);
                y = y(1:n);
                if n < 2 || t(end) <= t(1)
                    avg = mean(y);
                else
                    avg = trapz(t, y) / (t(end) - t(1));
                end
            end    
            function setIGBTFieldsVisible(app, mode)
                sharedAdvancedNames = {'IcVceSatLabelIGBT','IcVceSatEditFieldIGBT', ...
                    'VgeVceSatLabelIGBT','VgeVceSatEditFieldIGBT', ...
                    'VgeThLabelIGBT','VgeThEditFieldIGBT', 'IcesLabelIGBT','IcesEditFieldIGBT', ...
                    'VcesIcesLabelIGBT','VcesIcesEditFieldIGBT', 'TmeasIGBTLabel','TmeasIGBTEditField', ...
                    'RdsOn2TLabelMOSFET','RdsOn2TEditFieldMOSFET', 'RdsOnLabel2TMOSFET','RdsOnEditField2TMOSFET'};
                for k = 1:numel(sharedAdvancedNames)
                    if isprop(app, sharedAdvancedNames{k})
                        app.(sharedAdvancedNames{k}).Visible = 'off';
                    end
                end
                app.IGBTBlockParametersPanel.Visible = 'off';
        
                if strcmp(mode, 'off')
                    return
                end
                if strcmp(mode, 'mosfet')
                    if ~app.AdvancedParametersCheckBox.Value
                        return
                    end
                    app.IGBTBlockParametersPanel.Visible = 'on';
                    for k = 1:numel(sharedAdvancedNames)
                        if isprop(app, sharedAdvancedNames{k})
                            app.(sharedAdvancedNames{k}).Visible = 'on';
                        end
                    end
                    app.IcVceSatLabelIGBT.Text = 'Drain current for Rds_on, Ids_RdsOn [A]';
                    app.VgeVceSatLabelIGBT.Text = 'Gate voltage for Rds_on, Vgs_RdsOn [V]';
                    app.VgeThLabelIGBT.Text = 'Threshold voltage Vth [V]';
                    app.IcesLabelIGBT.Text  = 'Input capacitance Ciss [pF]';
                    app.VcesIcesLabelIGBT.Text = 'Reverse transfer capacitance Crss [pF]';
                    app.TmeasIGBTLabel.Text = 'Output capacitance Coss [pF]';
                    app.RdsOn2TLabelMOSFET.Text = '2nd temperature for Rds_on [degC]';
                    app.RdsOnLabel2TMOSFET.Text  = 'Rds_on at 2nd temp [ohm]';
                    return
                end
                if strcmp(mode, 'igbt')
                    app.IGBTBlockParametersPanel.Visible = 'off';
                    return
                end
                error('Unsupported extra parameter panel mode.');
            end    
            function Psw_cond_inst = computeSwitchConductionLoss(app, Isw, Tj_C)
                sw = getSwitchParams(app);
                if nargin < 3 || isempty(Tj_C)
                    Tj_C = sw.Tref_cond_C;
                end
                if strcmp(sw.Type, 'Si IGBTs')
                    Ic = max(Isw, 0);
                    [VCE0_T, rCE_T] = getIGBTConductionParamsAtTemperature(app, Tj_C);
                    Psw_cond_inst = VCE0_T .* Ic + rCE_T .* Ic.^2;
                else
                    RdsOn_T = getRdsOnAtTemperature(app, Tj_C);
                    Psw_cond_inst = abs(Isw).^2 .* RdsOn_T;
                end
                if ~isvector(Psw_cond_inst)
                    Psw_cond_inst = sum(Psw_cond_inst, 2);
                end
            end
            function hideIGBTConductionFields(app)
                names = {'IGBTlabel01','IGBTeditfield01', 'IGBTlabel02','IGBTeditfield02', ...
                    'IGBTlabel1','IGBTeditfield1', 'IGBTlabel2','IGBTeditfield2', ...
                    'IGBTlabel3','IGBTeditfield3', 'IGBTlabel4','IGBTeditfield4'};
                for k = 1:numel(names)
                    if isprop(app, names{k})
                        app.(names{k}).Visible = 'off';
                    end
                end
                if isprop(app,'Panel_4')
                    app.Panel_4.Visible = 'off';
                end
            end
            function hideIGBTAdvancedFields(app)
                names = { 'Label1ADI','EditField1ADI', 'Label2ADI','EditField2ADI', ...
                    'Label3ADI','EditField3ADI', 'Label4ADI','EditField4ADI', ...
                    'Label5ADI','EditField5ADI'};
                for k = 1:numel(names)
                    if isprop(app, names{k})
                        app.(names{k}).Visible = 'off';
                    end
                end
                if isprop(app,'AdvancedParametersIGBT')
                    app.AdvancedParametersIGBT.Visible = 'off';
                end
                if isprop(app,'Labelinputcapacitancelabel')
                    app.Labelinputcapacitancelabel.Visible = 'off';
                end
                if isprop(app,'Editfieldinputcapacitance')
                    app.Editfieldinputcapacitance.Visible = 'off';
                end
                if isprop(app,'Labelreversecapacitancelabel')
                    app.Labelreversecapacitancelabel.Visible = 'off';
                end
                if isprop(app,'Editfieldreversecapacitance')
                    app.Editfieldreversecapacitance.Visible = 'off';
                end
                if isprop(app,'Labeloutputcapacitancelabel')
                    app.Labeloutputcapacitancelabel.Visible = 'off';
                end
                if isprop(app,'Editfieldoutputcapacitance')
                    app.Editfieldoutputcapacitance.Visible = 'off';
                end
            end
            function loadIGBTConductionDefaults(app)
    
                D = getDefaultValues(app, app.SelectedTopology, ...
                    app.SwitchTechnologyDropDown.Value, app.DiodeTypeDropDown.Value);
    
                app.IGBTeditfield01.Value = D.Switch.Eon;
                app.IGBTeditfield02.Value = D.Switch.Eoff;
                if isprop(app, 'eoneoffrefcurrent')
                    app.eoneoffrefcurrent.Value = D.Switch.Eref_I;
                end
    
                if isprop(app, 'eoneoffrefvoltage')
                    app.eoneoffrefvoltage.Value = D.Switch.Eref_V;
                end
                method = app.OptionConductionLossIGBT.Value;
                if strcmp(method, 'Direct: VCE0 and rCE')
                    app.IGBTeditfield1.Value = D.Switch.VCE0;
                    app.IGBTeditfield2.Value = D.Switch.rCE;
                    app.IGBTeditfield3.Value = 0;
                    app.IGBTeditfield4.Value = 0;
                else
                    app.IGBTeditfield1.Value = D.Switch.IC1_VCEsat;
                    app.IGBTeditfield2.Value = D.Switch.VCE1_sat;
                    app.IGBTeditfield3.Value = D.Switch.IC2_VCEsat;
                    app.IGBTeditfield4.Value = D.Switch.VCE2_sat;
                end
                app.EditField1ADI.Value = D.Switch.VgeVceSat;
                app.EditField2ADI.Value = D.Switch.VgeTh;
                app.EditField3ADI.Value = D.Switch.Ices;
                app.EditField4ADI.Value = D.Switch.VcesIces;
                app.EditField5ADI.Value = D.Switch.TmeasIGBT;
                default_Cies_pF = 26400;
                default_Cres_pF = 1000;
                default_Coes_pF = 2700;        
                if isfield(D.Switch, 'Cies_IGBT_pF')
                    default_Cies_pF = D.Switch.Cies_IGBT_pF;
                end
                if isfield(D.Switch, 'Cres_IGBT_pF')
                    default_Cres_pF = D.Switch.Cres_IGBT_pF;
                end
                if isfield(D.Switch, 'Coes_IGBT_pF')
                    default_Coes_pF = D.Switch.Coes_IGBT_pF;
                end
                if isprop(app, 'Editfieldinputcapacitance')
                    app.Editfieldinputcapacitance.Value = default_Cies_pF;
                end
                if isprop(app, 'Editfieldreversecapacitance')
                    app.Editfieldreversecapacitance.Value = default_Cres_pF;
                end
                if isprop(app, 'Editfieldoutputcapacitance')
                    app.Editfieldoutputcapacitance.Value = default_Coes_pF;
                end
            end
            function updateIGBTConductionUI(app)
                hideIGBTConductionFields(app);
                if ~strcmp(app.SwitchTechnologyDropDown.Value, 'Si IGBTs')
                    return
                end
                if isprop(app,'Panel_4')
                    app.Panel_4.Visible = 'on';
                end
                app.OptionConductionLossIGBT.Visible = 'on';
                app.ConductionlossmodelLabel.Visible = 'on';
                app.IGBTlabel01.Visible = 'on';
                app.IGBTeditfield01.Visible = 'on';
                app.IGBTlabel01.Text = 'Turn-on energy Eon [J]';
                app.IGBTlabel02.Visible = 'on';
                app.IGBTeditfield02.Visible = 'on';
                app.IGBTlabel02.Text = 'Turn-off energy Eoff [J]';
                method = app.OptionConductionLossIGBT.Value;
                if strcmp(method, 'Direct: VCE0 and rCE')
                    app.S.Visible = 'on';
                    app.IGBTeditfield1.Visible = 'on';
                    app.S.Text = 'Threshold voltage VCE0 at Tref [V]';
                    app.IGBTlabel2.Visible = 'on';
                    app.IGBTeditfield2.Visible = 'on';
                    app.IGBTlabel2.Text = 'Dynamic resistance rCE at Tref [ohm]';
                    app.IGBTlabel3.Visible = 'off';
                    app.IGBTeditfield3.Visible = 'off';
                    app.IGBTlabel4.Visible = 'off';
                    app.IGBTeditfield4.Visible = 'off';
                else
                    app.S.Visible = 'on';
                    app.IGBTeditfield1.Visible = 'on';
                    app.S.Text = 'IC1 from VCE(sat)-IC curve [A]';
                    app.IGBTlabel2.Visible = 'on';
                    app.IGBTeditfield2.Visible = 'on';
                    app.IGBTlabel2.Text = 'VCE1 from VCE(sat)-IC curve [V]';
                    app.IGBTlabel3.Visible = 'on';
                    app.IGBTeditfield3.Visible = 'on';
                    app.IGBTlabel3.Text = 'IC2 from VCE(sat)-IC curve [A]';
                    app.IGBTlabel4.Visible = 'on';
                    app.IGBTeditfield4.Visible = 'on';
                    app.IGBTlabel4.Text = 'VCE2 from VCE(sat)-IC curve [V]';
                end
            end

            function updateIGBTAdvancedUI(app)
                hideIGBTAdvancedFields(app);
                if ~strcmp(app.SwitchTechnologyDropDown.Value, 'Si IGBTs')
                    return
                end
                app.IGBTadvancedparameterCheckbox.Visible = 'on';
                if ~app.IGBTadvancedparameterCheckbox.Value
                    return
                end
                app.AdvancedParametersIGBT.Visible = 'on';
                app.IGBTBlockParametersPanel.Visible = 'on';
                app.RdsOn2TLabelMOSFET.Visible = 'off';
                app.RdsOn2TEditFieldMOSFET.Visible = 'off';
                app.RdsOn2TLabelMOSFET.Text = '2nd temperature for VCE(sat) [degC]';
                app.RdsOnLabel2TMOSFET.Visible = 'off';
                app.RdsOnEditField2TMOSFET.Visible = 'off';
                app.RdsOnLabel2TMOSFET.Text = 'VCE(sat) at 2nd temperature [V]';
                app.Label1ADI.Visible = 'on';
                app.EditField1ADI.Visible = 'on';
                app.Label1ADI.Text = 'Vge at VceSat [V]';
                app.Label2ADI.Visible = 'on';
                app.EditField2ADI.Visible = 'on';
                app.Label2ADI.Text = 'Vge(th) [V]';
                app.Label3ADI.Visible = 'on';
                app.EditField3ADI.Visible = 'on';
                app.Label3ADI.Text = 'Ices leakage current [A]';
                app.Label4ADI.Visible = 'on';
                app.EditField4ADI.Visible = 'on';
                app.Label4ADI.Text = 'Vces at Ices [V]';
                app.Label5ADI.Visible = 'on';
                app.EditField5ADI.Visible = 'on';
                app.Label5ADI.Text = 'Measurement temperature [degC]';
                if isprop(app,'Labelinputcapacitancelabel')
                    app.Labelinputcapacitancelabel.Visible = 'on';
                    app.Labelinputcapacitancelabel.Text = 'Input capacitance Cies [pF]';
                end
                if isprop(app,'Editfieldinputcapacitance')
                    app.Editfieldinputcapacitance.Visible = 'on';
                end
                if isprop(app,'Labelreversecapacitancelabel')
                    app.Labelreversecapacitancelabel.Visible = 'on';
                    app.Labelreversecapacitancelabel.Text = 'Reverse transfer capacitance Cres [pF]';
                end
                if isprop(app,'Editfieldreversecapacitance')
                    app.Editfieldreversecapacitance.Visible = 'on';
                end
                if isprop(app,'Labeloutputcapacitancelabel')
                    app.Labeloutputcapacitancelabel.Visible = 'on';
                    app.Labeloutputcapacitancelabel.Text = 'Output capacitance Coes [pF]';
                end
                if isprop(app,'Editfieldoutputcapacitance')
                    app.Editfieldoutputcapacitance.Visible = 'on';
                end
            end
            function simStopTime = calculateAutomaticDCDCStopTime(app)
                fsw  = app.fswEditField_Boost.Value;
                Vout = app.VoutEditField_Boost.Value;
                Pout = app.PoutEditField_Boost.Value;
                Cout = app.CoutEdit_Field.Value;
                values = [fsw, Vout, Pout, Cout];
                if any(~isfinite(values)) || any(values <= 0)
                    error( 'fsw, Vout, Pout and Cout must be finite and positive.');
                end
                Rload = Vout^2 / Pout;
                switchingTime = 100 / fsw;
                outputSettlingTime = 8 * Rload * Cout;
                topologySettlingTime = 0;
                switch app.SelectedTopology
                    case {"Boost","Buck"}
                        L = app.LEditField_Boost_2.Value;
                        if ~isfinite(L) || L <= 0
                            error('Inductance L must be finite and positive.');
                        end
                        topologySettlingTime = 8 * sqrt(L * Cout);
                    case "Flyback"
                        Lm = app.LmEditField.Value;
                        n  = app.TurnsRatioEditField.Value; % Np/Ns
                        if ~isfinite(Lm) || Lm <= 0
                            error('Flyback Lm must be finite and positive.');
                        end
                        if ~isfinite(n) || n <= 0
                            error('Flyback turns ratio must be positive.');
                        end
                        % Magnetizing inductance referred to the secondary.
                        LmSecondary = Lm / n^2;
                        topologySettlingTime = 8 * sqrt(LmSecondary * Cout);
                end
                simStopTime = max([1e-3, switchingTime, outputSettlingTime, topologySettlingTime]);
                if ~isfinite(simStopTime) || simStopTime <= 0
                    error('Automatically calculated Stop Time is invalid.');
                end
            end
            function exportDCDCParamsToWorkspace(app)
                topology = app.SelectedTopology;
                Vin = app.VinEditField_Boost.Value;
                Vout = app.VoutEditField_Boost.Value;
                Pout = app.PoutEditField_Boost.Value;
                fsw = app.fswEditField_Boost.Value;
                Cout = app.CoutEdit_Field.Value;
                Vg = app.GateDriveVoltageEditField.Value;
                Rg_on = app.TonRgEditField.Value;
                Rg_off = app.ToffRgEditField.Value;
            
                converterValues = [Vin,Vout,Pout,fsw,Cout];
                if any(~isfinite(converterValues)) || any(converterValues <= 0)
                    error('DC/DC parameters must be finite and strictly positive.');
                end
                if ~isfinite(Vg) || Vg <= 0
                    error('Gate-drive voltage must be finite and strictly positive.');
                end
                if any(~isfinite([Rg_on,Rg_off])) || any([Rg_on,Rg_off] <= 0)
                    error('Gate resistances Rg_on and Rg_off must be finite and non-negative.');
                end
            
                sw = getSwitchParams(app);
                exportIGBTParamsToBase(app, sw);
                isIGBT = strcmp(sw.Type, 'Si IGBTs');
            
                if isIGBT
                    if ~isfinite(sw.VceSat) || sw.VceSat <= 0
                        error('Selected IGBT VceSat must be finite and strictly positive.');
                    end
                    RdsOn = 1e-3;
                else
                    RdsOn = sw.RdsOn;
                    if ~isfinite(RdsOn) || RdsOn <= 0
                        error('Selected switch RdsOn must be finite and strictly positive.');
                    end
                end
                if strcmp(sw.Type, 'GaN MOSFETs') && Vg > 7
                    error('GaN gate-drive voltage is too high. Use a datasheet-based value, typically 5 V to 6 V.');
                end
                diode = exportDiodeParamsToBase(app);
                Rload = Vout^2 / Pout;
                Tsw = 1 / fsw;
                switch sw.Type
                    case 'GaN MOSFETs'
                        Ts_local = min(Tsw/10000, 2e-9);
                    case 'SiC MOSFETs'
                        Ts_local = min(Tsw/5000, 10e-9);
                    case 'Si MOSFETs'
                        Ts_local = min(Tsw/5000, 50e-9);
                    case 'Si IGBTs'
                        Ts_local = min(Tsw/1000, 100e-9);
                    otherwise
                        Ts_local = Tsw/2000;
                end
                topologyVars = struct();
                switch topology
                    case "Boost"
                        L = app.LEditField_Boost_2.Value;
                        if ~isfinite(L) || L <= 0
                            error('For Boost, inductance L must be finite and positive.');
                        end
                        if Vout <= Vin
                            error('For Boost, Vout must be greater than Vin.');
                        end
                        Duty = 1 - Vin/Vout;
                        topologyVars.L = L;
                    case "Buck"
                        L = app.LEditField_Boost_2.Value;
                        if ~isfinite(L) || L <= 0
                            error('For Buck, inductance L must be finite and positive.');
                        end
                        if Vout >= Vin
                            error('For Buck, Vout must be lower than Vin.');
                        end
                        Duty = Vout/Vin;
                        topologyVars.L = L;
                    case "Flyback"
                        Lm = app.LmEditField.Value;
                        TurnsRatio = app.TurnsRatioEditField.Value;
                        Llk = app.LlkEditField.Value;
                        CoutESR = app.CoutESREditField.Value;
                        if ~isfinite(Lm) || Lm <= 0
                            error('For Flyback, magnetizing inductance Lm must be finite and positive.');
                        end
                        if ~isfinite(TurnsRatio) || TurnsRatio <= 0
                            error('For Flyback, turns ratio Np/Ns must be finite and positive.');
                        end
                        if ~isfinite(Llk) || Llk < 0
                            error('For Flyback, leakage inductance Llk must be finite and non-negative.');
                        end
                        if ~isfinite(CoutESR) || CoutESR < 0
                            error('For Flyback, output capacitor ESR must be finite and non-negative.');
                        end
                        Duty0 = TurnsRatio*(Vout+diode.Vf) / (Vin+TurnsRatio*(Vout+diode.Vf));
                        Duty0 = min(max(Duty0, 0.05), 0.75);
                        Gvd0 = Vin / (TurnsRatio*(1-Duty0)^2);
                        wc_ctrl = min(0.5/(Rload*Cout), 2*pi*fsw/200);
                        KpDuty = 1/max(Gvd0, eps);
                        KiDuty = KpDuty*wc_ctrl/5;
                        Duty = Duty0;
                        topologyVars = struct('Duty0', Duty0, 'KpDuty', KpDuty, 'KiDuty', KiDuty, ...
                            'Dmin', 0.05, 'Dmax', 0.75, 'Lm', Lm, 'Llk', Llk, 'TurnsRatio', TurnsRatio, 'CoutESR', CoutESR);
                    otherwise
                        error('Only Boost, Buck, and Flyback are implemented.');
                end   
                Duty = min(max(Duty, 0.01), 0.99);
                Iout0 = Pout/Vout;
                if topology == "Buck"
                    IL0 = Pout/Vout;
                else
                    IL0 = Pout/Vin;
                end
                if isIGBT
                    Ids_RdsOn = sw.IcVceSat;
                    Vgs_RdsOn = sw.VgeVceSat;
                    Vth_sw = sw.VgeTh;
                    Ciss_sw = 1e-12;
                    Crss_sw = 1e-12;
                    Coss_sw = 1e-12;
                elseif ismember(sw.Type, {'Si MOSFETs','SiC MOSFETs','GaN MOSFETs'})
                    Ids_RdsOn = sw.Ids_RdsOn;
                    Vgs_RdsOn = sw.Vgs_RdsOn;
                    Vth_sw = sw.Vth_sw;
                    Ciss_sw = max(sw.Ciss, 1e-15);
                    Crss_sw = max(sw.Crss, 1e-15);
                    Coss_sw = max(sw.Coss, 1e-15);
                else
                    error('Unsupported switch technology.');
                end 
                vars = struct('Vin', Vin, 'Vout_ref', Vout, 'Pout', Pout, 'fsw', fsw, ...
                    'Cout', Cout, 'C', Cout, 'Rload', Rload, 'Duty', Duty, ...
                    'Tsw', Tsw, 'DutyPercent', 100*Duty, 'Ts_local', Ts_local, ...
                    'IL0', IL0, 'Vout0', Vout, 'Iout0', Iout0, ...
                    'Ids_RdsOn', Ids_RdsOn, 'Vgs_RdsOn', Vgs_RdsOn, ...
                    'Vth_sw', Vth_sw, 'Vth', Vth_sw, 'Vg', Vg, ...
                    'Rg_on', Rg_on, 'Rg_off', Rg_off, 'RdsOn', RdsOn, ...
                    'Ciss_sw', Ciss_sw, 'Crss_sw', Crss_sw, 'Coss_sw', Coss_sw, ...
                    'Ciss_sw_pF', Ciss_sw*1e12, 'Crss_sw_pF', Crss_sw*1e12, ...
                    'Coss_sw_pF', Coss_sw*1e12, 'SwitchTechnology', sw.Type, ...
                    'Qg_sw', sw.Qg, 'Qoss_sw', sw.Qoss, 'Eon_sw', sw.Eon, ...
                    'Eoff_sw', sw.Eoff, 'Vf', diode.Vf, 'RonD', diode.Ron);
                if isIGBT
                    vars.Vgeth = sw.VgeTh;
                end
                assignVariables(app, 'base', topologyVars);
                assignVariables(app, 'base', vars);
                exportThermalParamsToBase(app);
            end
            function modelName = getImplementedModelName(app)
                tech = app.SwitchTechnologyDropDown.Value;
            
                switch app.SelectedTopology
                    case "Boost"
                        switch tech
                            case 'Si IGBTs', modelName = 'boost_IGBT_model';
                            case 'GaN MOSFETs', modelName = 'boost_mosfetGAN_model';
                            case 'SiC MOSFETs', modelName = 'boost_mosfetSiC_model';
                            otherwise, modelName = 'boost_mosfet_model';
                        end
                    case "Buck"
                        switch tech
                            case 'Si IGBTs', modelName = 'buck_igbt_model';
                            case 'GaN MOSFETs', modelName = 'buck_mosfetGaN_model';
                            case 'SiC MOSFETs', modelName = 'buck_mosfetSiC_model';
                            otherwise, modelName = 'buck_mosfet_model';
                        end
            
                    case "Flyback"
                        switch tech
                            case 'Si IGBTs', modelName = 'flyback_IGBT_model';
                            case 'SiC MOSFETs', modelName = 'flyback_mosfetSiC_model';
                            otherwise, modelName = 'flyback_mosfet_model2';
                        end
            
                    case "Half-bridge inverter"
                        switch tech
                            case 'Si IGBTs', modelName = 'halfbridge1ph_igbt_model';
                            case 'Si MOSFETs', modelName = 'halfbridge1ph_mosfet_model';
                            case 'SiC MOSFETs', modelName = 'flyback_mosfetSiC_model';
                            otherwise, modelName = 'halfbridge1ph_mosfet_model';
                        end                   
                    case "Full-bridge inverter"
                        switch tech
                            case 'Si IGBTs'
                                modelName = 'fullbridge1ph_igbt_model';
                    
                            case 'Si MOSFETs'
                                modelName = 'fullbridge1ph_mosfet_model2';
                    
                            case 'SiC MOSFETs'
                                modelName = 'fullbridge1ph_mosfet_model2';
                    
                            otherwise
                                modelName = 'fullbridge1ph_mosfet_model2';
                        end
                    case "Three-phase inverter"
                        modelName = 'threephaseinverter_mosfet_model';
                    otherwise
                        error('No Simulink model is assigned for the selected topology.');
                end
            end
            function plotMissionLossLine(app)

                if ~isfield(app.SimulationData, 'MissionThermalTrace')
                    return
                end
                TT = app.SimulationData.MissionThermalTrace;
                valid = isfinite(TT.time_min) & isfinite(TT.Ptotal_W);
                if ~any(valid)
                    return
                end
                t = TT.time_min(valid);
                names = TT.Properties.VariableNames;
                if ismember('Pdiode_body_fwd_W', names)
                    PbodyForward = TT.Pdiode_body_fwd_W(valid);
                else
                    PbodyForward = zeros(size(t));
                end
                if ismember('Prr_body_W', names)
                    PbodyRecovery = TT.Prr_body_W(valid);
                else
                    PbodyRecovery = zeros(size(t));
                end
                Y = [TT.Ptotal_W(valid), TT.Pcond_scaled_W(valid), TT.Psw_W(valid), PbodyForward, PbodyRecovery];
                cla(app.LossesAxes);
                lines = stairs(app.LossesAxes, t, Y);
                set(lines(1), 'LineWidth', 1.8);
                set(lines(2:end), 'LineStyle', '--', 'LineWidth', 1.2);
                xlabel(app.LossesAxes, 'Mission time [min]');
                ylabel(app.LossesAxes, 'Loss power [W]');
                title(app.LossesAxes, 'Mission-Profile Included Losses');
                grid(app.LossesAxes, 'on');
                switch app.SwitchTechnologyDropDown.Value
                    case 'Si IGBTs'
                        diodeLabel = 'FWD forward loss';
                    case 'GaN MOSFETs'
                        diodeLabel = 'Reverse-path conduction';
                    otherwise
                        diodeLabel = 'Intrinsic diode forward loss';
                end
                legend(app.LossesAxes, {'Total included losses','Conduction','Switching',diodeLabel,'Intrinsic reverse recovery'}, 'Location', 'best');
            end
            function sig = getSignalFromSimOut(app, simOut, candidateNames)
                sig = [];
                for k = 1:numel(candidateNames)
                    name = candidateNames{k};
                    try
                        tmp = simOut.get(name);
                        if ~isempty(tmp)
                            sig = tmp;
                            return
                        end
                    catch
                    end
                    try
                        if isprop(simOut, name)
                            tmp = simOut.(name);
                            if ~isempty(tmp)
                                sig = tmp;
                                return
                            end
                        end
                    catch
                    end
                end
                error('Could not find signal in simOut: %s', strjoin(candidateNames, ' / '));
            end
            function resetSummaryFields(app)
                fields = {app.ConductionLossesEditField, app.DiodeLossesEditField, ...
                    app.SwitchingLossesEditField, app.TotalLossesEditField, app.EfficiencyEditField};
                for k = 1:numel(fields)
                    fields{k}.Value = 0;
                end
            end
            function [t, y] = unpackSimSignal(app, sig, signalName)
                if isa(sig, 'timeseries')
                    t = sig.Time;
                    y = sig.Data;
                elseif isstruct(sig) && isfield(sig,'time') && isfield(sig,'signals')
                    t = sig.time;
                    y = sig.signals.values;
                elseif isstruct(sig) && isfield(sig,'Time') && isfield(sig,'Data')
                    t = sig.Time;
                    y = sig.Data;
                else
                    error('Unsupported format for signal "%s".', signalName);
                end
                t = squeeze(t);
                y = squeeze(y);
            end
            function updateSummaryTab(app)
                if ~isfield(app.SimulationData, 'Results') || isempty(app.SimulationData.Results)
                    app.SimulationstatusLabel.Text = 'Simulation status: no results available';
                    return
                end
                R = app.SimulationData.Results;
                Pcond = 0;
                Psw   = 0;
                if isfield(R, 'Psw_cond_avg') && isfinite(R.Psw_cond_avg)
                    Pcond = R.Psw_cond_avg;
                end
                if isfield(R, 'Psw_switch_avg') && isfinite(R.Psw_switch_avg)
                    Psw = R.Psw_switch_avg;
                end
                PdiodeIncluded = 0;

                if isfield(R,'Pdiode_avg') && isfinite(R.Pdiode_avg)
                    PdiodeIncluded = R.Pdiode_avg;
                end
                TotalLoss = Pcond + Psw + PdiodeIncluded;
                if isfield(R,'InputPowerMeasurementValid') && R.InputPowerMeasurementValid && isfield(R,'EfficiencyMeasured') && isfinite(R.EfficiencyMeasured)
                    Efficiency = R.EfficiencyMeasured;
                    app.EfficiencyLabel.Text = 'Measured DC efficiency (%)';
                elseif isfield(R,'EfficiencySemiconductor') && isfinite(R.EfficiencySemiconductor)
                    Efficiency = R.EfficiencySemiconductor;
                    app.EfficiencyLabel.Text = 'Semiconductor efficiency (%)';
                elseif isfield(R,'Pout_avg') && isfinite(R.Pout_avg) && R.Pout_avg > 0
                    Efficiency = 100 * R.Pout_avg / (R.Pout_avg + TotalLoss);
                    app.EfficiencyLabel.Text = 'Estimated efficiency (%)';
                else
                    Efficiency = 0;
                    app.EfficiencyLabel.Text = 'Efficiency (%)';
                end
                app.ConductionLossesEditField.Value = Pcond;
                app.SwitchingLossesEditField.Value  = Psw;
                app.DiodeLossesEditField.Value = PdiodeIncluded;
                app.TotalLossesEditField.Value = TotalLoss;
                app.EfficiencyEditField.Value = Efficiency;
            
                tech = app.SwitchTechnologyDropDown.Value;
            
                app.ConductionlossesWLabel.Text = 'Conduction losses (W)';
                app.SwitchinglossesWLabel.Text = 'Switching losses (W)';
                switch app.SelectedTopology

                    case "Boost"
                        app.DiodelossesWLabel.Text = 'Included diode losses (W)';
                    
                    case {"Buck","Half-bridge inverter"}
                        app.DiodelossesWLabel.Text = 'Intrinsic diode losses (W)';

                    case "Flyback"
                        app.DiodelossesWLabel.Text = 'Diode losses (W)';

                    otherwise
                        app.DiodelossesWLabel.Text = 'Reverse-conduction path losses (W)';
                end
                app.SelectedtopologyLabel.Text = ['Selected topology: ' char(app.SelectedTopology)];
                app.SelectedswitchtopologyLabel.Text = ['Selected switch technology:' char(app.SwitchTechnologyDropDown.Value)];
                app.SelecteddiodetypeLabel.Text = ['Selected diode type: ' char(app.DiodeTypeDropDown.Value)];
                if isfield(R, 'Validation') && isfield(R.Validation, 'IsValid') && ~R.Validation.IsValid
                    app.SimulationstatusLabel.Text = ['Simulation status: completed with validation warning. ' ...
                         R.Validation.Message];
                else
                    app.SimulationstatusLabel.Text = 'Simulation status: completed successfully';
                end            
                app.SimulationData.Results.Pdiode_included_avg = PdiodeIncluded;
                app.SimulationData.Results.Ptotal_avg = TotalLoss;
                app.SimulationData.Results.Efficiency = Efficiency;
            end
            function colNames = getMissionProfileColumns(app)
                switch app.SelectedTopology
                    case {"Boost","Buck","Flyback"}
                        colNames = {'Segment','Duration_s','Vin_V','Vout_V','Pout_W','T_amb_C'};
                    case {"Half-bridge inverter","Full-bridge inverter"}
                        colNames = {'Segment','Duration_s','Vdc_V','VoutRMS_V','fout_Hz','Pout_W','PF','T_amb_C'};
                    case "Three-phase inverter"
                        colNames = {'Segment','Duration_s','Vdc_V','VLLRMS_V','fout_Hz','Pout_W','PF','T_amb_C'};
                    otherwise
                        colNames = {'Segment','Duration_s'};
                end
            end
            function T = getMissionProfileTemplate(app)
                Segment = ["Low_Load";"Nominal_Load";"High_Load"];
                Duration_s = [60;120;90];
                T_amb_C = [25;30;35];
                switch app.SelectedTopology
                    case "Boost"
                        Vin_V = [200;200;180];
                        Vout_V = [400;400;400];
                        Pout_W = [500;1000;1500];
                        T = table(Segment, Duration_s, Vin_V, Vout_V, Pout_W, T_amb_C);
                    case "Buck"
                        Vin_V = [400;400;420];
                        Vout_V = [200;200;200];
                        Pout_W = [500;1000;1500];
                        T = table(Segment, Duration_s, Vin_V, Vout_V, Pout_W, T_amb_C);
                    case "Flyback"
                        Vin_V = [48;48;42];
                        Vout_V = [12;12;12];
                        Pout_W = [20;40;60];
                        T = table(Segment, Duration_s, Vin_V, Vout_V, Pout_W, T_amb_C);
                    case {"Half-bridge inverter","Full-bridge inverter"}
                        Vdc_V = [400;400;400];
                        fout_Hz = [50;50;50];
                        Pout_W = [500;1000;1500];
                        PF = [0.95;0.95;0.90];
                        if app.SelectedTopology == "Half-bridge inverter"
                            VoutRMS_V = [115;115;115];
                        else
                            VoutRMS_V = [230;230;230];
                        end
                        T = table(Segment, Duration_s, Vdc_V, VoutRMS_V, fout_Hz, Pout_W, PF, T_amb_C);
                    case "Three-phase inverter"
                        Vdc_V = [700;700;700];
                        VLLRMS_V = [400;400;400];
                        fout_Hz = [50;50;50];
                        Pout_W = [1000;3000;5000];
                        PF = [0.95;0.90;0.85];
                        T = table(Segment, Duration_s, Vdc_V, VLLRMS_V, fout_Hz, Pout_W, PF, T_amb_C);
                    otherwise
                        Segment = "Example";
                        Duration_s = 60;
                        T = table(Segment, Duration_s);
                end
            end
            function updateMissionProfileTableForTopology(app)
                T = app.getMissionProfileTemplate();
                app.MissionProfileTable.Data = T;
                app.MissionProfileTable.ColumnName = T.Properties.VariableNames;
                app.MissionProfileTable.RowName = {};
                if strlength(app.SelectedTopology) == 0
                    app.MissionProfileStatusLabel.Text = 'Status: no topology selected';
                else
                    app.MissionProfileStatusLabel.Text =['Status: template ready for ' char(app.SelectedTopology)];
                end
            end
            function Pdiode_inst = computeDiodeLossFromCurrent(app, ID)
                diode = getDiodeParams(app);
                Vf   = diode.Vf;
                RonD = diode.Ron;
                Ifwd = max(ID, 0);
                Pdiode_inst = Vf .* Ifwd + RonD .* Ifwd.^2;
                if ~isvector(Pdiode_inst)
                    Pdiode_inst = sum(Pdiode_inst, 2);
                end
            end
            function Vsw = getBlockingVoltageEstimate(app)
                switch app.SelectedTopology
                    case "Boost"
                        Vsw = app.VoutEditField_Boost.Value;
                    case "Buck"
                        Vsw = app.VinEditField_Boost.Value;
                    case "Flyback"
                        Vin  = app.VinEditField_Boost.Value;
                        Vout = app.VoutEditField_Boost.Value;
                        n    = app.TurnsRatioEditField.Value; % Np/Ns
                        Vsw = Vin + n * Vout;
                    case {"Half-bridge inverter","Full-bridge inverter","Three-phase inverter"}
                        Vsw = app.VdcEditField.Value;
                    otherwise
                        error('Unsupported topology for blocking-voltage estimate.');
                end
            end
            function Vg = getGateDriveVoltageValue(app)
                if ismember(app.SelectedTopology, ["Boost","Buck","Flyback"])
                    Vg = app.GateDriveVoltageEditField.Value;
                else
                    Vg = app.VgsDriveACEditField.Value;
                end
            end
            function Psw_switch_avg = computeSwitchingLossAverage(app)
                sw = getSwitchParams(app);

                if ismember(app.SelectedTopology, ["Boost","Buck","Flyback"])
                    fsw = app.fswEditField_Boost.Value;
                elseif ismember(app.SelectedTopology, ["Half-bridge inverter","Full-bridge inverter","Three-phase inverter"])
                    fsw = app.fswACEditField.Value;
                else
                    error('Unsupported topology for switching-loss calculation.');
                end
                nSw = getActiveSwitchCount(app);
                switch sw.Type
                    case {'SiC MOSFETs','Si IGBTs'}
                        [Psw_switch_avg, ~] = computeDatasheetSwitchingLossAverage(app);
                    case {'Si MOSFETs','GaN MOSFETs'}
                        Vsw = getBlockingVoltageEstimate(app);
                        Vg = getGateDriveVoltageValue(app);
                        Pgate = nSw * fsw * sw.Qg * Vg;
                        Pqoss = 0.5 * nSw * fsw * sw.Qoss * Vsw;
                        Psw_switch_avg = Pgate + Pqoss;
                    otherwise
                        error('Unsupported switch technology.');
                end
            end
            function tf = validateSwitchInputs(app)
                sw = getSwitchParams(app);
                switch sw.Type
                    case 'Si MOSFETs'
                        vals = [sw.RdsOn, sw.Qg, sw.Coss, sw.Ids_RdsOn, ...
                            sw.Vgs_RdsOn, sw.Vth_sw, sw.Ciss, sw.Crss];
                        tf = all(isfinite(vals)) && sw.RdsOn > 0 && sw.Qg >= 0 && ...
                            sw.Coss > 0 && sw.Ids_RdsOn > 0 && sw.Vgs_RdsOn > 0 && ...
                            sw.Vth_sw > 0 && sw.Ciss > 0 && sw.Crss >= 0;
                    case 'SiC MOSFETs'
                        vals = [sw.RdsOn, sw.Eon, sw.Eoff];
                        tf = all(isfinite(vals)) && sw.RdsOn > 0 && sw.Eon >= 0 && sw.Eoff >= 0;
                    case 'GaN MOSFETs'
                        vals = [sw.RdsOn, sw.Qg, sw.Qoss];
                        tf = all(isfinite(vals)) && sw.RdsOn > 0 && sw.Qg >= 0 && sw.Qoss >= 0;
                    case 'Si IGBTs'
                        vals = [sw.VceSat, sw.VCE0, sw.rCE, sw.Eon, sw.Eoff, ...
                            sw.VgeVceSat, sw.VgeTh, sw.Ices, sw.VcesIces, sw.TmeasIGBT];
                        tf = all(isfinite(vals)) && sw.VceSat > 0 && sw.VCE0 >= 0 && ...
                            sw.rCE >= 0 && sw.Eon >= 0 && sw.Eoff >= 0 && ...
                            sw.VgeVceSat > 0 && sw.VgeTh > 0 && sw.Ices >= 0 && sw.VcesIces > 0;
                        if tf && strcmp(sw.IGBTCondMethod, 'From VCE(sat)-IC curve points')
                            curve = [sw.IC1_VCEsat, sw.VCE1_sat, sw.IC2_VCEsat, sw.VCE2_sat];
                            tf = all(isfinite(curve)) && all(curve > 0) && ...
                                abs(sw.IC2_VCEsat-sw.IC1_VCEsat) > eps && sw.rCE >= 0;
                        end
                    otherwise
                        tf = false;
                end
            end
            function [L_H, Cout_F] = calculateAutomaticDCDCComponents(app, topology, Vin, Vout, Pout, fsw)
                values = [Vin, Vout, Pout, fsw];
                if any(~isfinite(values)) || any(values <= 0)
                    error('Vin, Vout, Pout and fsw must be finite and positive.');
                end
                currentRippleFraction = 0.20;
                voltageRippleFraction = 0.01;
                ccmMargin              = 1.20;
                flybackCCMMargin       = 1.50;
                capacitorMargin        = 1.20;
                Rload = Vout^2 / Pout;
                Iout  = Pout / Vout;
                deltaVoutMax = voltageRippleFraction * Vout;
                switch topology
                    case "Boost"
                        if Vout <= Vin
                            error('Boost requires Vout greater than Vin.');
                        end
                        D = 1 - Vin / Vout;
                        ILavg = Pout / Vin;
                        deltaILtarget = currentRippleFraction * ILavg;
                        Lripple = Vin * D / (fsw * deltaILtarget);
                        Lcritical = D * (1-D)^2 * Rload / (2 * fsw);
                        L_H = max(Lripple, ccmMargin * Lcritical);
                        CoutIdeal = Iout * D / (fsw * deltaVoutMax);
                    case "Buck"
                        if Vout >= Vin
                            error('Buck requires Vout lower than Vin.');
                        end
                        D = Vout / Vin;
                        ILavg = Iout;
                        deltaILtarget = currentRippleFraction * ILavg;
                        Lripple = (Vin - Vout) * D / (fsw * deltaILtarget);
                        Lcritical = (1-D) * Rload / (2 * fsw);
                        L_H = max(Lripple, ccmMargin * Lcritical);
                        deltaILactual = (Vin - Vout) * D / (fsw * L_H);
                        CoutIdeal = deltaILactual / (8 * fsw * deltaVoutMax);
                    case "Flyback"
                        turnsRatio = app.TurnsRatioEditField.Value; 
                        if ~isfinite(turnsRatio) || turnsRatio <= 0
                            error('Flyback turns ratio Np/Ns must be positive.');
                        end
                        diode = getDiodeParams(app);
                        Vf = diode.Vf;
                        D = turnsRatio * (Vout + Vf) / (Vin + turnsRatio * (Vout + Vf));
                        if ~isfinite(D) || D <= 0.05 || D >= 0.80
                            error(['Calculated Flyback duty cycle is %.4f. ' ...
                                 'Change Vin, Vout or turns ratio.'], D);
                        end
                        efficiencyEstimate = 0.90;
                        LmBoundary = efficiencyEstimate * Vin^2 * D^2 / (2 * Pout * fsw);
                        L_H = flybackCCMMargin * LmBoundary;
                        CoutIdeal = Iout * D / (fsw * deltaVoutMax);
                    otherwise
                        error( 'Automatic component calculation supports Boost, Buck and Flyback.');
                end
                Cout_F = capacitorMargin * CoutIdeal;
                if ~isfinite(L_H) || L_H <= 0
                    error('Automatically calculated inductance is invalid.');
                end
                if ~isfinite(Cout_F) || Cout_F <= 0
                    error('Automatically calculated output capacitance is invalid.');
                end
            end
            function nSw = getActiveSwitchCount(app)
                switch app.SelectedTopology
                    case {"Boost","Buck","Flyback"}
                        nSw = 1;
                    case "Half-bridge inverter"
                        nSw = 2;
                    case "Full-bridge inverter"
                        nSw = 4;
                    case "Three-phase inverter"
                        nSw = 6;
                    otherwise
                        error('Unsupported topology.');
                end
            end
            function sw = getSwitchParams(app)
                tech = app.SwitchTechnologyDropDown.Value;
                D = getDefaultValues(app, app.SelectedTopology, tech, app.DiodeTypeDropDown.Value);
                useAdvanced = app.AdvancedParametersCheckBox.Value;
                p = [app.RdsOnEditFieldMOSFET.Value, app.EonEditFieldMOSFET.Value, app.EoffEditFieldMOSFET.Value];
                sw = struct('Type',tech,'RdsOn',[],'Eon',0,'Eoff',0,'Qg',0,'Qoss',0,'Coss',0,'Ciss',0,'Crss',0,'VceSat',[],'IcVceSat',0,'VgeVceSat',0,'VgeTh',0,'Ices',0,'VcesIces',0,'TmeasIGBT',25,'VCE0',0,'rCE',0,'Eref_I',1,'Eref_V',1,'Eref_T',125,'Ids_RdsOn',1,'Vgs_RdsOn',10,'Vth_sw',3);
                switch tech
                    case {'Si MOSFETs','SiC MOSFETs','GaN MOSFETs'}
                        sw.RdsOn = p(1);
                        isSiC = strcmp(tech, 'SiC MOSFETs');
                        capacitanceScale = 1e-12;
                        if isSiC
                            sw.Eon = p(2);
                            sw.Eoff = p(3);
                            sw.Eref_I = D.Switch.Eref_I;
                            sw.Eref_V = D.Switch.Eref_V;
                            sw.Eref_T = D.Switch.Eref_T;
                        else
                            sw.Qg = p(2);
                            sw.Qoss = p(3);
                        end
                        if useAdvanced
                            sw.Ids_RdsOn = app.IcVceSatEditFieldIGBT.Value;
                            sw.Vgs_RdsOn = app.VgeVceSatEditFieldIGBT.Value;
                            sw.Vth_sw = app.VgeThEditFieldIGBT.Value;
                            sw.Ciss = app.IcesEditFieldIGBT.Value * capacitanceScale;
                            sw.Crss = app.VcesIcesEditFieldIGBT.Value * capacitanceScale;
                            sw.Coss = app.TmeasIGBTEditField.Value * capacitanceScale;
                        else
                            fields = {'Ids_RdsOn','Vgs_RdsOn','Vth_sw','Ciss','Crss','Coss'};
                            for k = 1:numel(fields)
                                sw.(fields{k}) = D.Switch.(fields{k});
                            end
                        end
                    case 'Si IGBTs'         
                        sw.Eon = app.IGBTeditfield01.Value;
                        sw.Eoff = app.IGBTeditfield02.Value;
                        sw.IGBTCondMethod = app.OptionConductionLossIGBT.Value;
                        if strcmp(sw.IGBTCondMethod, 'Direct: VCE0 and rCE')
                            sw.VCE0 = app.IGBTeditfield1.Value;
                            sw.rCE = app.IGBTeditfield2.Value;
                            sw.IC1_VCEsat = NaN;
                            sw.VCE1_sat = NaN;
                            sw.IC2_VCEsat = NaN;
                            sw.VCE2_sat = NaN;
                            sw.IcVceSat = D.Switch.IcVceSat;
                            sw.VceSat = sw.VCE0 + sw.rCE * sw.IcVceSat;
                        else
                            IC1 = app.IGBTeditfield1.Value;
                            VCE1 = app.IGBTeditfield2.Value;
                            IC2 = app.IGBTeditfield3.Value;
                            VCE2 = app.IGBTeditfield4.Value;
            
                            sw.IC1_VCEsat = IC1;
                            sw.VCE1_sat = VCE1;
                            sw.IC2_VCEsat = IC2;
                            sw.VCE2_sat = VCE2;
                            sw.IcVceSat = IC1;
                            sw.VceSat = VCE1;
                            if isfinite(IC1) && isfinite(IC2) && abs(IC2 - IC1) > eps
                                sw.rCE = (VCE2 - VCE1) / (IC2 - IC1);
                                sw.VCE0 = VCE1 - sw.rCE * IC1;
                            else
                                sw.rCE = NaN;
                                sw.VCE0 = NaN;
                            end
                        end
                        useIGBTAdvanced = isprop(app, 'AdvancedParametersIGBT') && strcmp(app.AdvancedParametersIGBT.Visible, 'on');
            
                        if useIGBTAdvanced
                            sw.VgeVceSat = app.EditField1ADI.Value;
                            sw.VgeTh = app.EditField2ADI.Value;
                            sw.Ices = app.EditField3ADI.Value;
                            sw.VcesIces = app.EditField4ADI.Value;
                            sw.TmeasIGBT = app.EditField5ADI.Value;
                        else
                            fields = {'VgeVceSat','VgeTh','Ices','VcesIces','TmeasIGBT'};
                            for k = 1:numel(fields)
                                sw.(fields{k}) = D.Switch.(fields{k});
                            end
                        end    
                        capFields = {'Cies_IGBT_pF','Cres_IGBT_pF','Coes_IGBT_pF'};
                        capDefaults = [26400, 1000, 2700];
                        capControls = {'Editfieldinputcapacitance','Editfieldreversecapacitance','Editfieldoutputcapacitance'};       
                        for k = 1:3
                            if isfield(D.Switch, capFields{k})
                                capDefaults(k) = D.Switch.(capFields{k});
                            end
                            if useIGBTAdvanced && isprop(app, capControls{k})
                                value = app.(capControls{k}).Value;
                            else
                                value = capDefaults(k);
                            end
                            if isnan(value) || value <= 0
                                value = capDefaults(k);
                            end
                            sw.(capFields{k}) = value;
                        end
                        sw.Cies_IGBT_nF = sw.Cies_IGBT_pF / 1000;
                        sw.Cres_IGBT_nF = sw.Cres_IGBT_pF / 1000;
                        sw.Coes_IGBT_nF = sw.Coes_IGBT_pF / 1000;
                        sw.Cies_IGBT = sw.Cies_IGBT_pF * 1e-12;
                        sw.Cres_IGBT = sw.Cres_IGBT_pF * 1e-12;
                        sw.Coes_IGBT = sw.Coes_IGBT_pF * 1e-12;
                        sw.Ciss = sw.Cies_IGBT;
                        sw.Crss = sw.Cres_IGBT;
                        sw.Coss = sw.Coes_IGBT;
                        sw.Vth_sw = sw.VgeTh;
                        capacitances = [sw.Cies_IGBT_pF, sw.Cres_IGBT_pF, sw.Coes_IGBT_pF];
                        if any(~isfinite(capacitances)) || any(capacitances <= 0)
                            error('IGBT capacitances Cies, Cres and Coes must be finite and strictly positive.');
                        end
                        if sw.Cres_IGBT_pF > sw.Coes_IGBT_pF
                            error('Invalid IGBT capacitances: Cres must be less than or equal to Coes. Current values: Cres = %.6g pF, Coes = %.6g pF.', sw.Cres_IGBT_pF, sw.Coes_IGBT_pF);
                        end
                        if sw.Cres_IGBT_pF > sw.Cies_IGBT_pF
                            error('Invalid IGBT capacitances: Cres must be less than or equal to Cies. Current values: Cres = %.6g pF, Cies = %.6g pF.', sw.Cres_IGBT_pF, sw.Cies_IGBT_pF);
                        end
                        sw.Eref_I = D.Switch.Eref_I;
                        sw.Eref_V = D.Switch.Eref_V;
                        sw.Eref_T = D.Switch.Eref_T;
                        useAdvancedRefs = isprop(app, 'IGBTadvancedparameterCheckbox') && app.IGBTadvancedparameterCheckbox.Value;
                        if useAdvancedRefs
                            Iref = NaN;
                            Vref = NaN;
                            if isprop(app, 'eoneoffrefcurrent')
                                Iref = app.eoneoffrefcurrent.Value;
                            elseif isprop(app, 'eonoffrefcurrent')
                                Iref = app.eonoffrefcurrent.Value;
                            end
                            if isprop(app, 'eoneoffrefvoltage')
                                Vref = app.eoneoffrefvoltage.Value;
                            elseif isprop(app, 'eonoffrefvoltage')
                                Vref = app.eonoffrefvoltage.Value;
                            end
                            if isfinite(Iref) && Iref > 0
                                sw.Eref_I = Iref;
                            end
                            if isfinite(Vref) && Vref > 0
                                sw.Eref_V = Vref;
                            end
                        end
                    otherwise
                        error('Unsupported switch technology.');
                end
                if isfield(D.Switch, 'Tref_cond_C') && isfinite(D.Switch.Tref_cond_C)
                    sw.Tref_cond_C = D.Switch.Tref_cond_C;
                else
                    sw.Tref_cond_C = 25;
                end
                sw.T2_cond_C = sw.Tref_cond_C;
                sw.HasConductionTempDependence = false;
                if strcmp(tech, 'Si IGBTs')
                    sw.VceSat_Tref = sw.VceSat;
                    T2 = app.alt2.Value;
                    VceSat_T2 = app.alt2b.Value;
                    if ~isfinite(T2) || T2 <= sw.Tref_cond_C
                        error('IGBT second temperature T2 must be greater than Tref.');
                    end
                    if ~isfinite(VceSat_T2) || VceSat_T2 <= 0
                        error('IGBT VCE(sat) at T2 must be finite and positive.');
                    end
                    sw.T2_cond_C = T2;
                    sw.VceSat_T2 = VceSat_T2;
                    sw.HasConductionTempDependence = abs(VceSat_T2 - sw.VceSat_Tref) > eps;
                else
                    sw.RdsOn_Tref = sw.RdsOn;
                    T2 = app.RdsOn2TEditFieldMOSFET.Value;
                    RdsOn_T2 = app.RdsOnEditField2TMOSFET.Value;
                    if ~isfinite(sw.RdsOn_Tref) || sw.RdsOn_Tref <= 0
                        error('MOSFET Rds_on at Tref must be finite and positive.');
                    end
                    if ~isfinite(T2) || T2 <= sw.Tref_cond_C
                        error('MOSFET second temperature T2 must be greater than Tref.');
                    end
                    if ~isfinite(RdsOn_T2) || RdsOn_T2 <= 0
                        error('MOSFET Rds_on at T2 must be finite and positive.');
                    end
                    sw.T2_cond_C = T2;
                    sw.RdsOn_T2 = RdsOn_T2;
                    sw.HasConductionTempDependence = abs(RdsOn_T2 - sw.RdsOn_Tref) > eps(max(abs([RdsOn_T2, sw.RdsOn_Tref])));
                end
            end
            function RdsOn_T = getRdsOnAtTemperature(app, Tj_C)
                sw = getSwitchParams(app);
                if ~ismember(sw.Type, {'Si MOSFETs','SiC MOSFETs','GaN MOSFETs'})
                    error(['getRdsOnAtTemperature is only valid for MOSFET technologies.']);
                end
                if nargin < 2 || isempty(Tj_C)
                    Tj_C = sw.Tref_cond_C;
                end
                R1 = sw.RdsOn_Tref;
                T1 = sw.Tref_cond_C;
                if ~sw.HasConductionTempDependence
                    RdsOn_T = R1 .* ones(size(Tj_C));
                    return
                end
                R2 = sw.RdsOn_T2;
                T2 = sw.T2_cond_C;
                RdsOn_T = R1 + (R2 - R1) .* (Tj_C - T1) ./ (T2 - T1);
                RdsOn_T = max(RdsOn_T, 0.05 * R1);
            end
            function [VCE0_T, rCE_T] = getIGBTConductionParamsAtTemperature(app, Tj_C)
                sw = getSwitchParams(app);
                if ~strcmp(sw.Type, 'Si IGBTs')
                    error('getIGBTConductionParamsAtTemperature is only valid for IGBTs.');
                end
                if nargin < 2 || isempty(Tj_C)
                    Tj_C = sw.Tref_cond_C;
                end
                VCE0_T = sw.VCE0 .* ones(size(Tj_C));
                rCE_T  = sw.rCE  .* ones(size(Tj_C));
                if isfield(sw, 'HasConductionTempDependence') && sw.HasConductionTempDependence
                    Vce_T = getVceSatAtTemperature(app, Tj_C);
                    Vce_ref = sw.VCE0 + sw.rCE * max(sw.IcVceSat, eps);
                    if Vce_ref > 0
                        scale = Vce_T ./ Vce_ref;
                        VCE0_T = sw.VCE0 .* scale;
                        rCE_T  = sw.rCE  .* scale;
                    end
                end
                VCE0_T = max(VCE0_T, 0);
                rCE_T  = max(rCE_T,  0);
            end
            function val = readOptionalNumericField(app, fieldName)
                val = NaN;
                if ~isprop(app, fieldName)
                    return
                end
                try
                    val = app.(fieldName).Value;
                catch
                    val = NaN;
                end
                if isempty(val) || ~isnumeric(val) || ~isscalar(val)
                    val = NaN;
                end
            end
            function Vce_T = getVceSatAtTemperature(app, Tj_C)
                sw = getSwitchParams(app);
                if ~strcmp(sw.Type, 'Si IGBTs')
                    error('getVceSatAtTemperature is only valid for IGBTs.');
                end
                V1 = sw.VceSat_Tref;
                T1 = sw.Tref_cond_C;
                if ~sw.HasConductionTempDependence
                    Vce_T = V1 .* ones(size(Tj_C));
                    return
                end
                V2 = sw.VceSat_T2;
                T2 = sw.T2_cond_C;
                Vce_T = V1 + (V2 - V1) .* (Tj_C - T1) ./ (T2 - T1);
                Vce_T = max(Vce_T, 0.05 * V1);
            end
            function [Pbody_inst, Pbody_avg, bodyEvents] = computeBodyDiodeLossAccurate(app, t, IDbody, VDbody, PWM)
                t      = t(:);
                IDbody = IDbody(:);
                VDbody = VDbody(:);
                PWM    = PWM(:);
                n = min([numel(t), numel(IDbody), numel(VDbody), numel(PWM)]);
                if n < 2
                    Pbody_inst = zeros(max(n,1),1);
                    Pbody_avg  = 0;
                    bodyEvents = table();
                    return
                end
                t = t(1:n);
                IDbody = IDbody(1:n);
                VDbody = VDbody(1:n);
                PWM= PWM(1:n);
                Pbody_inst = zeros(n,1);
                Iabs = abs(IDbody);
                Vabs = abs(VDbody);
                Ipk = max(Iabs);
                if ~isfinite(Ipk) || Ipk < 1e-9
                    Pbody_avg  = 0;
                    bodyEvents = table();
                    return
                end
                Ithr = max(1e-6, 0.01 * Ipk);
                bodyCond = IDbody > Ithr & VDbody > 0 & isfinite(IDbody) & isfinite(VDbody);
                Praw = VDbody .* IDbody;
                Pbody_inst(bodyCond) = Praw(bodyCond);
                Tobs = t(end) - t(1);
                if Tobs <= 0
                    Pbody_avg = 0;
                else
                    Pbody_avg = trapz(t, Pbody_inst) / Tobs;
                end
                d = diff([false; bodyCond; false]);
                starts = find(d == 1);
                stops  = find(d == -1) - 1;
                bodyEvents = table('Size',[0 6], 'VariableTypes', {'double','double','double','double','double','double'}, ...
                    'VariableNames', {'tStart','tEnd','Energy_J','Duration_s','Ipeak_A','Vpeak_V'});
                for k = 1:numel(starts)
                    i1 = starts(k);
                    i2 = stops(k);
                    if i2 <= i1
                        continue
                    end
                    Ebody = trapz(t(i1:i2), Pbody_inst(i1:i2));
                    dur   = t(i2) - t(i1);
                    bodyEvents = [bodyEvents; {t(i1), t(i2), Ebody, dur, max(Iabs(i1:i2)), max(Vabs(i1:i2))}]; %#ok<AGROW>
                end
            end
            function D = getDefaultValues(app, topology, tech, diodeType)
                if nargin < 2 || strlength(string(topology)) == 0, topology = app.SelectedTopology; end
                if nargin < 3 || isempty(tech), tech = app.SwitchTechnologyDropDown.Value; end
                if nargin < 4 || isempty(diodeType), diodeType = app.DiodeTypeDropDown.Value; end
                topology = string(topology);
                tech = char(tech);
                diodeType = char(diodeType);
                D.Startup = struct('SwitchTechnology','Si MOSFETs','DiodeType','Si ultrafast Diode');
                switch tech
                    case 'Si MOSFETs'
                        D.Switch = struct('ReferenceDevice','Infineon IPW65R080CFD','RdsOn',80e-3,'Eon',0,'Eoff',0, ...
                            'VceSat',[],'Qg',170e-9,'Qoss',100e-9,'Ids_RdsOn',20,'Vgs_RdsOn',10, ...
                            'Vth_sw',3,'Ciss',5030e-12,'Crss',20e-12,'Coss',215e-12,'Tref_cond_C',25, 'T2_cond_C',150,'RdsOn_T2',160e-3);
                        D.GateDrive = 10;
                        D.Gate = struct('Rg_on',5,'Rg_off',5);
                    case 'SiC MOSFETs'
                        D.Switch = struct('ReferenceDevice','Wolfspeed C3M0032120K','RdsOn',32e-3,'Eon',367e-6,'Eoff',123e-6, ...
                            'VceSat',[],'Qg',118e-9,'Qoss',0,'Ids_RdsOn',40,'Vgs_RdsOn',15,'Vth_sw',2.5, ...
                            'Ciss',3357e-12,'Crss',8e-12,'Coss',129e-12,'Eref_I',40,'Eref_V',800,'Eref_T',175, ...
                            'Tref_cond_C',25,'T2_cond_C',175,'RdsOn_T2',57.6e-3);
                        D.GateDrive = 15;
                        D.Gate = struct('Rg_on',2.5,'Rg_off',2.5);
                    case 'GaN MOSFETs'
                        D.Switch = struct('ReferenceDevice','GaN Systems GS66508B','RdsOn',50e-3,'Eon',0,'Eoff',0, ...
                            'VceSat',[],'Qg',6.1e-9,'Qoss',40e-9,'Ids_RdsOn',9,'Vgs_RdsOn',6, ...
                            'Vth_sw',1.7,'Ciss',242e-12,'Crss',1.5e-12,'Coss',65e-12,'Tref_cond_C',25, ...
                            'T2_cond_C',150,'RdsOn_T2',129e-3);
                        D.GateDrive = 6;
                        D.Gate = struct('Rg_on',10,'Rg_off',1);
                    case 'Si IGBTs'

                        if topology == "Full-bridge inverter"

                            % ==========================================================
                            % FULL BRIDGE VALIDATION DEVICE
                            % Infineon F4-75R12KS4
                            % ==========================================================

                            % Linearized VCE = VCE0 + rCE*IC model.
                            % Approximation from the 125 C output characteristic.
                            VCE0 = 1.54;
                            rCE  = 30.8e-3;

                            D.Switch = struct( ...
                                'ReferenceDevice','Infineon F4-75R12KS4', ...
                                'RdsOn',[], ...
                                'VCE0',VCE0, ...
                                'rCE',rCE, ...
                                'IcVceSat',75, ...
                                'VceSat',3.85, ...
                                'IC1_VCEsat',15, ...
                                'VCE1_sat',2.00, ...
                                'IC2_VCEsat',75, ...
                                'VCE2_sat',3.85, ...
                                'VgeVceSat',15, ...
                                'VgeTh',5.5, ...
                                'Ices',5.0e-3, ...
                                'VcesIces',1200, ...
                                'TmeasIGBT',25, ...
                                'Eon',9.00e-3, ...
                                'Eoff',3.80e-3, ...
                                'Eref_I',75, ...
                                'Eref_V',600, ...
                                'Eref_T',125, ...
                                'Qg',0.80e-6, ...
                                'Qoss',0, ...
                                'Coss',0, ...
                                'Cies_IGBT_pF',5100, ...
                                'Cres_IGBT_pF',300, ...
                                'Coes_IGBT_pF',1000, ...
                                'Tref_cond_C',125, ...
                                'T2_cond_C',150, ...
                                'VceSat_T2',3.85);

                            D.GateDrive = 15;
                            D.Gate = struct('Rg_on',7.5,'Rg_off',7.5);

                        else

                            % ==========================================================
                            % HALF BRIDGE: KEEP VALIDATED SKM50GB12T4
                            % ==========================================================

                            VCE0 = 0.70;
                            rCE  = 30e-3;

                            D.Switch = struct( ...
                                'ReferenceDevice','Semikron Danfoss SKM50GB12T4', ...
                                'RdsOn',[], 'VCE0',VCE0, ...
                                'rCE',rCE, 'IcVceSat',50, 'VceSat',2.20, ...
                                'IC1_VCEsat',25, 'VCE1_sat',1.45, ...
                                'IC2_VCEsat',50, 'VCE2_sat',2.20, ...
                                'VgeVceSat',15, 'VgeTh',5.8, ...
                                'Ices',1.0e-3, 'VcesIces',1200, ...
                                'TmeasIGBT',25,'Eon',5.50e-3, ...
                                'Eoff',4.50e-3,'Eref_I',50, ...
                                'Eref_V',600, 'Eref_T',150, ...
                                'Qg',280e-9, 'Qoss',0, ...
                                'Coss',0, 'Cies_IGBT_pF',2770, ...
                                'Cres_IGBT_pF',160, 'Coes_IGBT_pF',200, ...
                                'Tref_cond_C',150, 'T2_cond_C',175, ...
                                'VceSat_T2',2.20);

                            D.GateDrive = 15;
                            D.Gate = struct('Rg_on',8.2,'Rg_off',8.2);

                        end
                    otherwise
                        error('Unsupported switch technology: %s.', tech);
                end
                switch topology
                    case "Boost"
                        D.Topology = struct('Vin',200,'Vout',400,'Pout',1000,'fsw',50e3, ...
                            'L',1.5e-3,'Cout',220e-6,'Rg_on',10,'Rg_off',5);
                    case "Buck"
                        D.Topology = struct('Vin',400,'Vout',200,'Pout',1000,'fsw',50e3, ...
                            'L',1.5e-3,'Cout',220e-6,'Rg_on',10,'Rg_off',5);
                    case "Flyback"
                        D.Topology = struct('Vin',48,'Vout',12,'Pout',60,'fsw',100e3,'Cout',470e-6, ...
                            'Rg_on',4.7,'Rg_off',4.7,'Lm',200e-6,'TurnsRatio',4,'Llk',2e-6,'CoutESR',0.05);
                    case "Half-bridge inverter"
                        D.Topology = struct( ...
                            'Vdc',400, ...
                            'Pout',1000, ...
                            'fout',50, ...
                            'fsw',2e3, ...
                            'PF',0.95, ...
                            'ModIndex',0.80, ...
                            'VoutRMS',115, ...
                            'Rg_on',8.2, ...
                            'Rg_off',8.2, ...
                            'PWM','Bipolar PWM');
                    case "Full-bridge inverter"
                        D.Topology = struct('Vdc',400,'Pout',1500,'fout',50,'fsw',10e3,'PF',0.95, ...
                            'ModIndex',0.80,'VoutRMS',230,'Rg_on',10,'Rg_off',5,'PWM','Bipolar PWM');
                    case "Three-phase inverter"
                        D.Topology = struct('Vdc',700,'Pout',3000,'fout',50,'fsw',10e3,'PF',0.90, ...
                            'ModIndex',0.90,'VLLRMS',400,'Rg_on',10,'Rg_off',5,'PWM','SVPWM');
                end
                D.Cooling = struct('Rth_ch',0.15,'FixedTemp',60,'Tamb',25,'Rth_ha',0,'Cth_h',500,'TimeConstant',0);
                switch tech
                    case 'Si MOSFETs'
                        D.Cooling.Rth_foster_sw = [0.03 0.07 0.15 0.50];
                        D.Cooling.tau_foster_sw = [0.01 0.10 1 10];
                    case 'SiC MOSFETs'
                        D.Cooling.Rth_foster_sw = [0.00192 0.00672 0.0233 0.00946];
                        D.Cooling.tau_foster_sw = [0.0006 0.0086 0.0668 1.08];
                        D.Cooling.Rth_ch = 0.0249;
                    case 'GaN MOSFETs'
                        D.Cooling.Rth_foster_sw = [0.05 0.15 0.35 0.95];
                        D.Cooling.tau_foster_sw = [0.002 0.02 0.2 3];
                    case 'Si IGBTs'
                        D.Cooling.Rth_foster_sw = [0.016055 0.117494 0.15756 3.3e-3 3.4e-4];
                        D.Cooling.tau_foster_sw = [4.1e-4 2.8e-3 0.018313 0.491884 12.38553];
                        D.Cooling.Rth_ch = 0.15;
                end
                makeDiode = @(type,Vf,Ron,Qrr,Trr,Cj,Is,Vbi,n) ...
                    struct('Type',type,'Vf',Vf,'Ron',Ron,'Qrr',Qrr,'Trr',Trr,'Cj',Cj,'Is',Is,'Vbi',Vbi,'n',n);
                switch diodeType
                    case 'Ideal Diode'
                        D.Diode = makeDiode('Ideal Diode',0,1e-6,0,1e-12,1e-12,1e-12,0.6,1);
                    case 'GaN reverse path'
                        D.Diode = makeDiode('GaN reverse path',2,50e-3,0,1e-12,65e-12,1e-12,0.6,1);
                    case 'SiC Diode'
                        D.Diode = makeDiode('SiC Diode',1.5,15e-3,0,1e-12,100e-12,1e-15,1.8,1.5);
                    case 'Si ultrafast Diode'
                        D.Diode = makeDiode('Si ultrafast Diode',1,20e-3,300e-9,150e-9,200e-12,1e-12,0.7,1.2);
                    case 'Fast Recovery Diode'

                        if strcmp(tech,'Si IGBTs')

                            if topology == "Full-bridge inverter"

                                % Infineon F4-75R12KS4 antiparallel diode
                                % 125 C reference
                                D.Diode = makeDiode( ...
                                    'Fast Recovery Diode', ...
                                    0.89, ...
                                    10.8e-3, ...
                                    13.0e-6, ...
                                    419e-9, ...
                                    120e-12, ...
                                    1e-12, ...
                                    0.7, ...
                                    1.2);

                            else

                                % Existing SKM50GB12T4 half-bridge diode
                                D.Diode = makeDiode( ...
                                    'Fast Recovery Diode', ...
                                    0.90, ...
                                    25.6e-3, ...
                                    8.70e-6, ...
                                    497e-9, ...
                                    120e-12, ...
                                    1e-12, ...
                                    0.7, ...
                                    1.2);

                            end

                        else
                            D.Diode = makeDiode( ...
                                'Fast Recovery Diode', ...
                                1.65,10e-3,2.45e-6,175e-9, ...
                                120e-12,1e-12,0.7,1.2);
                        end
                    otherwise
                        error('Unsupported diode type: %s.', diodeType);
                end
                switch tech
                    case 'Si MOSFETs'
                        D.Reverse = makeDiode('Si ultrafast Diode',1,20e-3,1e-6,180e-9,215e-12,1e-12,0.7,1.2);
                    case 'SiC MOSFETs'
                        D.Reverse = makeDiode('SiC Diode',4.6,20e-3,478e-9,27e-9,129e-12,1e-15,1.8,1.5);
                    case 'GaN MOSFETs'
                        D.Reverse = makeDiode('GaN reverse path',2,50e-3,0,1e-12,65e-12,1e-12,0.6,1);
                    case 'Si IGBTs'

                        % For IGBT bridge topologies, the antiparallel/FWD diode
                        % is the diode already selected above. This preserves the
                        % topology-specific device data:
                        %
                        % Half bridge -> SKM50GB12T4
                        % Full bridge -> Infineon F4-75R12KS4
                        D.Reverse = D.Diode;
                    otherwise
                        D.Reverse = D.Diode;
                end
            end
            function exportIGBTParamsToBase(app, sw)
                if nargin < 2
                    sw = getSwitchParams(app);
                end
                if ~strcmp(sw.Type, 'Si IGBTs')
                    return
                end
                values = struct('VceSat', sw.VceSat, 'Ic_VceSat', sw.IcVceSat, ...
                    'Vge_VceSat', sw.VgeVceSat, 'VCE0_IGBT', sw.VCE0, 'rCE_IGBT', sw.rCE, ...
                    'Eref_I_IGBT', sw.Eref_I, 'Eref_V_IGBT', sw.Eref_V, 'Eref_T_IGBT', sw.Eref_T, ...
                    'Vgeth', sw.VgeTh, 'Vth', sw.VgeTh, 'Vth_sw', sw.VgeTh, ...
                    'Ices', sw.Ices, 'Vces_Ices', sw.VcesIces, 'Tmeas_IGBT', sw.TmeasIGBT, ...
                    'Eon_sw', sw.Eon, 'Eoff_sw', sw.Eoff, 'Cies_IGBT_pF', sw.Cies_IGBT_pF, 'Cres_IGBT_pF', sw.Cres_IGBT_pF, ...
                    'Coes_IGBT_pF', sw.Coes_IGBT_pF, 'Cies_IGBT_nF', sw.Cies_IGBT_nF, ...
                    'Cres_IGBT_nF', sw.Cres_IGBT_nF, 'Coes_IGBT_nF', sw.Coes_IGBT_nF, ...
                    'Cies_IGBT', sw.Cies_IGBT, 'Cres_IGBT', sw.Cres_IGBT, 'Coes_IGBT', sw.Coes_IGBT);
                assignVariables(app, 'base', values);
            end
            function applyDefaultValues(app, scope)
                if nargin < 2, scope = "startup"; end
                scope = lower(string(scope));
                if strlength(app.SelectedTopology) == 0
                    app.SelectedTopology = getSelectedTopology(app);
                end
                topology = app.SelectedTopology;
                tech = app.SwitchTechnologyDropDown.Value;
                dtype = app.DiodeTypeDropDown.Value;
                D = getDefaultValues(app, topology, tech, dtype);
                applyDiodeUI = false;
                switch scope
                    case "startup"
                        app.SwitchTechnologyDropDown.Value = D.Startup.SwitchTechnology;
                        app.DiodeTypeDropDown.Value = D.Startup.DiodeType;
                        app.YesButton_OmitDiodeParameters.Value = true;
                        app.NoButton_OmitDiodeParameters.Value = false;
                        applyDefaultValues(app, "technology");
                        app.RthchEditField.Value = D.Cooling.Rth_ch;
                        app.FixedTempValue.Value = D.Cooling.FixedTemp;
                        app.EditField_3.Value = D.Cooling.Tamb;
                        app.ThermalResistanceValue.Value = mat2str(D.Cooling.Rth_foster_sw);
                        app.TimeConstantValue.Value = mat2str(D.Cooling.tau_foster_sw);
                        app.ThermalImpedanceValue.Value = D.Cooling.Cth_h;
                        resetSummaryFields(app);
                        app.SelectedtopologyLabel.Text = 'Selected topology:';
                        app.SelectedswitchtopologyLabel.Text = 'Selected switch technology:';
                        app.SelecteddiodetypeLabel.Text = 'Selected diode type:';
                        app.SimulationstatusLabel.Text = 'Simulation status: not run yet';
            
                    case "topology"
                        switch topology
                            case {"Boost","Buck","Flyback"}
                                app.VinEditField_Boost.Value = D.Topology.Vin;
                                app.VoutEditField_Boost.Value = D.Topology.Vout;
                                app.PoutEditField_Boost.Value = D.Topology.Pout;
                                app.fswEditField_Boost.Value = D.Topology.fsw;
                                app.GateDriveVoltageEditField.Value = D.GateDrive;
                                app.TonRgEditField.Value = D.Topology.Rg_on;
                                app.ToffRgEditField.Value = D.Topology.Rg_off;
                                if topology == "Flyback"
                                    app.CoutEdit_Field.Value = D.Topology.Cout;
                                    app.LmEditField.Value = D.Topology.Lm;
                                    app.TurnsRatioEditField.Value = D.Topology.TurnsRatio;
                                    app.LlkEditField.Value = D.Topology.Llk;
                                    app.CoutESREditField.Value = D.Topology.CoutESR;
                                else
                                    [Lauto_H, Cauto_F] = calculateAutomaticDCDCComponents(app, topology, D.Topology.Vin, D.Topology.Vout, D.Topology.Pout, D.Topology.fsw);
                                    app.LEditField_Boost_2.Value = Lauto_H;
                                    app.CoutEdit_Field.Value = Cauto_F;
                                end
                            case {"Half-bridge inverter","Full-bridge inverter","Three-phase inverter"}
                                app.VdcEditField.Value = D.Topology.Vdc;
                                app.PoutACEditField.Value = D.Topology.Pout;
                                app.foutEditField.Value = D.Topology.fout;
                                app.fswACEditField.Value = D.Topology.fsw;
                                app.PFEditField.Value = D.Topology.PF;
                                app.ModIndexEditField.Value = D.Topology.ModIndex;
                                app.VgsDriveACEditField.Value = D.GateDrive;
                                app.RgOnACEditField.Value = D.Topology.Rg_on;
                                app.RgOffACEditField.Value = D.Topology.Rg_off;
                                if topology == "Three-phase inverter"
                                    app.VLLRMSEditField.Value = D.Topology.VLLRMS;
                                    app.PWMStrategy3phDropDown.Value = D.Topology.PWM;
                                else
                                    app.VoutRMSEditField.Value = D.Topology.VoutRMS;
                                    app.PWMStrategyDropDown.Value = D.Topology.PWM;
                                end
                        end
                    case "technology"
                        updateSwitchTechnologyFields(app);
                        app.RthchEditField.Value = D.Cooling.Rth_ch;
                        app.ThermalResistanceValue.Value = mat2str(D.Cooling.Rth_foster_sw);
                        app.TimeConstantValue.Value = mat2str(D.Cooling.tau_foster_sw);
                        app.ThermalImpedanceValue.Value = D.Cooling.Cth_h;
                        switch tech
                            case {'Si MOSFETs','SiC MOSFETs','GaN MOSFETs'}
                                app.RdsOnEditFieldMOSFET.Value = D.Switch.RdsOn;
                                if strcmp(tech, 'SiC MOSFETs')
                                    app.EonEditFieldMOSFET.Value = D.Switch.Eon;
                                    app.EoffEditFieldMOSFET.Value = D.Switch.Eoff;
                                else
                                    app.EonEditFieldMOSFET.Value = D.Switch.Qg;
                                    app.EoffEditFieldMOSFET.Value = D.Switch.Qoss;
                                end
                                app.IcVceSatEditFieldIGBT.Value = D.Switch.Ids_RdsOn;
                                app.VgeVceSatEditFieldIGBT.Value = D.Switch.Vgs_RdsOn;
                                app.VgeThEditFieldIGBT.Value = D.Switch.Vth_sw;
                                app.IcesEditFieldIGBT.Value = D.Switch.Ciss * 1e12;
                                app.VcesIcesEditFieldIGBT.Value = D.Switch.Crss * 1e12;
                                app.TmeasIGBTEditField.Value = D.Switch.Coss * 1e12;
                            case 'Si IGBTs'
                                app.OptionConductionLossIGBT.Value = 'Direct: VCE0 and rCE';                            
                                loadIGBTConductionDefaults(app);
                                app.alt2.Value = D.Switch.T2_cond_C;
                                app.alt2b.Value = D.Switch.VceSat_T2;
                                updateIGBTConductionUI(app);
                                updateIGBTAdvancedUI(app);
                        end
                        if ~strcmp(tech, 'Si IGBTs')
                            app.RdsOn2TEditFieldMOSFET.Value = D.Switch.T2_cond_C;
                            app.RdsOnEditField2TMOSFET.Value = D.Switch.RdsOn_T2;
                        end
                        if ismember(topology, ["Boost","Buck","Flyback"])
                            app.GateDriveVoltageEditField.Value = D.GateDrive;
                        else
                            app.VgsDriveACEditField.Value = D.GateDrive;
                        end
                        if ismember(topology, ["Half-bridge inverter","Full-bridge inverter","Three-phase inverter"])
                            diodeDefault = D.Reverse;
                            app.DiodeTypeDropDown.Value = diodeDefault.Type;
                        else
                            currentDType = app.DiodeTypeDropDown.Value;
                            if strcmp(tech, 'Si IGBTs')
                                currentDType = 'Fast Recovery Diode';
                            elseif strcmp(currentDType, 'Ideal Diode') || strcmp(currentDType, 'GaN reverse path')
                                currentDType = 'SiC Diode';
                            end
                            app.DiodeTypeDropDown.Value = currentDType;
                            D2 = getDefaultValues(app, topology, tech, currentDType);
                            diodeDefault = D2.Diode;
                        end
                        applyDiodeUI = true;
                    case "diode"
                        diodeDefault = D.Diode;
                        applyDiodeUI = true;
                end
                if applyDiodeUI
                    app.DiodeVFEditField.Value = diodeDefault.Vf;
                    app.DiodeRonEditField.Value = diodeDefault.Ron;
                    rr = getReverseRecoveryUIDefaults(app, app.DiodeTypeDropDown.Value);
                    controls = {'ErrErecEditfield','QrrEditfield','trrEditfield','IrrmEditfield','IfrefEditfield','VrevEditfield','didtrefEditfield'};
                    values = [rr.ErrErec_J, rr.Qrr_nC, rr.Trr_ns, rr.Irrm_A, rr.IF_ref_A, rr.Vrev_ref_V, rr.didt_A_per_us];
                    for k = 1:numel(controls)
                        app.(controls{k}).Value = values(k);
                    end
                    app.YesButton_OmitDiodeParameters.Value = true;
                    app.NoButton_OmitDiodeParameters.Value = false;
                    updateDiodeFields(app);
                end
            end
            function simOut = runModelWithSimulationProgress(app, modelName, simStopTime)
                load_system(modelName);
                waitUntilModelStopped(app, modelName);
                simObj = simulation(modelName);
                simObj = setModelParameter(simObj, SimulationMode="normal");
                if ~isempty(simStopTime)
                    simObj = setModelParameter(simObj, StopTime=num2str(simStopTime), ReturnWorkspaceOutputs="on");
                end
                app.Simulation = simObj;
                app.SimulationProgress.Simulation = simObj;
                app.SimulationProgress.ShowSimTime = 'on';
                app.SimulationProgress.ShowElapsedTime = 'on';
                start(simObj);
                while app.Simulation.Status ~= "inactive"
                    pause(0.05);
                    drawnow limitrate
                end
                simOut = app.Simulation.SimulationOutput;
                if ~isempty(simOut.ErrorMessage)
                    error('Simulink model failed:\n%s', simOut.ErrorMessage);
                end
            end
            function rr = getReverseRecoveryUIDefaults(app, dtype)
                tech = app.SwitchTechnologyDropDown.Value;
                rr.Irrm_A        = 0;
                rr.didt_A_per_us = 0;
                rr.Qrr_nC        = 0;
                rr.Trr_ns        = 1e-3;
                rr.ErrErec_J     = 0;
                rr.IF_ref_A      = 0;
                rr.Vrev_ref_V    = 0;
                if strcmp(tech,'Si IGBTs') && strcmp(dtype,'Fast Recovery Diode')

                    if app.SelectedTopology == "Full-bridge inverter"

                        % Infineon F4-75R12KS4, Tvj = 125 C
                        rr.Irrm_A        = 62;
                        rr.didt_A_per_us = 1800;
                        rr.Qrr_nC        = 13000;
                        rr.Trr_ns        = 419;
                        rr.ErrErec_J     = 4.70e-3;
                        rr.IF_ref_A      = 75;
                        rr.Vrev_ref_V    = 600;

                    else

                        % SKM50GB12T4 half-bridge validation
                        rr.Irrm_A        = 35;
                        rr.didt_A_per_us = 1380;
                        rr.Qrr_nC        = 8700;
                        rr.Trr_ns        = 497;
                        rr.ErrErec_J     = 3.80e-3;
                        rr.IF_ref_A      = 50;
                        rr.Vrev_ref_V    = 600;

                    end

                    return
                end
                if strcmp(tech, 'SiC MOSFETs') && strcmp(dtype, 'SiC Diode')
                    % C3M0032120K intrinsic diode, 175 C
                    rr.Irrm_A    = 27;
                    rr.didt_A_per_us = 2250;
                    rr.Qrr_nC = 478;
                    rr.Trr_ns  = 27;
                    rr.ErrErec_J = 0.27e-3;
                    rr.IF_ref_A  = 40;
                    rr.Vrev_ref_V = 800;
                    return
                end
                switch dtype
                    case 'Si ultrafast Diode'
                        rr.Irrm_A = 5;
                        rr.didt_A_per_us = 100;
                        rr.Qrr_nC   = 300;
                        rr.Trr_ns   = 150;
                        rr.ErrErec_J = 0.20e-3;
                        rr.IF_ref_A = 10;
                        rr.Vrev_ref_V= 600;

                    case 'Fast Recovery Diode'
                        rr.Irrm_A    = 10;
                        rr.didt_A_per_us = 100;
                        rr.Qrr_nC= 800;
                        rr.Trr_ns = 200;
                        rr.ErrErec_J= 0.50e-3;
                        rr.IF_ref_A  = 20;
                        rr.Vrev_ref_V= 600;
                    case {'SiC Diode','GaN reverse path','Ideal Diode'}
                    otherwise
                        error('Unsupported diode type: %s.', dtype);
                end
            end
            function plotLossBreakdown(app)
                ax = app.LossesAxes;
                cla(ax, 'reset');
                if ~isfield(app.SimulationData, 'Results') || isempty(app.SimulationData.Results)
                    title(ax, 'Loss Breakdown');
                    return
                end
                R = app.SimulationData.Results;
                Pcond = 0;
                Psw = 0;
                Pdiode = 0;

                if isfield(R, 'Psw_cond_avg') && isfinite(R.Psw_cond_avg)
                    Pcond = R.Psw_cond_avg;
                end

                if isfield(R, 'Psw_switch_avg') && isfinite(R.Psw_switch_avg)
                    Psw = R.Psw_switch_avg;
                end

                if isfield(R, 'Pdiode_included_avg') && isfinite(R.Pdiode_included_avg)
                    Pdiode = R.Pdiode_included_avg;
                elseif isfield(R, 'Pdiode_body_avg') && isfinite(R.Pdiode_body_avg)
                    Pdiode = R.Pdiode_body_avg;
                end
                switch app.SwitchTechnologyDropDown.Value
                    case 'Si IGBTs'
                        names = {'IGBT conduction','IGBT switching','FWD diode'};
                    case 'GaN MOSFETs'
                        names = {'GaN conduction','GaN switching','Reverse path'};
                    otherwise
                        names = {'Switch conduction','Switch switching','Body diode'};
                end
                values = [Pcond Psw Pdiode];
                x = 1:3;
                ymax = max(values);
                if ~isfinite(ymax) || ymax <= 0, ymax = 1; end
                bar(ax, x, values);
                ax.XTick = x;
                ax.XTickLabel = names;
                ax.XLim = [0.5 3.5];
                ax.YLim = [0 1.25*ymax];
                xtickangle(ax, 30);
                ylabel(ax, 'Losses [W]');
                title(ax, 'Included Semiconductor Losses');
                grid(ax, 'on');
                text(ax, x, values + 0.02*ymax, compose('%.2f W', values), 'HorizontalAlignment', 'center');
            end
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.TopologyImage.ImageSource = '';
                app.TopologyImage.Visible = 'off';
                app.ImageLoadSymbol.ImageSource = 'imageload.png';
            
                app.PlaceholderSubTextLabel.Text = 'No topology selected';
                app.MissionProfileStatusLabel.Text = 'No file loaded';
            
                if ~isempty(app.ButtonGroup_3.SelectedObject)
                    app.SelectedTopology = getSelectedTopology(app);
                else
                    app.SelectedTopology = "Boost";
                end
            
                applyDefaultValues(app, "startup");
                applyDefaultValues(app, "topology");
            
                app.ConductionLossesEditField.Editable = 'off';
                app.DiodeLossesEditField.Editable = 'off';
                app.SwitchingLossesEditField.Editable = 'off';
                app.EfficiencyEditField.Editable = 'off';
                app.TotalLossesEditField.Editable = 'off';
            
                app.ConductionLossesEditField.ValueDisplayFormat = '%.2f';
                app.DiodeLossesEditField.ValueDisplayFormat = '%.2f';
                app.SwitchingLossesEditField.ValueDisplayFormat = '%.2f';
                app.EfficiencyEditField.ValueDisplayFormat = '%.2f';
                app.TotalLossesEditField.ValueDisplayFormat = '%.2f';
                app.ErrErecEditfield.ValueDisplayFormat = '%.3e';
                app.QrrEditfield.ValueDisplayFormat = '%.3e';
                app.trrEditfield.ValueDisplayFormat  = '%.3e';
    
                app.CoutESREditField.ValueDisplayFormat = '%.3g';
    
                app.RdsOnEditFieldMOSFET.ValueDisplayFormat = '%.3g';
                app.EonEditFieldMOSFET.ValueDisplayFormat  = '%.3e';
                app.EoffEditFieldMOSFET.ValueDisplayFormat = '%.3e';
    
    
                app.ReversetimeTrrlabel.Text = 'Junction capacitance C_j [F]';
                app.AdvancedParametersCheckBox.Value = false;
                app.IGBTadvancedparameterCheckbox.Value = false;
                app.IGBTadvancedparameterCheckbox.Visible = 'off';
                updateSwitchTechnologyFields(app);
                updateDiodeFields(app);
                updateCoolingPanels(app);
                updateCircuitParameterPanels(app);
                syncSchematicToTab4(app);
    
                app.AdvanceReverseRecoveryCheckBox.ValueChangedFcn = ...
                    createCallbackFcn(app, @AdvanceReverseRecoveryCheckBoxValueChanged, true);
            
                if ~isempty(app.ButtonGroup_3.SelectedObject)
                    app.PlaceholderSubTextLabel.Text = ['Selected topology: ' char(app.SelectedTopology)];
                    updateSchematicImage(app, app.ButtonGroup_3.SelectedObject);
                end
            
                updateMissionProfileTableForTopology(app);
                updateMissionProfileControls(app);
        end

        % Value changed function: SwitchTechnologyDropDown
        function SwitchTechnologyDropDownValueChanged(app, event)
                updateSwitchTechnologyFields(app);
                applyDefaultValues(app, "technology");
        end

        % Value changed function: DiodeTypeDropDown
        function DiodeTypeDropDownValueChanged(app, event)
                applyDefaultValues(app, "diode");
                
        end

        % Selection changed function: ButtonGroup_4
        function ButtonGroup_4SelectionChanged(app, event)
                updateDiodeFields(app);
    
    
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
                updateCoolingPanels(app);
                
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
                updateCoolingPanels(app);
        end

        % Button pushed function: ContinueButton6
        function ContinueButton6Pushed(app, event)
                if strlength(app.SelectedTopology) == 0
                    uialert(app.UIFigure, 'Please select a topology first.', 'Missing Topology');
                    return
                end
                if ~ismember(app.SelectedTopology, ["Boost","Buck","Flyback","Half-bridge inverter","Full-bridge inverter","Three-phase inverter"])
                    uialert(app.UIFigure, ['Simulation is currently implemented only for Boost, Buck, Flyback, ' ...
                         'Half-bridge inverter, Full-bridge inverter, and Three-phase inverter.'], 'Not Available');
                    return
                end
                if ~validateDeviceInputs(app)
                    uialert(app.UIFigure, 'Please review the input fields. One or more values are missing or invalid.', ...
                        'Invalid Inputs');
                    return
                end
                if app.UseMissionProfileCheckBox.Value && ~ismember(app.SelectedTopology, ["Boost","Buck","Flyback"])
                    uialert(app.UIFigure, 'Mission profile simulation is currently implemented only for Boost, Buck, and Flyback.', ...
                        'Mission Profile Not Available');
                    return
                end
                d = [];
                try
                    app.ContinueButton6.Enable = 'off';
                    cleanupObj = onCleanup(@() set(app.ContinueButton6, 'Enable', 'on'));
                    app.MissionProfileStatusLabel.Text = 'Status: simulation running...';
                    drawnow limitrate
                    buildSimulationData(app);
                    app.SimulationData.MissionProfile = app.MissionProfileTable.Data;
                    if ismember(app.SelectedTopology, ["Boost","Buck","Flyback"])
                        if app.UseMissionProfileCheckBox.Value
                            runDCDCMissionProfileSimulation(app, []);
                        else
                            runDCDCSimulation(app);
                        end
                    elseif ismember(app.SelectedTopology, ["Half-bridge inverter","Full-bridge inverter","Three-phase inverter"])
                        runACSimulation(app);
                    else
                        error(['Simulation is currently implemented only for Boost, Buck, Flyback, ' ...
                               'Half-bridge inverter, Full-bridge inverter, and Three-phase inverter.']);
                    end
                    updateSummaryTab(app);
                    if app.UseMissionProfileCheckBox.Value && isfield(app.SimulationData, 'MissionResults')
                        plotMissionLossLine(app);
                        plotMissionTemperature(app);
                        app.SimulationstatusLabel.Text = sprintf('Simulation status: Mission completed. Max Tj = %.1f °C',app.SimulationData.Results.Tj_max_C);
                    else
                        plotLossBreakdown(app);
                        plotFixedOperatingPointTemperature(app);
                    end
                    uialert(app.UIFigure, 'Simulation completed successfully.', 'Done');
                    app.TabGroup.SelectedTab = app.SummaryTab;
                catch ME
                    if ~isempty(d) && isvalid(d)
                        close(d)
                    end
                    app.MissionProfileStatusLabel.Text = 'Status: simulation failed.';
                    fprintf(2, '%s\n', getReport(ME,'extended','hyperlinks','on'));
                    uialert(app.UIFigure, ...
                        getReport(ME,'basic','hyperlinks','off'), ...
                        'Simulation Error');
                end
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
                selectedButton = event.NewValue;
            
                if isempty(selectedButton)
                    app.SelectedTopology = "";
                    app.PlaceholderSubTextLabel.Text = 'No topology selected';
                    app.TopologyImage.ImageSource = '';
                    app.TopologyImage.Visible = 'off';
                    syncSchematicToTab4(app);
                    updateCircuitParameterPanels(app);
                    updateMissionProfileTableForTopology(app);
                    return
                end
            
                app.SelectedTopology = getSelectedTopology(app);
                app.MissionProfileLoaded = false;
                applyDefaultValues(app, "topology");
                updateDiodeFields(app);
                resetSummaryFields(app);
                app.PlaceholderSubTextLabel.Text = ['Selected topology: ' selectedButton.Text];
                updateSchematicImage(app, selectedButton);
                updateCircuitParameterPanels(app);
                updateMissionProfileTableForTopology(app);
                updateMissionProfileControls(app);
        end

        % Button pushed function: LoadMissionProfileButton
        function LoadMissionProfileButtonPushed(app, event)
                [file, path] = uigetfile({'*.csv', 'CSV Files (*.csv)'}, 'Select mission profile CSV');
                if isequal(file, 0)
                    return
                end
                try 
                    T = readtable(fullfile(path, file));
                    requiredCols = getMissionProfileColumns(app);
                    missingCols = setdiff(requiredCols, T.Properties.VariableNames);
    
                    if ~isempty(missingCols)
                        error(['Missing columns: ' strjoin(missingCols, ', ')]);
                    end
                    app.MissionProfileTable.Data = T;
                    app.MissionProfileTable.ColumnName = T.Properties.VariableNames;
                    app.MissionProfileStatusLabel.Text = ['Loaded file: ' file];
                    app.MissionProfileLoaded = true; 
                catch ME
                    app.MissionProfileLoaded = false;
                    uialert(app.UIFigure, ['Could not load CSV: ' ME.message], 'CSV Error');
                end
        end

        % Button pushed function: DownloadMissionProfileTemplateButton
        function DownloadMissionProfileTemplateButtonPushed(app, event)
                T = app.getMissionProfileTemplate();
                defaultName = ['mission_profile_' lower(strrep(char(app.SelectedTopology),' ','_')) '.csv'];
                [file, path] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save Mission Profile Template', defaultName);
                if isequal(file,0)
                    return
                end
                try
                    writetable(T, fullfile(path,file));
                    app.MissionProfileStatusLabel.Text = ['Status: template saved: ' file];
                catch ME
                    uialert(app.UIFigure, ['Could not save template: ' ME.message], 'Save Error');
                end
        end

        % Value changed function: AdvancedParametersCheckBox
        function AdvancedParametersCheckBoxValueChanged(app, event)
                updateSwitchTechnologyFields(app);
        end

        % Value changed function: UseMissionProfileCheckBox
        function UseMissionProfileCheckBoxValueChanged(app, event)
                updateMissionProfileControls(app);
        end

        % Value changed function: OptionConductionLossIGBT
        function OptionConductionLossIGBTValueChanged(app, event)
                if strcmp(app.SwitchTechnologyDropDown.Value, 'Si IGBTs')
                    loadIGBTConductionDefaults(app);
                    updateIGBTConductionUI(app);
                    updateIGBTAdvancedUI(app);
                end
    
        end

        % Value changed function: IGBTadvancedparameterCheckbox
        function IGBTadvancedparameterCheckboxValueChanged(app, event)
                updateIGBTAdvancedUI(app);
                
        end

        % Value changed function: AdvanceReverseRecoveryCheckBox
        function AdvanceReverseRecoveryCheckBoxValueChanged(app, event)
                updateDiodeFields(app);
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ApplicationTab = uitab(app.TabGroup);
            app.ApplicationTab.Title = '1 Application';
            app.TopologiesPanel = uipanel(app.ApplicationTab);
            app.TopologiesPanel.Title = 'Converter Topologies';
            app.ButtonGroup_3 = uibuttongroup(app.TopologiesPanel);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.rbBoostConverter = uiradiobutton(app.ButtonGroup_3);
            app.rbBoostConverter.Text = 'Boost';
            app.rbBoostConverter.Value = true;
            app.rbBuckConverter = uiradiobutton(app.ButtonGroup_3);
            app.rbBuckConverter.Text = 'Buck';
            app.rbBuckBoostConverter = uiradiobutton(app.ButtonGroup_3);
            app.rbBuckBoostConverter.Text = 'Flyback';
            app.rbHalfBridgeInverter = uiradiobutton(app.ButtonGroup_3);
            app.rbHalfBridgeInverter.Text = '1-phase Half-bridge inverter';
            app.rbFullBridgeInverter = uiradiobutton(app.ButtonGroup_3);
            app.rbFullBridgeInverter.Text = '1-phase SPWM Full-bridge inverter';
            app.rbThreePhaseInverter = uiradiobutton(app.ButtonGroup_3);
            app.rbThreePhaseInverter.Text = '2-level SPWM 3-phase inverter';
            app.DCDCLabel = uilabel(app.ButtonGroup_3);
            app.DCDCLabel.Text = 'DC/DC';
            app.DCACLabel = uilabel(app.ButtonGroup_3);
            app.DCACLabel.Text = 'DC/AC';
            app.SchematicPanel = uipanel(app.ApplicationTab);
            app.SchematicPanel.Title = 'Schematic viewer';
            app.PlaceholderSubTextLabel = uilabel(app.SchematicPanel);
            app.PlaceholderSubTextLabel.Text = 'No topology selected. Select one.';
            app.TopologyImage = uiimage(app.SchematicPanel);
            app.Image = uiimage(app.ApplicationTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'politologo.png');
            app.AuLabel = uilabel(app.ApplicationTab);
            app.AuLabel.Text = 'Author: Walmy Fernandez';
            app.AuLabel_2 = uilabel(app.ApplicationTab);
            app.AuLabel_2.Text = 'Version: 1.0';
            app.AuLabel_3 = uilabel(app.ApplicationTab);
            app.AuLabel_3.Text = 'Date: 07/2026';
            app.AuLabel_4 = uilabel(app.ApplicationTab);
            app.AuLabel_4.Text = 'License: Open';
            app.AuLabel_5 = uilabel(app.ApplicationTab);
            app.AuLabel_5.Text = 'Supervisor: Francesco Iannuzzo';
            app.DeviceSelectionTab = uitab(app.TabGroup);
            app.DeviceSelectionTab.Title = '2 Device Selection';
            app.Panel = uipanel(app.DeviceSelectionTab);
            app.DeviceSelectionTitleLabel_3 = uilabel(app.Panel);
            app.DeviceSelectionTitleLabel_3.Text = 'Diode Type';
            app.DiodeVFEditField = uieditfield(app.Panel, 'numeric');
            app.DiodeVFEditField.Value = 0.7;
            app.DiodeRonEditField = uieditfield(app.Panel, 'numeric');
            app.DiodeRonEditField.Value = 0.02;
            app.DiodeVFLabel = uilabel(app.Panel);
            app.DiodeVFLabel.Text = 'Forward voltage drop [V]';
            app.DiodeRonLabel = uilabel(app.Panel);
            app.DiodeRonLabel.Text = 'On-state/dynamic resistance [ohm]';
            app.DropDown_2Label = uilabel(app.Panel);
            app.DropDown_2Label.Text = '';
            app.DiodeTypeDropDown = uidropdown(app.Panel);
            app.DiodeTypeDropDown.Items = {'SiC Diode', 'Si ultrafast Diode', 'GaN reverse path', 'Fast Recovery Diode'};
            app.DiodeTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DiodeTypeDropDownValueChanged, true);
            app.DiodeTypeDropDown.Value = 'SiC Diode';
            app.ButtonGroup_4 = uibuttongroup(app.Panel);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_4SelectionChanged, true);
            app.YesButton_OmitDiodeParameters = uiradiobutton(app.ButtonGroup_4);
            app.YesButton_OmitDiodeParameters.Text = 'Yes';
            app.NoButton_OmitDiodeParameters = uiradiobutton(app.ButtonGroup_4);
            app.NoButton_OmitDiodeParameters.Text = 'No';
            app.NoButton_OmitDiodeParameters.Value = true;
            app.ErrErecEditfield = uieditfield(app.ButtonGroup_4, 'numeric');
            app.QrrEditfield = uieditfield(app.ButtonGroup_4, 'numeric');
            app.QrrEditfield.ValueDisplayFormat = '%.0f';
            app.ErrErecLabel = uilabel(app.ButtonGroup_4);
            app.ErrErecLabel.Text = 'Err/Erec [J]';
            app.ReverseQrrLabel = uilabel(app.ButtonGroup_4);
            app.ReverseQrrLabel.Text = 'Reverse-recovery charge Qrr [nC]';
            app.trrEditfield = uieditfield(app.ButtonGroup_4, 'numeric');
            app.trrEditfield.ValueDisplayFormat = '%.0f';
            app.ReversetimeTrrlabel = uilabel(app.ButtonGroup_4);
            app.ReversetimeTrrlabel.Text = 'Reverse-recovery time trr [ns]';
            app.OmitLabelDiodeExtraParameters = uilabel(app.ButtonGroup_4);
            app.OmitLabelDiodeExtraParameters.Text = 'Include reverse recovery? Yes/No';
            app.IrrmEditfield = uieditfield(app.ButtonGroup_4, 'numeric');
            app.IfrefEditfield = uieditfield(app.ButtonGroup_4, 'numeric');
            app.IfrefEditfield.ValueDisplayFormat = '%.0f';
            app.VrevEditfield = uieditfield(app.ButtonGroup_4, 'numeric');
            app.VrevEditfield.ValueDisplayFormat = '%.0f';
            app.Peakreversecurrentlabel = uilabel(app.ButtonGroup_4);
            app.Peakreversecurrentlabel.Text = 'Peak reverse-recovery current Irrm [A]';
            app.IfredLabel = uilabel(app.ButtonGroup_4);
            app.IfredLabel.Text = 'Forward current before recovery, IF_ref [A]';
            app.Vrevlabel = uilabel(app.ButtonGroup_4);
            app.Vrevlabel.Text = 'Reverse voltage during recovery, Vrev_ref [V]';
            app.didtrefLabel = uilabel(app.ButtonGroup_4);
            app.didtrefLabel.Text = 'Current fall rate, di/dt_ref [A/s]';
            app.didtrefEditfield = uieditfield(app.ButtonGroup_4, 'numeric');
            app.AdvanceReverseRecoveryCheckBox = uicheckbox(app.ButtonGroup_4);
            app.AdvanceReverseRecoveryCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvanceReverseRecoveryCheckBoxValueChanged, true);
            app.AdvanceReverseRecoveryCheckBox.Text = 'Advanced Reverse Recovery data for Scaling';
            app.Panel_2 = uipanel(app.DeviceSelectionTab);
            app.DeviceSelectionTitleLabel_2 = uilabel(app.Panel_2);
            app.DeviceSelectionTitleLabel_2.Text = 'Device Type';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '';
            app.SwitchTechnologyDropDown = uidropdown(app.Panel_2);
            app.SwitchTechnologyDropDown.Items = {'SiC MOSFETs', 'SiC cascode JFETs', 'Si MOSFETs', 'Si IGBTs', 'GaN MOSFETs'};
            app.SwitchTechnologyDropDown.ValueChangedFcn = createCallbackFcn(app, @SwitchTechnologyDropDownValueChanged, true);
            app.SwitchTechnologyDropDown.Value = 'Si IGBTs';
            app.IGBTBlockParametersPanel = uipanel(app.DeviceSelectionTab);
            app.IcVceSatEditFieldIGBT = uieditfield(app.IGBTBlockParametersPanel, 'numeric');
            app.IcVceSatEditFieldIGBT.Value = 0.08;
            app.VgeVceSatEditFieldIGBT = uieditfield(app.IGBTBlockParametersPanel, 'numeric');
            app.VgeVceSatEditFieldIGBT.Value = 0.08;
            app.VgeThEditFieldIGBT = uieditfield(app.IGBTBlockParametersPanel, 'numeric');
            app.VgeThEditFieldIGBT.Value = 0.08;
            app.IcesEditFieldIGBT = uieditfield(app.IGBTBlockParametersPanel, 'numeric');
            app.IcesEditFieldIGBT.Value = 0.08;
            app.VcesIcesEditFieldIGBT = uieditfield(app.IGBTBlockParametersPanel, 'numeric');
            app.VcesIcesEditFieldIGBT.Value = 0.08;
            app.TmeasIGBTEditField = uieditfield(app.IGBTBlockParametersPanel, 'numeric');
            app.TmeasIGBTEditField.Value = 0.08;
            app.IcVceSatLabelIGBT = uilabel(app.IGBTBlockParametersPanel);
            app.IcVceSatLabelIGBT.Text = 'Ic at Vce(sat) [A]';
            app.VgeVceSatLabelIGBT = uilabel(app.IGBTBlockParametersPanel);
            app.VgeVceSatLabelIGBT.Text = 'Vge at Vce(sat) [V]';
            app.VgeThLabelIGBT = uilabel(app.IGBTBlockParametersPanel);
            app.VgeThLabelIGBT.Text = 'Vge(th) [V]';
            app.IcesLabelIGBT = uilabel(app.IGBTBlockParametersPanel);
            app.IcesLabelIGBT.Text = 'Ices leakage current [A]';
            app.VcesIcesLabelIGBT = uilabel(app.IGBTBlockParametersPanel);
            app.VcesIcesLabelIGBT.Text = 'Vces at Ices [V]';
            app.TmeasIGBTLabel = uilabel(app.IGBTBlockParametersPanel);
            app.TmeasIGBTLabel.Text = 'Measurement temperature [degC]';
            app.RdsOnLabel2TMOSFET = uilabel(app.IGBTBlockParametersPanel);
            app.RdsOnLabel2TMOSFET.Text = 'On-resistance Rds_on at 2nd Temp. [ohm]';
            app.RdsOn2TEditFieldMOSFET = uieditfield(app.IGBTBlockParametersPanel, 'numeric');
            app.RdsOn2TEditFieldMOSFET.Value = 175;
            app.RdsOnEditField2TMOSFET = uieditfield(app.IGBTBlockParametersPanel, 'numeric');
            app.RdsOnEditField2TMOSFET.Value = 0.08;
            app.RdsOn2TLabelMOSFET = uilabel(app.IGBTBlockParametersPanel);
            app.RdsOn2TLabelMOSFET.Text = '2nd Temp. of Measurement Rds(on) [degC]';
            app.MOSFETParametersPanel = uipanel(app.DeviceSelectionTab);
            app.RdsOnEditFieldMOSFET = uieditfield(app.MOSFETParametersPanel, 'numeric');
            app.RdsOnEditFieldMOSFET.Value = 0.08;
            app.RdsOnLabelMOSFET = uilabel(app.MOSFETParametersPanel);
            app.RdsOnLabelMOSFET.Text = 'On-resistance Rds_on [ohm]';
            app.EonEditFieldMOSFET = uieditfield(app.MOSFETParametersPanel, 'numeric');
            app.EonEditFieldMOSFET.Value = 0.08;
            app.EonLabelMOSFET = uilabel(app.MOSFETParametersPanel);
            app.EonLabelMOSFET.Text = 'Eon';
            app.EoffEditFieldMOSFET = uieditfield(app.MOSFETParametersPanel, 'numeric');
            app.EoffEditFieldMOSFET.Value = 0.08;
            app.EoffLabelMOSFET = uilabel(app.MOSFETParametersPanel);
            app.EoffLabelMOSFET.Text = 'Eoff';
            app.AdvancedParametersCheckBox = uicheckbox(app.DeviceSelectionTab);
            app.AdvancedParametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedParametersCheckBoxValueChanged, true);
            app.AdvancedParametersCheckBox.Text = 'Advanced Parameters';
            app.OptionConductionLossIGBT = uidropdown(app.DeviceSelectionTab);
            app.OptionConductionLossIGBT.Items = {'Direct: VCE0 and rCE', 'From VCE(sat)-IC curve points'};
            app.OptionConductionLossIGBT.ValueChangedFcn = createCallbackFcn(app, @OptionConductionLossIGBTValueChanged, true);
            app.OptionConductionLossIGBT.Value = 'Direct: VCE0 and rCE';
            app.ConductionlossmodelLabel = uilabel(app.DeviceSelectionTab);
            app.ConductionlossmodelLabel.Text = 'Conduction-loss model:';
            app.Panel_4 = uipanel(app.DeviceSelectionTab);
            app.S = uilabel(app.Panel_4);
            app.S.Text = 'Threshold voltage VCE0 at Tref [V]';
            app.IGBTlabel2 = uilabel(app.Panel_4);
            app.IGBTlabel2.Text = 'Dynamic resistance rCE at Tref [ohm]';
            app.IGBTeditfield2 = uieditfield(app.Panel_4, 'numeric');
            app.IGBTeditfield2.Value = 0.08;
            app.IGBTeditfield1 = uieditfield(app.Panel_4, 'numeric');
            app.IGBTeditfield1.Value = 0.08;
            app.IGBTeditfield01 = uieditfield(app.Panel_4, 'numeric');
            app.IGBTeditfield01.Value = 0.08;
            app.IGBTeditfield02 = uieditfield(app.Panel_4, 'numeric');
            app.IGBTeditfield02.Value = 0.08;
            app.IGBTlabel01 = uilabel(app.Panel_4);
            app.IGBTlabel01.Text = 'Turn-on energy Eon [J]';
            app.IGBTlabel02 = uilabel(app.Panel_4);
            app.IGBTlabel02.Text = 'Turn-off energy Eoff [J]';
            app.IGBTlabel3 = uilabel(app.Panel_4);
            app.IGBTlabel3.Text = '2nd Temp. of Measurement Rds(on) [degC]';
            app.IGBTlabel4 = uilabel(app.Panel_4);
            app.IGBTlabel4.Text = 'On-resistance Rds_on at 2nd Temp. [ohm]';
            app.IGBTeditfield4 = uieditfield(app.Panel_4, 'numeric');
            app.IGBTeditfield4.Value = 0.08;
            app.IGBTeditfield3 = uieditfield(app.Panel_4, 'numeric');
            app.IGBTeditfield3.Value = 175;
            app.AdvancedParametersIGBT = uipanel(app.DeviceSelectionTab);
            app.AdvancedParametersIGBT.Title = 'Advanced Parameters';
            app.EditField1ADI = uieditfield(app.AdvancedParametersIGBT, 'numeric');
            app.EditField1ADI.Value = 0.08;
            app.EditField2ADI = uieditfield(app.AdvancedParametersIGBT, 'numeric');
            app.EditField2ADI.Value = 0.08;
            app.EditField3ADI = uieditfield(app.AdvancedParametersIGBT, 'numeric');
            app.EditField3ADI.Value = 0.08;
            app.EditField4ADI = uieditfield(app.AdvancedParametersIGBT, 'numeric');
            app.EditField4ADI.Value = 0.08;
            app.EditField5ADI = uieditfield(app.AdvancedParametersIGBT, 'numeric');
            app.EditField5ADI.Value = 0.08;
            app.Label1ADI = uilabel(app.AdvancedParametersIGBT);
            app.Label1ADI.Text = 'Vge at VceSat [V]';
            app.Label2ADI = uilabel(app.AdvancedParametersIGBT);
            app.Label2ADI.Text = 'Vge(th) [V]';
            app.Label3ADI = uilabel(app.AdvancedParametersIGBT);
            app.Label3ADI.Text = 'Ices leakage current [A]';
            app.Label4ADI = uilabel(app.AdvancedParametersIGBT);
            app.Label4ADI.Text = 'Vces at Ices [V]';
            app.Label5ADI = uilabel(app.AdvancedParametersIGBT);
            app.Label5ADI.Text = 'Measurement temperature [degC]';
            app.Editfieldoutputcapacitance = uieditfield(app.AdvancedParametersIGBT, 'numeric');
            app.Editfieldoutputcapacitance.Value = 0.08;
            app.Editfieldreversecapacitance = uieditfield(app.AdvancedParametersIGBT, 'numeric');
            app.Editfieldreversecapacitance.Value = 0.08;
            app.Labeloutputcapacitancelabel = uilabel(app.AdvancedParametersIGBT);
            app.Labeloutputcapacitancelabel.Text = 'Output capacitance [pF]';
            app.Labelreversecapacitancelabel = uilabel(app.AdvancedParametersIGBT);
            app.Labelreversecapacitancelabel.Text = 'Reverse transfer capacitance  [pF]';
            app.Editfieldinputcapacitance = uieditfield(app.AdvancedParametersIGBT, 'numeric');
            app.Editfieldinputcapacitance.Value = 0.08;
            app.Labelinputcapacitancelabel = uilabel(app.AdvancedParametersIGBT);
            app.Labelinputcapacitancelabel.Text = 'Input capacitance [pF]';
            app.Labeloutputcapacitancelabel_2 = uilabel(app.AdvancedParametersIGBT);
            app.Labeloutputcapacitancelabel_2.Text = 'Eon/Eoff reference voltage Vref [V]';
            app.Labelreversecapacitancelabel_2 = uilabel(app.AdvancedParametersIGBT);
            app.Labelreversecapacitancelabel_2.Text = 'Eon/Eoff reference current Iref [A]';
            app.eoneoffrefcurrent = uieditfield(app.AdvancedParametersIGBT, 'numeric');
            app.eoneoffrefcurrent.Value = 450;
            app.eoneoffrefvoltage = uieditfield(app.AdvancedParametersIGBT, 'numeric');
            app.eoneoffrefvoltage.Value = 1800;
            app.IGBTadvancedparameterCheckbox = uicheckbox(app.DeviceSelectionTab);
            app.IGBTadvancedparameterCheckbox.ValueChangedFcn = createCallbackFcn(app, @IGBTadvancedparameterCheckboxValueChanged, true);
            app.IGBTadvancedparameterCheckbox.Text = 'Advanced Parameters';
            app.alt2b = uieditfield(app.DeviceSelectionTab, 'numeric');
            app.alt2b.ValueDisplayFormat = '%.0f';
            app.alt3b = uilabel(app.DeviceSelectionTab);
            app.alt3b.Text = 'Reverse voltage during recovery, Vrev_ref [V]';
            app.alt3 = uilabel(app.DeviceSelectionTab);
            app.alt3.Text = 'Current fall rate, di/dt_ref [A/s]';
            app.alt2 = uieditfield(app.DeviceSelectionTab, 'numeric');
            app.CircuitParametersTab = uitab(app.TabGroup);
            app.CircuitParametersTab.Title = '3 Circuit Parameters';
            app.DeviceSelectionTitleLabel_4 = uilabel(app.CircuitParametersTab);
            app.DeviceSelectionTitleLabel_4.Text = 'Converter Parameters';
            app.SchematicPanel_2 = uipanel(app.CircuitParametersTab);
            app.SchematicPanel_2.Title = 'Schematic viewer';
            app.PlaceholderSubTextLabel_2 = uilabel(app.SchematicPanel_2);
            app.PlaceholderSubTextLabel_2.Text = 'No topology selected. Select one.';
            app.HereistheschematicLabel_2 = uilabel(app.SchematicPanel_2);
            app.HereistheschematicLabel_2.Text = 'Here is the schematic:';
            app.TopologyImage_2 = uiimage(app.SchematicPanel_2);
            app.DCDCCircuit = uipanel(app.CircuitParametersTab);
            app.VinEditField_Boost = uieditfield(app.DCDCCircuit, 'numeric');
            app.VinEditField_Boost.Value = 400;
            app.VoutEditField_Boost = uieditfield(app.DCDCCircuit, 'numeric');
            app.VoutEditField_Boost.Value = 800;
            app.PoutEditField_Boost = uieditfield(app.DCDCCircuit, 'numeric');
            app.PoutEditField_Boost.Value = 2000;
            app.fswEditField_Boost = uieditfield(app.DCDCCircuit, 'numeric');
            app.fswEditField_Boost.ValueDisplayFormat = '%.0f';
            app.fswEditField_Boost.Value = 100000;
            app.GateDriveVoltageEditField = uieditfield(app.DCDCCircuit, 'numeric');
            app.GateDriveVoltageEditField.ValueDisplayFormat = '%.6f';
            app.VinLabel_Boost = uilabel(app.DCDCCircuit);
            app.VinLabel_Boost.Text = 'Input voltage Vin [V]';
            app.VoutLabel_Boost = uilabel(app.DCDCCircuit);
            app.VoutLabel_Boost.Text = 'Output voltage Vout  [V]';
            app.PoutLabel_Boost = uilabel(app.DCDCCircuit);
            app.PoutLabel_Boost.Text = 'Output power Pout [W]';
            app.fswLabel_Boost = uilabel(app.DCDCCircuit);
            app.fswLabel_Boost.Text = 'Switching frequency fsw [Hz]';
            app.CoutLabel_Boost = uilabel(app.DCDCCircuit);
            app.CoutLabel_Boost.Text = 'Gate drive voltage Vgs_drive [V]';
            app.CoutEdit_Field = uieditfield(app.DCDCCircuit, 'numeric');
            app.CoutEdit_Field.ValueDisplayFormat = '%.6f';
            app.CoutEdit_Field.Value = 0.00047;
            app.CoutLabel_Boost_2 = uilabel(app.DCDCCircuit);
            app.CoutLabel_Boost_2.Text = 'Output capacitance Cout [F]';
            app.LLabel_Boost_2 = uilabel(app.DCDCCircuit);
            app.LLabel_Boost_2.Text = 'Inductance L [H]';
            app.LEditField_Boost_2 = uieditfield(app.DCDCCircuit, 'numeric');
            app.LEditField_Boost_2.ValueDisplayFormat = '%.6f';
            app.LEditField_Boost_2.Value = 0.001;
            app.CoutLabel_Boost_3 = uilabel(app.DCDCCircuit);
            app.CoutLabel_Boost_3.Text = 'Turn-on gate resistance Rg_on [ohm]';
            app.CoutLabel_Boost_4 = uilabel(app.DCDCCircuit);
            app.CoutLabel_Boost_4.Text = 'Turn-off gate resistance Rg_off [ohm]';
            app.TonRgEditField = uieditfield(app.DCDCCircuit, 'numeric');
            app.TonRgEditField.ValueDisplayFormat = '%.6f';
            app.ToffRgEditField = uieditfield(app.DCDCCircuit, 'numeric');
            app.ToffRgEditField.ValueDisplayFormat = '%.6f';
            app.DCDCFlyback = uipanel(app.CircuitParametersTab);
            app.LmEditField = uieditfield(app.DCDCFlyback, 'numeric');
            app.LmEditField.Value = 400;
            app.TurnsRatioEditField = uieditfield(app.DCDCFlyback, 'numeric');
            app.TurnsRatioEditField.Value = 800;
            app.LlkEditField = uieditfield(app.DCDCFlyback, 'numeric');
            app.LlkEditField.Value = 2000;
            app.CoutESREditField = uieditfield(app.DCDCFlyback, 'numeric');
            app.CoutESREditField.ValueDisplayFormat = '%.0f';
            app.CoutESREditField.Value = 100000;
            app.LmLabel = uilabel(app.DCDCFlyback);
            app.LmLabel.Text = 'Primary magnetizing inductance Lm [H]';
            app.TurnsRatioLabel = uilabel(app.DCDCFlyback);
            app.TurnsRatioLabel.Text = 'Turns ratio Np/Ns';
            app.LlkLabel = uilabel(app.DCDCFlyback);
            app.LlkLabel.Text = 'Leakage inductance Llk [H]';
            app.CoutESRLabel = uilabel(app.DCDCFlyback);
            app.CoutESRLabel.Text = 'Output capacitor ESR [ohm]';
            app.DCACCircuitPanel = uipanel(app.CircuitParametersTab);
            app.VdcEditField = uieditfield(app.DCACCircuitPanel, 'numeric');
            app.VdcEditField.Value = 400;
            app.PoutACEditField = uieditfield(app.DCACCircuitPanel, 'numeric');
            app.PoutACEditField.Value = 800;
            app.foutEditField = uieditfield(app.DCACCircuitPanel, 'numeric');
            app.foutEditField.Value = 2000;
            app.fswACEditField = uieditfield(app.DCACCircuitPanel, 'numeric');
            app.fswACEditField.ValueDisplayFormat = '%.0f';
            app.fswACEditField.Value = 100000;
            app.VgsDriveACEditField = uieditfield(app.DCACCircuitPanel, 'numeric');
            app.VgsDriveACEditField.ValueDisplayFormat = '%.6f';
            app.VdcLabel = uilabel(app.DCACCircuitPanel);
            app.VdcLabel.Text = 'DC-link voltage Vdc [V]';
            app.PoutACLabel = uilabel(app.DCACCircuitPanel);
            app.PoutACLabel.Text = 'Output power Pout [W]';
            app.foutLabel = uilabel(app.DCACCircuitPanel);
            app.foutLabel.Text = 'Output frequency fout [Hz]';
            app.fswACLabel = uilabel(app.DCACCircuitPanel);
            app.fswACLabel.Text = 'Switching frequency fsw [Hz]';
            app.VgsDriveACLabel = uilabel(app.DCACCircuitPanel);
            app.VgsDriveACLabel.Text = 'Gate drive voltage Vgs_drive [V]';
            app.ModIndexEditField = uieditfield(app.DCACCircuitPanel, 'numeric');
            app.ModIndexEditField.ValueDisplayFormat = '%.6f';
            app.ModIndexEditField.Value = 0.00047;
            app.ModIndexLabel = uilabel(app.DCACCircuitPanel);
            app.ModIndexLabel.Text = 'Modulation index m [-]';
            app.PFLabel = uilabel(app.DCACCircuitPanel);
            app.PFLabel.Text = 'Power factor PF [-]';
            app.PFEditField = uieditfield(app.DCACCircuitPanel, 'numeric');
            app.PFEditField.ValueDisplayFormat = '%.6f';
            app.PFEditField.Value = 0.001;
            app.RgOnACLabel = uilabel(app.DCACCircuitPanel);
            app.RgOnACLabel.Text = 'Turn-on gate resistance Rg_on [ohm]';
            app.RgOffACLabel = uilabel(app.DCACCircuitPanel);
            app.RgOffACLabel.Text = 'Turn-off gate resistance Rg_off [ohm]';
            app.RgOnACEditField = uieditfield(app.DCACCircuitPanel, 'numeric');
            app.RgOnACEditField.ValueDisplayFormat = '%.6f';
            app.RgOffACEditField = uieditfield(app.DCACCircuitPanel, 'numeric');
            app.RgOffACEditField.ValueDisplayFormat = '%.6f';
            app.HalfandFullBridge = uipanel(app.CircuitParametersTab);
            app.VoutRMSEditField = uieditfield(app.HalfandFullBridge, 'numeric');
            app.VoutRMSEditField.Value = 400;
            app.VoutRMSLabel = uilabel(app.HalfandFullBridge);
            app.VoutRMSLabel.Text = 'Output RMS voltage Vout_rms [V]';
            app.PWMStrategyLabel = uilabel(app.HalfandFullBridge);
            app.PWMStrategyLabel.Text = 'PWM strategy';
            app.PWMStrategyDropDown = uidropdown(app.HalfandFullBridge);
            app.PWMStrategyDropDown.Items = {'Bipolar PWM', 'Unipolar PWM'};
            app.PWMStrategyDropDown.Value = 'Bipolar PWM';
            app.Threephaseextrapanel = uipanel(app.CircuitParametersTab);
            app.VLLRMSEditField = uieditfield(app.Threephaseextrapanel, 'numeric');
            app.VLLRMSEditField.Value = 400;
            app.VLLRMSLabel = uilabel(app.Threephaseextrapanel);
            app.VLLRMSLabel.Text = 'Line-line RMS voltage VLL [V]';
            app.PWMStrategy3phLabel = uilabel(app.Threephaseextrapanel);
            app.PWMStrategy3phLabel.Text = 'PWM strategy';
            app.PWMStrategy3phDropDown = uidropdown(app.Threephaseextrapanel);
            app.PWMStrategy3phDropDown.Items = {'SPWM', 'SVPWM'};
            app.PWMStrategy3phDropDown.Value = 'SPWM';
            app.CoolingTab = uitab(app.TabGroup);
            app.CoolingTab.Title = '4 Cooling';
            app.ThermalparametersLabel = uilabel(app.CoolingTab);
            app.ThermalparametersLabel.Text = 'Thermal parameters';
            app.HeatsinkmodelLabel = uilabel(app.CoolingTab);
            app.HeatsinkmodelLabel.Text = 'Heat sink model';
            app.RthchLabel = uilabel(app.CoolingTab);
            app.RthchLabel.Text = 'Case-to-heatsink interface resistance R_th,ch [K/W]';
            app.RthchEditField = uieditfield(app.CoolingTab, 'numeric');
            app.ButtonGroup = uibuttongroup(app.CoolingTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.FixedTemp = uiradiobutton(app.ButtonGroup);
            app.FixedTemp.Text = 'Fixed temperature ';
            app.CustomThermalImpedance = uiradiobutton(app.ButtonGroup);
            app.CustomThermalImpedance.Text = 'Custom thermal impedance';
            app.CustomThermalImpedance.Value = true;
            app.HeatSinkFixedTempPanel = uipanel(app.CoolingTab);
            app.FixedTempValueLabel = uilabel(app.HeatSinkFixedTempPanel);
            app.FixedTempValueLabel.Text = 'Fixed temperature T_h';
            app.FixedTempValue = uieditfield(app.HeatSinkFixedTempPanel, 'numeric');
            app.HeatSinkImpedancePanel = uipanel(app.CoolingTab);
            app.BoundarytemperatureT_bCLabel = uilabel(app.HeatSinkImpedancePanel);
            app.BoundarytemperatureT_bCLabel.Text = 'Boundary temperature T_b [°C]';
            app.EditField_3 = uieditfield(app.HeatSinkImpedancePanel, 'numeric');
            app.DevicethermalimpedanceLabel = uilabel(app.HeatSinkImpedancePanel);
            app.DevicethermalimpedanceLabel.Text = 'Device thermal impedance';
            app.ButtonGroup_2 = uibuttongroup(app.HeatSinkImpedancePanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.FosterButton = uiradiobutton(app.ButtonGroup_2);
            app.FosterButton.Text = 'Foster';
            app.FosterButton.Value = true;
            app.CauerButton = uiradiobutton(app.ButtonGroup_2);
            app.CauerButton.Text = 'Cauer';
            app.ThermalchainLabel = uilabel(app.HeatSinkImpedancePanel);
            app.ThermalchainLabel.Text = 'Thermal chain';
            app.ThermalresistanceR_thhaLabel = uilabel(app.HeatSinkImpedancePanel);
            app.ThermalresistanceR_thhaLabel.Text = 'Foster thermal resistance R_th [K/W]';
            app.ThermalCapacitancePanel = uipanel(app.HeatSinkImpedancePanel);
            app.ThermalcapacitanceC_thhLabel = uilabel(app.ThermalCapacitancePanel);
            app.ThermalcapacitanceC_thhLabel.Text = 'Thermal capacitance C_th,h';
            app.ThermalImpedanceValue = uieditfield(app.ThermalCapacitancePanel, 'numeric');
            app.ThermalResistanceValue = uieditfield(app.HeatSinkImpedancePanel, 'text');
            app.TimeConstantPanel = uipanel(app.HeatSinkImpedancePanel);
            app.TimeConstantT_haLabel = uilabel(app.TimeConstantPanel);
            app.TimeConstantT_haLabel.Text = 'Foster time constant tau [s]';
            app.TimeConstantValue = uieditfield(app.TimeConstantPanel, 'text');
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = '5 Simulation';
            app.MissionprofileoptionalLabel = uilabel(app.SimulationTab);
            app.MissionprofileoptionalLabel.Text = 'Mission profile (optional)';
            app.ContinueButton6 = uibutton(app.SimulationTab, 'push');
            app.ContinueButton6.ButtonPushedFcn = createCallbackFcn(app, @ContinueButton6Pushed, true);
            app.ContinueButton6.Text = 'Run Simulation';
            app.Panel_3 = uipanel(app.SimulationTab);
            app.MissionProfileTable = uitable(app.Panel_3);
            app.MissionProfileTable.ColumnName = {'Segment'; 'Duration_s'; 'Vin_V'; 'Vout_V'; 'Pout_W'; 'Tamb_C'};
            app.MissionProfileTable.RowName = {};
            app.LoadMissionProfileButton = uibutton(app.Panel_3, 'push');
            app.LoadMissionProfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMissionProfileButtonPushed, true);
            app.LoadMissionProfileButton.Text = 'Import Mission Profile';
            app.ImageLoadSymbol = uiimage(app.Panel_3);
            app.MissionProfileStatusLabel = uilabel(app.Panel_3);
            app.MissionProfileStatusLabel.Text = 'Status:';
            app.DownloadMissionProfileTemplateButton = uibutton(app.Panel_3, 'push');
            app.DownloadMissionProfileTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadMissionProfileTemplateButtonPushed, true);
            app.DownloadMissionProfileTemplateButton.Text = 'Download Mission Profile Template';
            app.SchematicPanel_3 = uipanel(app.SimulationTab);
            app.SchematicPanel_3.Title = 'Schematic viewer';
            app.TopologyImage_3 = uiimage(app.SchematicPanel_3);
            app.UseMissionProfileCheckBox = uicheckbox(app.SimulationTab);
            app.UseMissionProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseMissionProfileCheckBoxValueChanged, true);
            app.UseMissionProfileCheckBox.Text = 'Use Mission Profile';
            app.SimulationProgress = uisimprogress(app.SimulationTab);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = '6 Summary';
            app.LossesAxes = uiaxes(app.SummaryTab);
            title(app.LossesAxes, 'Loss Breakdown')
            ylabel(app.LossesAxes, 'Y')
            zlabel(app.LossesAxes, 'Z')
            app.ThermalEvolutionPlot = uiaxes(app.SummaryTab);
            title(app.ThermalEvolutionPlot, 'Thermal Evolution')
            ylabel(app.ThermalEvolutionPlot, 'Temperature (°C)')
            zlabel(app.ThermalEvolutionPlot, 'time')
            app.SimulationSummaryLabel = uilabel(app.SummaryTab);
            app.SimulationSummaryLabel.Text = 'Simulation Summary';
            app.SelectedtopologyLabel = uilabel(app.SummaryTab);
            app.SelectedtopologyLabel.Text = 'Selected topology:';
            app.SelectedswitchtopologyLabel = uilabel(app.SummaryTab);
            app.SelectedswitchtopologyLabel.Text = 'Selected switch topology:';
            app.SelecteddiodetypeLabel = uilabel(app.SummaryTab);
            app.SelecteddiodetypeLabel.Text = 'Selected diode type:';
            app.SimulationstatusLabel = uilabel(app.SummaryTab);
            app.SimulationstatusLabel.Text = 'Simulation status:';
            app.SummaryPanel = uipanel(app.SummaryTab);
            app.SummaryPanel.Title = 'Summary Panel';
            app.EfficiencyLabel = uilabel(app.SummaryPanel);
            app.EfficiencyLabel.Text = 'Efficiency (%)';
            app.EfficiencyEditField = uieditfield(app.SummaryPanel, 'numeric');
            app.SwitchinglossesWLabel = uilabel(app.SummaryPanel);
            app.SwitchinglossesWLabel.Text = 'Switching losses (W)';
            app.SwitchingLossesEditField = uieditfield(app.SummaryPanel, 'numeric');
            app.DiodelossesWLabel = uilabel(app.SummaryPanel);
            app.DiodelossesWLabel.Text = 'Diode losses (W)';
            app.DiodeLossesEditField = uieditfield(app.SummaryPanel, 'numeric');
            app.ConductionlossesWLabel = uilabel(app.SummaryPanel);
            app.ConductionlossesWLabel.Text = 'Conduction losses (W)';
            app.ConductionLossesEditField = uieditfield(app.SummaryPanel, 'numeric');
            app.TotalLossesLabel = uilabel(app.SummaryPanel);
            app.TotalLossesLabel.Text = 'Total losses (W)';
            app.TotalLossesEditField = uieditfield(app.SummaryPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end